classdef antenna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AntennaarrayconfigurationLabel  matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        ButtonPreview                   matlab.ui.control.Button
        AntennaTx                       matlab.ui.container.ButtonGroup
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CylindricalButton               matlab.ui.control.RadioButton
        UPAButton_2                     matlab.ui.control.RadioButton
        ULAButton_2                     matlab.ui.control.RadioButton
        AntennaNameEditField            matlab.ui.control.EditField
        NameofantennaLabel              matlab.ui.control.Label
        importButton                    matlab.ui.control.Button
        EfieldTx                        matlab.ui.control.EditField
        DirectoryofantennapatternLabel  matlab.ui.control.Label
        spacingTx_2                     matlab.ui.control.NumericEditField
        percolumnLabel_2                matlab.ui.control.Label
        noAntennaTx_2                   matlab.ui.control.NumericEditField
        percolumnLabel                  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        PolarizationmodeLabel           matlab.ui.control.Label
        beta_z_Tx                       matlab.ui.control.NumericEditField
        zRotateangleZLabel              matlab.ui.control.Label
        yRotateangleYLabel              matlab.ui.control.Label
        beta_y_Tx                       matlab.ui.control.NumericEditField
        xRotateangleXLabel              matlab.ui.control.Label
        beta_x_Tx                       matlab.ui.control.NumericEditField
        spacingTx                       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        noAntennaTx                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        UIAxes_Tx                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        callingapp;
        sps = simulation_parameters; % 仿真参数控制类 % Description
    end

    properties (Access = private)
        index % Description
        choice%add or edit
        data%the data of pre antenna
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, index, data, choice)

            app.callingapp=mainapp;
            app.choice=choice;

            tag_scen = app.callingapp.Tree_2.SelectedNodes.Tag;
            if contains(tag_scen, app.callingapp.sps.scenario_UWA)
                app.AntennaarrayconfigurationLabel.Text = '声呐阵列配置 (Sonar array configuration)';
                app.Label.Text = {'声呐阵元数量（根）:         每行  '; 'Number of sonar elements :       per row'};
                app.Label_2.Text = {'声呐阵元间距（波长倍数）:         每行  '; 'Spacing of sonar elements :       per row'};
                app.NameofantennaLabel.Text = {'声呐名称'; 'Sonar Name'};
                name_str = 'Sonar_';
                app.DropDown.Value = '理想点源(Ideal point source)';

                app.DropDown.Enable = "off";
                app.DropDown.Visible = "off";
                app.PolarizationmodeLabel.Visible = "off";
                app.DirectoryofantennapatternLabel.Visible = "off";
                app.EfieldTx.Visible = "off";
                app.importButton.Visible = "off";
                
            else
                name_str = 'Antenna_';
            end
   
            if strcmp(choice, 'add')
                currentRowCount = size(app.callingapp.UITable_antenna.Data, 1);
                newRowNumber = currentRowCount + 1;
                nameOfantena = [name_str, num2str(newRowNumber)];
                app.AntennaNameEditField.Value = nameOfantena;
            elseif strcmp(choice, 'edit')
                app.index=index;
                app.data=data;
                switch data{1,12}{1}%天线种类按钮确定
                    case '均匀线阵(ULA)'
                        app.ULAButton_2.Value=true;
                        app.CylindricalButton.Value=false;
                        app.UPAButton_2.Value=false;
                    case '均匀面阵(UPA)'
                        app.ULAButton_2.Value=false;
                        app.CylindricalButton.Value=false;
                        app.UPAButton_2.Value=true;
                        app.noAntennaTx_2.Enable=true;
                        app.spacingTx_2.Enable=true;
                        app.noAntennaTx_2.Editable = "on";
                        app.spacingTx_2.Editable = "on";
                        app.noAntennaTx_2.Value=str2num(data{1,4}{1});
                        app.spacingTx_2.Value=str2num(data{1,6}{1});
                    case '圆柱阵列(Cylindrical)'
                        app.ULAButton_2.Value=false;
                        app.CylindricalButton.Value=true;
                        app.UPAButton_2.Value=false;
                        app.noAntennaTx_2.Enable=true;
                        app.spacingTx_2.Enable=true;
                        app.noAntennaTx_2.Editable = "on";
                        app.spacingTx_2.Editable = "on";
                        app.noAntennaTx_2.Value=str2num(data{1,4}{1});
                        app.spacingTx_2.Value=str2num(data{1,6}{1});
                end
                app.AntennaNameEditField.Value = data{1,2}{1};%名称
                app.noAntennaTx.Value=str2num(data{1,3}{1});%行数、列数等
                app.spacingTx.Value=str2num(data{1,5}{1});
                app.DropDown.Value=data{1,10}{1};%极化方式
                app.beta_x_Tx.Value=str2num(data{1,9}{1});%角度
                app.beta_y_Tx.Value=str2num(data{1,8}{1});
                app.beta_z_Tx.Value=str2num(data{1,7}{1});
            end
        end

        % Button pushed function: ButtonPreview
        function ButtonPreviewPushed(app, event)
            selectedOption = app.ButtonGroup.SelectedObject.Text;
            if strfind(app.callingapp.Tree_2.SelectedNodes.Tag, app.callingapp.sps.scenario_UWA)
                app.sps.carrier_frequency = str2double( app.callingapp.EditField.Value)*1e3;
            else
                app.sps.carrier_frequency = str2double( app.callingapp.EditField.Value)*1e9;
            end

            
            switch selectedOption
                case '均匀线阵(ULA)'
                    tx_element_spacing = app.spacingTx.Value;
                    tx_X_angle = app.beta_x_Tx.Value*pi/180;
                    tx_Y_angle = app.beta_y_Tx.Value * pi / 180;
                    tx_Z_angle = app.beta_z_Tx.Value * pi / 180;

                    tx_no_elements = app.noAntennaTx.Value;
                    deltap_y = (0: tx_no_elements - 1) * tx_element_spacing * app.sps.wavelength(1);
                    [deltap_x_grid, deltap_y_grid,deltap_z_grid] = meshgrid(0, deltap_y,0); % 创建网格
                    
                    a = [cos(tx_Z_angle) -sin(tx_Z_angle) 0;sin(tx_Z_angle) cos(tx_Z_angle) 0;0 0 1];
                    b = [cos(tx_Y_angle) 0 sin(tx_Y_angle);0 1 0;-sin(tx_Y_angle) 0 cos(tx_Y_angle)];
                    c = [1 0 0;0 cos(tx_X_angle) -sin(tx_X_angle);0 sin(tx_X_angle) cos(tx_X_angle)];
                    d = a*b*c;
                    element_position_1(1,:) = d(1,1).*deltap_x_grid(:) + d(1,2) .*deltap_y_grid(:) + d(1,3) .* deltap_z_grid(:);
                    element_position_1(2,:) = d(2,1).*deltap_x_grid(:) + d(2,2) .*deltap_y_grid(:) + d(2,3) .* deltap_z_grid(:);
                    element_position_1(3,:) = d(3,1).*deltap_x_grid(:) + d(3,2) .*deltap_y_grid(:) + d(3,3) .* deltap_z_grid(:);
                    element_position_1(abs(element_position_1)<1e-7) = 0;

                    scatter3(app.UIAxes_Tx, element_position_1(1,:), element_position_1(2,:), element_position_1(3,:));
                case '均匀面阵(UPA)'
                    tx_colum_spacing = app.spacingTx_2.Value; % 每列的间距
                    tx_row_spacing = app.spacingTx.Value; % 每行的间距
                    tx_X_angle = app.beta_x_Tx.Value * pi / 180;
                    tx_Y_angle = app.beta_y_Tx.Value * pi / 180;
                    tx_Z_angle = app.beta_z_Tx.Value * pi / 180;
                    tx_no_elements_colum = app.noAntennaTx_2.Value; % 每列的天线阵元数量
                    tx_no_elements_row = app.noAntennaTx.Value; % 每行的天线阵元数量

                    % 计算面阵天线的位置
                    deltap_y = (0: tx_no_elements_colum - 1) * tx_colum_spacing * app.sps.wavelength(1); % 每列的位置
                    deltap_z = (0: tx_no_elements_row - 1) * tx_row_spacing * app.sps.wavelength(1); % 每行的位置
                    [deltap_x_grid, deltap_y_grid,deltap_z_grid] = meshgrid(0, deltap_y,deltap_z); % 创建网格

                    a = [cos(tx_Z_angle) -sin(tx_Z_angle) 0;sin(tx_Z_angle) cos(tx_Z_angle) 0;0 0 1];
                    b = [cos(tx_Y_angle) 0 sin(tx_Y_angle);0 1 0;-sin(tx_Y_angle) 0 cos(tx_Y_angle)];
                    c = [1 0 0;0 cos(tx_X_angle) -sin(tx_X_angle);0 sin(tx_X_angle) cos(tx_X_angle)];
                    d = a*b*c;
                    element_position_2(1,:) = d(1,1).*deltap_x_grid(:) + d(1,2) .* deltap_y_grid(:) + d(1,3) .* deltap_z_grid(:);
                    element_position_2(2,:) = d(2,1).*deltap_x_grid(:) + d(2,2) .* deltap_y_grid(:) + d(2,3) .* deltap_z_grid(:);
                    element_position_2(3,:) = d(3,1).*deltap_x_grid(:) + d(3,2) .* deltap_y_grid(:) + d(3,3) .* deltap_z_grid(:);
                    element_position_2(abs(element_position_2) < 1e-7) = 0; % 移除近似于零的值
                    % 绘制面阵天线预览
                    scatter3(app.UIAxes_Tx, element_position_2(1,:), element_position_2(2,:), element_position_2(3,:));

                case '圆柱阵列(Cylindrical)'
                    tx_colum_spacing = app.spacingTx_2.Value; % 每列的间距
                    tx_row_spacing = app.spacingTx.Value; % 每行的间距
                    tx_X_angle = app.beta_x_Tx.Value * pi / 180;
                    tx_Y_angle = app.beta_y_Tx.Value * pi / 180;
                    tx_Z_angle = app.beta_z_Tx.Value * pi / 180;
                    tx_no_elements_colum = app.noAntennaTx_2.Value; % 每列的天线阵元数量
                    tx_no_elements_row = app.noAntennaTx.Value; % 每行的天线阵元数量

                    % 计算圆柱天线的位置
                    deltap_z = (0: tx_no_elements_row - 1) * tx_row_spacing * app.sps.wavelength(1); % z轴的位置
                    position_3(3,:) = reshape(deltap_z(ones(1,tx_no_elements_colum),:).',1,[]); % Z 坐标
                    deltap_x = cos(2*pi/tx_no_elements_colum*(0: tx_no_elements_colum - 1)) * tx_colum_spacing * app.sps.wavelength(1); % 每行x的位置
                    position_3(1,:)= reshape(deltap_x(ones(1,tx_no_elements_row),:),1,[]);
                    deltap_y = sin(2*pi/tx_no_elements_colum*(0: tx_no_elements_colum - 1)) * tx_colum_spacing * app.sps.wavelength(1);% 每行y的位置
                    position_3(2,:)= reshape(deltap_y(ones(1,tx_no_elements_row),:),1,[]);

                    a = [cos(tx_Z_angle) -sin(tx_Z_angle) 0;sin(tx_Z_angle) cos(tx_Z_angle) 0;0 0 1];
                    b = [cos(tx_Y_angle) 0 sin(tx_Y_angle);0 1 0;-sin(tx_Y_angle) 0 cos(tx_Y_angle)];
                    c = [1 0 0;0 cos(tx_X_angle) -sin(tx_X_angle);0 sin(tx_X_angle) cos(tx_X_angle)];
                    d=a*b*c;
                    element_position_3(1,:) = d(1,1).*position_3(1,:)+d(1,2).*position_3(2,:)+d(1,3).*position_3(3,:);
                    element_position_3(2,:) = d(2,1).*position_3(1,:)+d(2,2).*position_3(2,:)+d(2,3).*position_3(3,:);
                    element_position_3(3,:) = d(3,1).*position_3(1,:)+d(3,2).*position_3(2,:)+d(3,3).*position_3(3,:);
                    element_position_3(abs(element_position_3) < 1e-7) = 0; % 移除近似于零的值
                    scatter3(app.UIAxes_Tx, element_position_3(1,:), element_position_3(2,:), element_position_3(3,:));
            end
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [file, path] = uigetfile('.txt');
            if isequal(file, 0)
                mf.add_logs('AntennaPatternLoaded', 'Cancel loading');
            else
                app.EfieldTx.Value = fullfile(path, file);
                msgbox(['The antenna pattern file loaded successfully：', fullfile(path, file)], "modal");
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if strcmp(app.DropDown.Value, '自定义(user-defined)')
                % 检查方向图文件文本区域是否包含.txt文件
                if isempty(app.EfieldTx.Value) || ~contains(app.EfieldTx.Value, '.txt')
                    % 如果文本区域不满足要求，显示错误信息
                    uialert(app.UIFigure, 'Please import the directory of antenna pattern file first！', 'Error', 'Icon', 'error');
                    return; % 阻止进一步执行
                end

                % 如果检查通过，则继续保存操作

                mf.add_logs('AntennaSettings', 'Save successfully');
            end
            if strcmp(app.choice,'add')
                switch app.ButtonGroup.SelectedObject.Text
                    case '均匀线阵(ULA)'
                        updata(app.callingapp, [], app.AntennaNameEditField.Value,app.noAntennaTx.Value,'\',app.spacingTx.Value,'\',app.beta_z_Tx.Value,app.beta_y_Tx.Value,app.beta_x_Tx.Value,string(app.DropDown.Value),app.EfieldTx.Value,string(app.ULAButton_2.Text))
                        app.callingapp.add_antenna.Enable = 'on';
                        delete(app);
                    case '均匀面阵(UPA)'
                        updata(app.callingapp, [], app.AntennaNameEditField.Value,app.noAntennaTx.Value,app.noAntennaTx_2.Value,app.spacingTx.Value,app.spacingTx_2.Value,app.beta_z_Tx.Value,app.beta_y_Tx.Value,app.beta_x_Tx.Value,string(app.DropDown.Value),app.EfieldTx.Value,string(app.UPAButton_2.Text))
                        app.callingapp.add_antenna.Enable = 'on';
                        delete(app);
                    case '圆柱阵列(Cylindrical)'
                        updata(app.callingapp, [], app.AntennaNameEditField.Value,app.noAntennaTx.Value,app.noAntennaTx_2.Value,app.spacingTx.Value,app.spacingTx_2.Value,app.beta_z_Tx.Value,app.beta_y_Tx.Value,app.beta_x_Tx.Value,string(app.DropDown.Value),app.EfieldTx.Value,string(app.CylindricalButton.Text))
                        app.callingapp.add_antenna.Enable = 'on';
                        delete(app);
                end
                return;
            elseif strcmp(app.choice,'edit')
                SelectedText = app.ButtonGroup.SelectedObject.Text;
                switch SelectedText
                    case '均匀线阵(ULA)'
                        app.callingapp.UITable_antenna.Data(app.index,:) = {num2str(app.index), num2str(app.AntennaNameEditField.Value), num2str(app.noAntennaTx.Value), '\', num2str(app.spacingTx.Value), '\', num2str(app.beta_z_Tx.Value), num2str(app.beta_y_Tx.Value), num2str(app.beta_x_Tx.Value), num2str(app.DropDown.Value), num2str(app.EfieldTx.Value), num2str(SelectedText)};
                        delete(app);
                    case '均匀面阵(UPA)'
                        app.callingapp.UITable_antenna.Data(app.index,:) = {num2str(app.index), num2str(app.AntennaNameEditField.Value), num2str(app.noAntennaTx.Value), num2str(app.noAntennaTx_2.Value), num2str(app.spacingTx.Value), num2str(app.spacingTx_2.Value), num2str(app.beta_z_Tx.Value), num2str(app.beta_y_Tx.Value), num2str(app.beta_x_Tx.Value), num2str(app.DropDown.Value), num2str(app.EfieldTx.Value), num2str(SelectedText)};
                        delete(app);
                    case '圆柱阵列(Cylindrical)'
                        app.callingapp.UITable_antenna.Data(app.index,:) = {num2str(app.index), num2str(app.AntennaNameEditField.Value), num2str(app.noAntennaTx.Value), num2str(app.noAntennaTx_2.Value), num2str(app.spacingTx.Value), num2str(app.spacingTx_2.Value), num2str(app.beta_z_Tx.Value), num2str(app.beta_y_Tx.Value), num2str(app.beta_x_Tx.Value), num2str(app.DropDown.Value), num2str(app.EfieldTx.Value), num2str(SelectedText)};
                        delete(app);
                end
                return;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.add_antenna.Enable = 'on';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch selectedButton
                case'均匀线阵(ULA)'
                    app.noAntennaTx_2.Enable="off";
                    app.spacingTx_2.Enable='off';
                    app.noAntennaTx_2.Editable='off';
                    app.spacingTx_2.Editable='off';
                case'均匀面阵(UPA)'
                    app.noAntennaTx_2.Enable='on';
                    app.spacingTx_2.Enable='on';
                    app.noAntennaTx_2.Editable='on';
                    app.spacingTx_2.Editable='on';
                case'圆柱阵列(Cylindrical)'
                    app.noAntennaTx_2.Enable='on';
                    app.spacingTx_2.Enable='on';
                    app.noAntennaTx_2.Editable='on';
                    app.spacingTx_2.Editable='on';
            end
            return;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value,'自定义(user-defined)')
                app.EfieldTx.Enable="on";
                app.importButton.Enable="on";
            else
                app.EfieldTx.Enable="off";
                app.importButton.Enable="off";
            end
            return;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_Tx = uiaxes(app.UIFigure);
            title(app.UIAxes_Tx, 'Tx')
            xlabel(app.UIAxes_Tx, 'x  (m)')
            ylabel(app.UIAxes_Tx, 'y  (m)')
            zlabel(app.UIAxes_Tx, 'z  (m)')
            app.AntennaTx = uibuttongroup(app.UIFigure);
            app.Label = uilabel(app.AntennaTx);
            app.Label.Text = {'天线阵元数量（根）:         每行  '; 'Number of antenna elements :       per row'};
            app.noAntennaTx = uieditfield(app.AntennaTx, 'numeric');
            app.noAntennaTx.Limits = [1 256];
            app.noAntennaTx.Value = 2;
            app.Label_2 = uilabel(app.AntennaTx);
            app.Label_2.Text = {'天线阵元间距（波长倍数）:         每行  '; 'Spacing of antenna elements :       per row'};
            app.spacingTx = uieditfield(app.AntennaTx, 'numeric');
            app.spacingTx.Limits = [0.001 1000];
            app.spacingTx.Value = 0.5;
            app.beta_x_Tx = uieditfield(app.AntennaTx, 'numeric');
            app.beta_x_Tx.Limits = [0 360];
            app.xRotateangleXLabel = uilabel(app.AntennaTx);
            app.xRotateangleXLabel.Text = {' x轴旋转角（°）:     '; ' Rotate angle X (°) :    '};
            app.beta_y_Tx = uieditfield(app.AntennaTx, 'numeric');
            app.beta_y_Tx.Limits = [0 360];
            app.yRotateangleYLabel = uilabel(app.AntennaTx);
            app.yRotateangleYLabel.Text = {' y轴旋转角（°）:     '; ' Rotate angle Y (°) :   '};
            app.zRotateangleZLabel = uilabel(app.AntennaTx);
            app.zRotateangleZLabel.Text = {'z轴旋转角（°）:       '; 'Rotate angle Z (°)  :    '};
            app.beta_z_Tx = uieditfield(app.AntennaTx, 'numeric');
            app.beta_z_Tx.Limits = [0 360];
            app.PolarizationmodeLabel = uilabel(app.AntennaTx);
            app.PolarizationmodeLabel.Text = '极化方式 (Polarization mode) :';
            app.DropDown = uidropdown(app.AntennaTx);
            app.DropDown.Items = {'理想点源(Ideal point source)', '全向单极化(垂直)(Omnidirectional single polarization (vertical))', '全向单极化(水平)(Omnidirectional unipolarization (horizontal))', '全向±45°双极化(Omnidirectional ±45° dual polarization)', '自定义(user-defined)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '理想点源(Ideal point source)';
            app.percolumnLabel = uilabel(app.AntennaTx);
            app.percolumnLabel.Text = {'每列      '; 'per column'};
            app.noAntennaTx_2 = uieditfield(app.AntennaTx, 'numeric');
            app.noAntennaTx_2.Limits = [1 1024];
            app.noAntennaTx_2.Editable = 'off';
            app.noAntennaTx_2.Enable = 'off';
            app.noAntennaTx_2.Value = 2;
            app.percolumnLabel_2 = uilabel(app.AntennaTx);
            app.percolumnLabel_2.Text = {'每列      '; 'per column'};
            app.spacingTx_2 = uieditfield(app.AntennaTx, 'numeric');
            app.spacingTx_2.Limits = [0.001 1000];
            app.spacingTx_2.Editable = 'off';
            app.spacingTx_2.Enable = 'off';
            app.spacingTx_2.Value = 0.5;
            app.DirectoryofantennapatternLabel = uilabel(app.AntennaTx);
            app.DirectoryofantennapatternLabel.Text = {'方向图文件：'; 'Directory of '; 'antenna pattern:'};
            app.EfieldTx = uieditfield(app.AntennaTx, 'text');
            app.EfieldTx.Enable = 'off';
            app.importButton = uibutton(app.AntennaTx, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Enable = 'off';
            app.importButton.Text = '导入(import)';
            app.NameofantennaLabel = uilabel(app.AntennaTx);
            app.NameofantennaLabel.Text = {'天线名称'; 'Antenna Name'};
            app.AntennaNameEditField = uieditfield(app.AntennaTx, 'text');
            app.ButtonGroup = uibuttongroup(app.AntennaTx);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ULAButton_2 = uiradiobutton(app.ButtonGroup);
            app.ULAButton_2.Text = '均匀线阵(ULA)';
            app.ULAButton_2.Value = true;
            app.UPAButton_2 = uiradiobutton(app.ButtonGroup);
            app.UPAButton_2.Text = '均匀面阵(UPA)';
            app.CylindricalButton = uiradiobutton(app.ButtonGroup);
            app.CylindricalButton.Text = '圆柱阵列(Cylindrical)';
            app.ButtonPreview = uibutton(app.UIFigure, 'push');
            app.ButtonPreview.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewPushed, true);
            app.ButtonPreview.Text = '预览 (Preview)';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '保存并关闭(Save and Close)';
            app.AntennaarrayconfigurationLabel = uilabel(app.UIFigure);
            app.AntennaarrayconfigurationLabel.Text = '天线阵列配置 (Antenna array configuration)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = antenna(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end