classdef chooseAntenna_tx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        SelectmodifyantennaarrayintheTxsidePanel  matlab.ui.container.Panel
        Text_Display    matlab.ui.control.TextArea
        ChooseButton    matlab.ui.control.Button
        AntennaNumber   matlab.ui.control.DropDown
        AntennaidLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp; % Description
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, antenna_number, selection_tx)
            app.CallingApp = mainapp;
            app.AntennaNumber.Items = antenna_number;
            app.AntennaNumber.Value = antenna_number(1);
            % 获取选中的所有行索引
            selectedRows = unique(app.CallingApp.selection_tx(:,1)); % 仅行索引，去除重复

            % 获取选中行的编号列数据
            selectedIds = app.CallingApp.UITable_tx.Data{selectedRows, 1}; %

            % 将表格列数据转换为字符串
            selectedIdsStr = strjoin(cellstr(selectedIds), ', ');
            message = ['Select/modify the antenna arrays of the ' selectedIdsStr ' users'];

            % 显示消息在文本组件中
            app.Text_Display.Value = message;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)

            % 获取选中的所有行索引
            selectedRows = unique(app.CallingApp.selection_tx(:,1)); % 仅行索引，去除重复

            % 获取当前表格数据
            tableData = app.CallingApp.UITable_tx.Data;

            % 更新选中行的第四列
            for i = 1:length(selectedRows)
                rowIndex = selectedRows(i);
                if rowIndex <= size(tableData, 1)
                    tableData(rowIndex, 4) = {app.AntennaNumber.Value};
                end
            end

            % 将更新后的数据重新设置到表格中
            app.CallingApp.UITable_tx.Data = tableData;

            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.settingButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectmodifyantennaarrayintheTxsidePanel = uipanel(app.UIFigure);
            app.SelectmodifyantennaarrayintheTxsidePanel.Title = 'Select/modify antenna array in the Tx side';
            app.AntennaidLabel = uilabel(app.SelectmodifyantennaarrayintheTxsidePanel);
            app.AntennaidLabel.Text = 'Antenna id';
            app.AntennaNumber = uidropdown(app.SelectmodifyantennaarrayintheTxsidePanel);
            app.AntennaNumber.Items = {'1', '2', '3', '4'};
            app.AntennaNumber.Value = '1';
            app.ChooseButton = uibutton(app.SelectmodifyantennaarrayintheTxsidePanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Confirm';
            app.Text_Display = uitextarea(app.SelectmodifyantennaarrayintheTxsidePanel);
            app.Text_Display.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseAntenna_tx(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end