classdef guiAdvice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SuggestionsrequiredTextAreaLabel  matlab.ui.control.Label
        suggest                matlab.ui.control.TextArea
        JobtitleoptionalLabel  matlab.ui.control.Label
        editFieldOther         matlab.ui.control.EditField
        JobtitleDropDown       matlab.ui.control.DropDown
        SubmitButton           matlab.ui.control.Button
        res                    matlab.ui.control.EditField
        ResearchLabel          matlab.ui.control.Label
        email                  matlab.ui.control.EditField
        TitleLabel             matlab.ui.control.Label
        aff                    matlab.ui.control.EditField
        AffiliationLabel       matlab.ui.control.Label
        name                   matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp;
    end
    properties (Access = private)
        selectedTitle = '教授/研究员(Professor)'; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % 数据库连接信息
            conn = databaseConnection();

            % 其他要插入的变量值
            user_id = app.CallingApp.UserId; %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 这里需要替换成userId
            usname = app.name.Value;
            usaff = app.aff .Value;
            ustitle = app.selectedTitle;
            usemail = app.email.Value;
            usres = app.res.Value;
            ussuggest = app.suggest.Value;
            timeValue = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');  % 获取当前时间
            disp(app.CallingApp.UserId);

            
            if ~isempty(usname)
                [encryptedusname] = tools.encryptData(usname);
            end

            if ~isempty(usaff)
                [encryptedusaff] = tools.encryptData(usaff);
            end

            if ~isempty(ustitle)
                [encryptedustitle] = tools.encryptData(ustitle);
            end

            if ~isempty(usemail)
                [encryptedusemail] = tools.encryptData(usemail);
            end

            if ~isempty(usres)
                [encryptedusres] = tools.encryptData(usres);
            end


            if isempty(ussuggest)
                % app.backAdviceApp = backAdvice();
                msgbox("请输入建议！Please write suggestions.","Error");

            else
                if iscell(ussuggest)
                    ussuggest = strjoin(ussuggest, ' '); % 将单元格数组合并为一个字符串
                end

                % 替换换行符以避免SQL语句中断
                ussuggest = strrep(ussuggest, newline, ' ');

               
                % 构建插入数据的 SQL 语句
                insertQuery = ['INSERT INTO advice (user_id, user_name, user_affiliation, user_title, user_email, user_research, user_advice, time, version) '...
                    'VALUES (''', user_id, ''', ''', encryptedusname, ''', ''', encryptedusaff, ''', ''', encryptedustitle, ''', ''', encryptedusemail, ''', ''', encryptedusres, ''', ''', ussuggest, ''', ''', datestr(timeValue, 'yyyy-mm-dd HH:MM:SS'), ''', ''v2'')'];
                % 执行插入操作
                curs = exec(conn, insertQuery);

                % 检查是否有错误
                if strcmp(curs.Message, 'Invalid connection.')
%                     app.adviceFailApp = adviceFail();
                    msgbox("上传未成功，请检查网络或与我们联系 Upload Failure. Please check network connections.", "Error");
                elseif strcmp(curs.Message, 'Data truncation: Data too long for column ''user_advice'' at row 1')
                    msgbox("上传未成功，请减少建议字数 Upload Failure. Please reduce suggestions", "Error");
                elseif isempty(curs.Message)
                    msgbox("上传成功，感谢您的反馈！Upload Success. Thank you for suggestions.", "Success");
                    app.CallingApp.advice.Enable = 'on';
                    delete(app);
                else
                    msgbox("上传未成功，请关注公众号与我们联系 Upload Failure. Please contact us.", "Error");

                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.advice.Enable = 'on';
            delete(app);
        end

        % Value changed function: JobtitleDropDown
        function JobtitleDropDownValueChanged(app, event)
            app.selectedTitle = app.JobtitleDropDown.Value;

            % 检查是否选择了“其他”
            if strcmp(app.selectedTitle, '其他(Other)')
                app.editFieldOther.Visible = 'on'; % 显示输入框
                app.selectedTitle= app.editFieldOther.Value;
            else
                app.editFieldOther.Visible = 'off'; % 隐藏输入框
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '您的姓名 选填 (Name, optional)';
            app.name = uieditfield(app.UIFigure, 'text');
            app.AffiliationLabel = uilabel(app.UIFigure);
            app.AffiliationLabel.Text = '您的单位 选填 (Affiliation, optional)';
            app.aff = uieditfield(app.UIFigure, 'text');
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = '您的邮箱 选填 (E-mail, optional)';
            app.email = uieditfield(app.UIFigure, 'text');
            app.ResearchLabel = uilabel(app.UIFigure);
            app.ResearchLabel.Text = '您的研究方向 选填(Research, optional)';
            app.res = uieditfield(app.UIFigure, 'text');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = {'提交'; 'Submit'};
            app.JobtitleDropDown = uidropdown(app.UIFigure);
            app.JobtitleDropDown.Items = {'教授/研究员(Professor)', '副教授/副研究员(Associate professor)', '助理教授/助理研究员(Assistant professor)', '工程师(Engineer)', '学生(Student)', '其他(Other)'};
            app.JobtitleDropDown.ValueChangedFcn = createCallbackFcn(app, @JobtitleDropDownValueChanged, true);
            app.JobtitleDropDown.Value = '教授/研究员(Professor)';
            app.editFieldOther = uieditfield(app.UIFigure, 'text');
            app.editFieldOther.Visible = 'off';
            app.JobtitleoptionalLabel = uilabel(app.UIFigure);
            app.JobtitleoptionalLabel.Text = '您的职称 选填 (Job title, optional)';
            app.suggest = uitextarea(app.UIFigure);
            app.SuggestionsrequiredTextAreaLabel = uilabel(app.UIFigure);
            app.SuggestionsrequiredTextAreaLabel.Text = '您的建议 必填 (Suggestions, required)*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiAdvice(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end