classdef guiAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_lsfs                      matlab.ui.container.Panel
        Button_saveSps_Largescale       matlab.ui.control.Button
        SetrainfallrateButton           matlab.ui.control.Button
        ReceivingUserIndexLabel_5       matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        DropDown_txUserIndex_lsfs       matlab.ui.control.DropDown
        TransmittingUserIndexLabel_5    matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        DropDown_rxUserIndex_lsfs       matlab.ui.control.DropDown
        Label_43                        matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        ChangeALparametersButton        matlab.ui.control.Button
        BLCheckBox                      matlab.ui.control.CheckBox
        PLCheckBox                      matlab.ui.control.CheckBox
        Label_BLConcerned               matlab.ui.control.Label
        Label_ALConcerned               matlab.ui.control.Label
        Label_PLConcerned               matlab.ui.control.Label
        Label_OTConcerned               matlab.ui.control.Label
        CheckBox_3dRxTx                 matlab.ui.control.CheckBox
        OTCheckBox                      matlab.ui.control.CheckBox
        ALCheckBox                      matlab.ui.control.CheckBox
        UIAxes_OT                       matlab.ui.control.UIAxes
        UIAxes_AL                       matlab.ui.control.UIAxes
        UIAxes_BL                       matlab.ui.control.UIAxes
        UIAxes_PL                       matlab.ui.control.UIAxes
        UIAxes_RxTx                     matlab.ui.control.UIAxes
        UIAxes_DisRxTx                  matlab.ui.control.UIAxes
        Panel_systemLevel               matlab.ui.container.Panel
        SNRLabel                        matlab.ui.control.Label
        Label_53                        matlab.ui.control.Label
        Label_52                        matlab.ui.control.Label
        Label_51                        matlab.ui.control.Label
        SNRInterval                     matlab.ui.control.NumericEditField
        IntervalEditFieldLabel          matlab.ui.control.Label
        SNRMinimum                      matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        SNRMaximum                      matlab.ui.control.NumericEditField
        TransmittingAntennaIndexLabel_2  matlab.ui.control.Label
        MaximumEditFieldLabel           matlab.ui.control.Label
        ReceivingAntennaIndexLabel_2    matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        DropDown_rxAntennaIndex_system  matlab.ui.control.DropDown
        Label_48                        matlab.ui.control.Label
        DropDown_txAntennaIndex_system  matlab.ui.control.DropDown
        Label_47                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        ReceivingUserIndexLabel_4       matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        DropDown_txUserIndex_system     matlab.ui.control.DropDown
        TransmittingUserIndexLabel_4    matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        DropDown_rxUserIndex_system     matlab.ui.control.DropDown
        Label_39                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Button_saveSps_system           matlab.ui.control.Button
        EditField_loop_system           matlab.ui.control.NumericEditField
        NumberofSimulationsLabel_3      matlab.ui.control.Label
        UIAxes_AAoAs                    matlab.ui.control.UIAxes
        UIAxes_pdfP                     matlab.ui.control.UIAxes
        UIAxes_pdfA                     matlab.ui.control.UIAxes
        UIAxes_channelCapacity          matlab.ui.control.UIAxes
        Panel_spaceDomain               matlab.ui.container.Panel
        ReceivingUserIndexLabel_3       matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Button_saveSps_spaceDomain      matlab.ui.control.Button
        DropDown_txUserIndex_space      matlab.ui.control.DropDown
        TransmittingUserIndexLabel_3    matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        DropDown_rxUserIndex_space      matlab.ui.control.DropDown
        Label_35                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        DeterminewhichsidetoanalyzeButtonGroup  matlab.ui.container.ButtonGroup
        Button_rx                       matlab.ui.control.RadioButton
        Button_tx                       matlab.ui.control.RadioButton
        EditField_loop_space            matlab.ui.control.NumericEditField
        NumberofSimulationsLabel_4      matlab.ui.control.Label
        UIAxes_LCRs                     matlab.ui.control.UIAxes
        UIAxes_SD                       matlab.ui.control.UIAxes
        UIAxes_sptialdopplerPSD         matlab.ui.control.UIAxes
        UIAxes_angleSpreads             matlab.ui.control.UIAxes
        UIAxes_cohDistance              matlab.ui.control.UIAxes
        UIAxes_SCCF                     matlab.ui.control.UIAxes
        Panel_freqDomain                matlab.ui.container.Panel
        EditField_simPoints_freq        matlab.ui.control.NumericEditField
        Label_50                        matlab.ui.control.Label
        ReceivingUserIndexLabel_2       matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        DropDown_txUserIndex_freq       matlab.ui.control.DropDown
        TransmittingUserIndexLabel_2    matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        DropDown_rxUserIndex_freq       matlab.ui.control.DropDown
        Label_31                        matlab.ui.control.Label
        Button_saveSps_freqDomain       matlab.ui.control.Button
        Label_30                        matlab.ui.control.Label
        NumberofFrequencyPointsLabel    matlab.ui.control.Label
        NumberofSimulationsLabel_2      matlab.ui.control.Label
        ReceivingAntennaIndexLabel      matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        TransmittingAntennaIndexLabel   matlab.ui.control.Label
        EditField_loop_freq             matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        DropDown_rxAntennaIndex_freq    matlab.ui.control.DropDown
        Label_17                        matlab.ui.control.Label
        DropDown_txAntennaIndex_freq    matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        UIAxes_LCRf                     matlab.ui.control.UIAxes
        UIAxes_SB                       matlab.ui.control.UIAxes
        UIAxes_delaySpread              matlab.ui.control.UIAxes
        UIAxes_cohBandWidth             matlab.ui.control.UIAxes
        UIA_delayPSD                    matlab.ui.control.UIAxes
        UIAxes_FCF                      matlab.ui.control.UIAxes
        Panel_timeDomain                matlab.ui.container.Panel
        ClickthepaneltoupdateLabel      matlab.ui.control.Label
        ReceivingUserIndexLabel         matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        DropDown_txUserIndex            matlab.ui.control.DropDown
        TransmittingUserIndexLabel      matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        DropDown_rxUserIndex            matlab.ui.control.DropDown
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        NumberofSimulationSnapshotsLabel  matlab.ui.control.Label
        NumberofSimulationsLabel        matlab.ui.control.Label
        ReceivingAntennaIndexLabel_3    matlab.ui.control.Label
        TransmittingAntennaIndexLabel_3  matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Button_saveSps_timeDomain       matlab.ui.control.Button
        Button_generate                 matlab.ui.control.Button
        DropDown_type                   matlab.ui.control.DropDown
        EditField_simPoints             matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        EditField_loop                  matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        DropDown_rxAntennaIndex         matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        DropDown_txAntennaIndex         matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        UIAxes_LCRt                     matlab.ui.control.UIAxes
        UIAxes_SI                       matlab.ui.control.UIAxes
        UIADopplerSpread                matlab.ui.control.UIAxes
        UIAxes_cohTime                  matlab.ui.control.UIAxes
        UIA_DopplerPSD                  matlab.ui.control.UIAxes
        UIAxes_TACF                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
        CallingApp;  % Description
    end
    
    properties (Access = private)
        Property2 % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.UIFigure.Name = '信道分析(Channel Analysis)';
            
            app.Panel_timeDomain.Parent = app.UIFigure;
            app.Panel_freqDomain.Parent = app.UIFigure;
            app.Panel_spaceDomain.Parent = app.UIFigure;
            app.Panel_lsfs.Parent = app.UIFigure;
            app.Panel_systemLevel.Parent = app.UIFigure;
            app.Button_generate.Parent = app.UIFigure;
            app.DropDown_type.Parent = app.UIFigure;
            app.ClickthepaneltoupdateLabel.Parent = app.UIFigure;
            
            if tools.is_owc_band(app.CallingApp.scenarios, app.CallingApp)
                app.DropDown_type.Items = {'时域/多普勒域信道统计特性 (Time domain)', '频域/时延域信道统计特性 (Frequency domain)', '空域/角度域信道统计特性 (Space domain)'};
            end
            app.DropDown_type.Value = '时域/多普勒域信道统计特性 (Time domain)';
            tools.show_guiAnalysis_dropdownType(app);
        end

        % Button pushed function: Button_generate
        function Button_generatePushed(app, event)
             try
                mf.add_logs('guiAnalysis_generatePushed', 'Analyze channel properties, check parameter settings');
                if app.CallingApp.canAnalysis == 0
                    mf.add_logs('guiAnalysis_generatePushed', 'After modifying the frequency band and scenario, click the Run button first to generate the channel matrix');
                    errordlg('After modifying the frequency band and scenario, click the Run button first to generate the channel matrix', 'error: 400',"modal");
                    return;
                end

                tools.setAppScenarios(app.CallingApp)
                scenario = app.CallingApp.scenarios;

                [freqs, bandwidth, freq_sample] = mf.str2frequencys(app.CallingApp.EditField.Value,app.CallingApp.bandwidth.Value,app.CallingApp.freqSamples.Value);
                if strfind(scenario, app.CallingApp.sps.scenario_UWA)
                    app.CallingApp.sps.carrier_frequency = freqs*1e3;
                    B = bandwidth*1e3;
                else
                    app.CallingApp.sps.carrier_frequency = freqs*1e9;
                    B = bandwidth*1e6;
                end
                
                [cm, tx_track, rx_track, cm_l] = tools.preGenSPs(app.CallingApp, B);

                %显示当前参数
                show_check_allsettings(app.CallingApp, scenario);
                
                result = app.CallingApp.channelresults.results;
                ssps = app.CallingApp.channelresults.ssps;
                savePath = app.CallingApp.channelresults.savePath;

                owc = tools.gen_owc_gui(app.CallingApp);
                ris = tools.gen_ris_gui(app.CallingApp);

                tools.show_guiAnalysis_dropdownType(app);
                if isempty(result)
                    mf.add_logs('guiAnalysis_generatePushed', 'Please click the Run button first to generate the channel matrix');
                    errordlg('Please click the Run button first to generate the channel matrix', 'error: 400',"modal");
                    return;
                end

                if strfind(app.DropDown_type.Value, 'Large scale fadings')
                    mf.add_logs('guiAnalysis_generatePushed', 'Generating the large-scale fading');
                    get_statisticalProps_lsfs(app, cm,  tx_track, rx_track, savePath, ssps);
                    
                elseif strfind(app.DropDown_type.Value, 'Time domain')
                    mf.add_logs('guiAnalysis_generatePushed', 'Generating time- and Doppler-domain channel statistical properties');
                    no_simPoints = min(length(tx_track(1).time_scale), app.EditField_simPoints.Value);
                    deltaT = tx_track(1).time_scale(1: no_simPoints)';
                    if length(deltaT) < 10
                        mf.add_logs('guiAnalysis_generatePushed', 'The number of time points is less than 10, should be increased to analyze the time- and Doppler-domain channel statistical properties');
                        warndlg('Warning: the number of simulation time points is less than 10, should be increased to analyze the time- and Doppler-domain channel statistical properties', "Inappropriate parameter setting", "model");
                        return;
                    end
                    if tools.is_owc_band(app.CallingApp.scenarios, app.CallingApp)
                        get_statisticalProps_time(app, cm, deltaT, owc, tx_track, rx_track, [], []);
                        return;
                    else
                        get_statisticalProps_time(app, cm, deltaT, [], tx_track, rx_track, ris, cm_l);
                    end

                elseif strfind(app.DropDown_type.Value, 'Frequency domain')
                    mf.add_logs('guiAnalysis_generatePushed', 'Simulating frequency- and delay-domain channel statistical properties');
                    freq_sample = freq_sample(1);
                    app.EditField_simPoints_freq.Value = freq_sample;
                    if ~strfind(app.CallingApp.scenarios, app.CallingApp.sps.scenario_ISAC)
                        i_snap = 1; % 取第1个时间点的track量
                        [tx_track, rx_track] = mf.get_x_snap_track(i_snap, tx_track, rx_track);
                    end
                    
                    if tools.is_owc_band(app.CallingApp.scenarios, app.CallingApp)
                        deltaF = linspace(0, 100e9, 200);
                        get_statisticalProps_freq(app, cm, owc, tx_track, rx_track, [], deltaF, [], [], freq_sample, []);

                    else
                        if freq_sample < 50
                            mf.add_logs('guiAnalysis_generatePushed', 'The number of subcarriers is less than 50, should be increased to analyze the frequency- and delay-domain channel statistical properties');
                            warndlg('Warning: the number of subcarriers is less than 50, should be increased to analyze the frequency- and delay-domain channel statistical properties', "Inappropriate parameter setting", "model");
                            return;
                        end

                        if length(freq_sample) == 1
                            freq_sample = repmat(freq_sample,1,length(freqs));
                        end
                        if length(bandwidth) == 1
                            bandwidth = repmat(bandwidth,1,length(freqs));
                        end
                        for i_freq = 1:length(freqs)
                            deltaF{i_freq} = linspace(0, bandwidth(i_freq), freq_sample(i_freq))*1e6;
                        end
                        get_statisticalProps_freq(app, cm, [], tx_track, rx_track, ris, deltaF, freqs*1e9, bandwidth*1e6, freq_sample, cm_l);
                    end

                elseif strfind(app.DropDown_type.Value, 'Space domain')
                    mf.add_logs('guiAnalysis_generatePushed', 'Simulating space- and angular domain channel statistical properties');
                    if ~strfind(app.CallingApp.scenarios, app.CallingApp.sps.scenario_ISAC)
                        i_snap = 1; % 取第1个时间点的track量
                        [tx_track, rx_track] = mf.get_x_snap_track(i_snap, tx_track, rx_track);
                    end
                    if tools.is_owc_band(app.CallingApp.scenarios, app.CallingApp)
                        get_statisticalProps_space(app, cm, [], owc, tx_track, rx_track, app.Button_tx.Value, [], cm_l);
                    else
                        get_statisticalProps_space(app, cm, [], [], tx_track, rx_track, app.Button_tx.Value, ris, cm_l);
                    end
                else
                    mf.add_logs('guiAnalysis_generatePushed', 'Simulating other channel statistical properties');
                    get_statisticalProps_system(app, cm, [], [], tx_track, rx_track, app.Button_tx.Value, ris, cm_l);
                end
                mf.add_logs('guiAnalysis_generatePushed', 'Simulation results');
            catch exception
                % 输出异常信息
                disp(['Error occurred in function: ', exception.stack(1).name]);
                disp(['Error message: ', exception.message]);
                disp(['Error on line: ', num2str(exception.stack(1).line)]);
                % 显示错误对话框
                errordlg(exception.message, 'Error', 'modal');
                return;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 弹出确认关闭对话框
            choice = uiconfirm(app.UIFigure,'Confirm to close?', 'Confirm close', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if strcmp(choice, 'Confirm')
                app.CallingApp.Button_Analysis.Enable = 'on';
                delete(app);
            end
        end

        % Value changed function: DropDown_type
        function DropDown_typeValueChanged(app, event)
            tools.show_guiAnalysis_dropdownType(app);
        end

        % Value changed function: ALCheckBox
        function ALCheckBoxValueChanged(app, event)
            value = app.ALCheckBox.Value;
            if value
                app.ChangeALparametersButton.Visible = 'on';
            else
                app.ChangeALparametersButton.Visible = 'off';
            end
        end

        % Callback function
        function EditField_BLValueChanged(app, event)
            value = app.EditField_BL.Value;
            blockPos = mf.str2positions(value);
            app.CallingApp.lsf_confs.N = size(blockPos,1);
            app.CallingApp.lsf_confs.X = blockPos(:,1);
            app.CallingApp.lsf_confs.Y = blockPos(:,2);
            app.CallingApp.lsf_confs.Z = blockPos(:,3);
        end

        % Callback function
        function ALTableCellEdit(app, event)
            indice = event.Indices(1,2);
            switch indice
                case 1
                    app.CallingApp.lsf_confs.T = event.NewData;
                case 2
                    app.CallingApp.lsf_confs.P = event.NewData;
                otherwise
                    app.CallingApp.lsf_confs.W = event.NewData;
            end
        end

        % Button pushed function: Button_saveSps_timeDomain
        function Button_saveSps_timeDomainPushed(app, event)
            mf.add_logs('saveSps_timeDomainPushed', 'Saving time- and Doppler-domain simulation graphics');
            image_names = {'UIAxes_TACF', 'UIA_DopplerPSD', 'UIAxes_LCRt', 'UIAxes_cohTime', 'UIADopplerSpread', 'UIAxes_SI'};
            image_names_save = {'TACF', 'DopplerPSD', 'timeLCR', 'coherenceTime', 'DopplerSpread', 'statinaryTime'};
            %判断当前有没有生成图像
            axes_component = findobj(app.UIFigure, 'Tag', image_names{1});
            line_object = findobj(axes_component, 'Type', 'line');
            if  isempty(line_object)
                mf.add_logs('saveSps_timeDomainPushed', 'Please click the Generate button to generate the image');
                msgbox('Please click the Generate button to generate the image')
            else
                %生成保存路径
                save_path = app.CallingApp.channelresults.savePath;
                assignin('base', 'save_path', save_path);
                % 创建目录
                if exist(save_path, 'dir')
                else
                    mkdir(save_path);
                end
                for i = 1:length(image_names)
                    axes_component = findobj(app.UIFigure, 'Tag', image_names{i});
                    line_objects = findobj(axes_component, 'Type', 'line');
                    assignin('base', 'line_object', line_object);
                    if  isempty(line_object)
                    else
                        %保存图像为fig格式
                        file_name_fig = strcat('time_doppler_domain_',image_names_save{i}, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        figure_handle = figure;
                        axes_copy = copyobj(axes_component, figure_handle);
                        ax = gca;  % 创建axes对象
                        set(figure_handle, 'Units', 'normalized');  % 设置figure对象的Units属性为'normalized'
                        set(ax, 'Units', 'normalized');  % 设置axes对象的Units属性为'normalized'
                        set(ax, 'OuterPosition', [0, 0, 1, 1]);  % 设置axes对象的OuterPosition属性
                        savefig(figure_handle,full_file_path_fig);
                        close(figure_handle);

                        %保存数据为mat格式
                        % 创建一个结构数组来保存线条的数据
                        line_data = struct();
                        % 遍历所有线条对象
                        for j = 1:numel(line_objects)
                            % 获取当前线条对象
                            line_object = line_objects(j);

                            % 获取线条对象的 x 和 y 坐标值
                            x_data = get(line_object, 'XData');
                            y_data = get(line_object, 'YData');

                            % 将坐标值保存到结构数组中
                            line_data(j).x = x_data;
                            line_data(j).y = y_data;
                        end
                        % 保存结构数组为 .mat 文件
                        file_name = strcat('time_doppler_domain_',image_names_save{i},'.mat');  % 文件名称
                        full_file_path = fullfile(save_path, file_name);
                        save(full_file_path, 'line_data');

                    end
                end
                mes=strcat('Files have been successfully saved to ', save_path);
                mf.add_logs('saveSps_timeDomainPushed', ['Files have been successfully saved to ', save_path]);
                msgbox(mes);
            end
        end

        % Button pushed function: Button_saveSps_freqDomain
        function Button_saveSps_freqDomainPushed(app, event)
            mf.add_logs('saveSps_freqDomainPushed', 'Saving frequency- and delay-domain simulation graphics');
            image_names = {'UIAxes_FCF', 'UIA_delayPSD', 'UIAxes_LCRf', 'UIAxes_cohBandWidth', 'UIAxes_delaySpread', 'UIAxes_SB'};
            image_names_save = {'FCF', 'delayPSD', 'frequencyLCR', 'coherenceBandWidth', 'delaySpread', 'stationaryBandWidth'};
            %判断当前有没有生成图像
            axes_component = findobj(app.UIFigure, 'Tag', image_names{1});
            line_object = findobj(axes_component, 'Type', 'line');
            if  isempty(line_object)
                mf.add_logs('saveSps_frequencyDomainPushed', 'Please click the Generate button to generate the image');
                msgbox('Please click the Generate button to generate the image')
            else
                %生成保存路径
                save_path = app.CallingApp.channelresults.savePath;
                assignin('base', 'save_path', save_path);
                % 创建目录
                if exist(save_path, 'dir')
                else
                    mkdir(save_path);
                end
                for i = 1:length(image_names)
                    axes_component = findobj(app.UIFigure, 'Tag', image_names{i});
                    line_objects = findobj(axes_component, 'Type', 'line');
                    assignin('base', 'line_object', line_object);
                    if  isempty(line_object)
                    else
                        %保存图像为fig格式
                        file_name_fig = strcat('frequency_doppler_domain_',image_names_save{i}, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        figure_handle = figure;
                        axes_copy = copyobj(axes_component, figure_handle);
                        ax = gca;  % 创建axes对象
                        set(figure_handle, 'Units', 'normalized');  % 设置figure对象的Units属性为'normalized'
                        set(ax, 'Units', 'normalized');  % 设置axes对象的Units属性为'normalized'
                        set(ax, 'OuterPosition', [0, 0, 1, 1]);  % 设置axes对象的OuterPosition属性
                        savefig(figure_handle,full_file_path_fig);
                        close(figure_handle);

                        %保存数据为mat格式
                        % 创建一个结构数组来保存线条的数据
                        line_data = struct();
                        % 遍历所有线条对象
                        for j = 1:numel(line_objects)
                            % 获取当前线条对象
                            line_object = line_objects(j);

                            % 获取线条对象的 x 和 y 坐标值
                            x_data = get(line_object, 'XData');
                            y_data = get(line_object, 'YData');

                            % 将坐标值保存到结构数组中
                            line_data(j).x = x_data;
                            line_data(j).y = y_data;
                        end
                        % 保存结构数组为 .mat 文件
                        file_name = strcat('frequency_doppler_domain_',image_names_save{i},'.mat');  % 文件名称
                        full_file_path = fullfile(save_path, file_name);
                        save(full_file_path, 'line_data');

                    end
                end
                mes=strcat('Files have been successfully saved to ', save_path);
                mf.add_logs('saveSps_frequencyDomainPushed', ['Files have been successfully saved to ', save_path]);
                msgbox(mes);
            end

        end

        % Button pushed function: Button_saveSps_spaceDomain
        function Button_saveSps_spaceDomainPushed(app, event)
            mf.add_logs('saveSps_spaceDomainPushed', 'Saving space- and angular domain simulation graphics');
            image_names = {'UIAxes_SCCF', 'UIAxes_sptialdopplerPSD', 'UIAxes_LCRs', 'UIAxes_cohDistance', 'UIAxes_angleSpreads', 'UIAxes_SD'};
            image_names_save = {'SCCF', 'sptialdopplerPSD', 'spaceLCR', 'coherenceDistance', 'angleSpreads', 'stationaryDistance'};
            %判断当前有没有生成图像
            axes_component = findobj(app.UIFigure, 'Tag', image_names{1});
            line_object = findobj(axes_component, 'Type', 'line');
            if  isempty(line_object)
                mf.add_logs('saveSps_spaceDomainPushed', 'Please click the Generate button to generate the image');
                msgbox('Please click the Generate button to generate the image')
            else
                %生成保存路径
                save_path = app.CallingApp.channelresults.savePath;
                assignin('base', 'save_path', save_path);
                % 创建目录
                if exist(save_path, 'dir')
                else
                    mkdir(save_path);
                end
                for i = 1:length(image_names)
                    axes_component = findobj(app.UIFigure, 'Tag', image_names{i});
                    line_objects = findobj(axes_component, 'Type', 'line');
                    assignin('base', 'line_object', line_object);
                    if  isempty(line_object)
                    else
                        %保存图像为fig格式
                        file_name_fig = strcat('space_doppler_domain_',image_names_save{i}, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        figure_handle = figure;
                        axes_copy = copyobj(axes_component, figure_handle);
                        ax = gca;  % 创建axes对象
                        set(figure_handle, 'Units', 'normalized');  % 设置figure对象的Units属性为'normalized'
                        set(ax, 'Units', 'normalized');  % 设置axes对象的Units属性为'normalized'
                        set(ax, 'OuterPosition', [0, 0, 1, 1]);  % 设置axes对象的OuterPosition属性
                        savefig(figure_handle,full_file_path_fig);
                        close(figure_handle);

                        %保存数据为mat格式
                        % 创建一个结构数组来保存线条的数据
                        line_data = struct();
                        % 遍历所有线条对象
                        for j = 1:numel(line_objects)
                            % 获取当前线条对象
                            line_object = line_objects(j);

                            % 获取线条对象的 x 和 y 坐标值
                            x_data = get(line_object, 'XData');
                            y_data = get(line_object, 'YData');

                            % 将坐标值保存到结构数组中
                            line_data(j).x = x_data;
                            line_data(j).y = y_data;
                        end
                        % 保存结构数组为 .mat 文件
                        file_name = strcat('space_doppler_domain_',image_names_save{i},'.mat');  % 文件名称
                        full_file_path = fullfile(save_path, file_name);
                        save(full_file_path, 'line_data');

                    end
                end
                mes=strcat('Files have been successfully saved to ', save_path);
                mf.add_logs('saveSps_spaceDomainPushed', ['Files have been successfully saved to ', save_path]);
                msgbox(mes);
            end
        end

        % Button pushed function: Button_saveSps_system
        function Button_saveSps_systemPushed(app, event)
            mf.add_logs('saveSps_systemPushed', 'Saving other channel statistical property simulation results');
            image_names = {'UIAxes_channelCapacity', 'UIAxes_pdfA', 'UIAxes_pdfP','UIAxes_AAoAs'};
            image_names_save = {'channelCapacity', 'pdfA', 'pdfP','AAoAs'};
            %判断当前有没有生成图像
            axes_component = findobj(app.UIFigure, 'Tag', image_names{1});
            line_object = findobj(axes_component, 'Type', 'line');
            if  isempty(line_object)
                mf.add_logs('saveSps_timeDomainPushed', 'Please click the Generate button to generate the image');
                msgbox('Please click the Generate button to generate the image')
            else
                %生成保存路径
                save_path = app.CallingApp.channelresults.savePath;
                assignin('base', 'save_path', save_path);
                % 创建目录
                if exist(save_path, 'dir')
                else
                    mkdir(save_path);
                end
                for i = 1:length(image_names)
                    axes_component = findobj(app.UIFigure, 'Tag', image_names{i});
                    line_objects = findobj(axes_component, ...
                        '-and', 'Type', 'line', ...
                        '-or', 'Type', 'bar');
                    assignin('base', 'line_object', line_object);
                    if  isempty(line_object)
                    else
                        %保存图像为fig格式
                        file_name_fig = strcat('channel_',image_names_save{i}, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        figure_handle = figure;
                        axes_copy = copyobj(axes_component, figure_handle);
                        ax = gca;  % 创建axes对象
                        set(figure_handle, 'Units', 'normalized');  % 设置figure对象的Units属性为'normalized'
                        set(ax, 'Units', 'normalized');  % 设置axes对象的Units属性为'normalized'
                        set(ax, 'OuterPosition', [0, 0, 1, 1]);  % 设置axes对象的OuterPosition属性
                        savefig(figure_handle,full_file_path_fig);
                        close(figure_handle);

                        %保存数据为mat格式
                        % 创建一个结构数组来保存线条的数据
                        line_data = struct();
                        % 遍历所有线条对象
                        for j = 1:numel(line_objects)
                            % 获取当前线条对象
                            line_object = line_objects(j);

                            % 获取线条对象的 x 和 y 坐标值
                            x_data = get(line_object, 'XData');
                            y_data = get(line_object, 'YData');

                            % 将坐标值保存到结构数组中
                            line_data(j).x = x_data;
                            line_data(j).y = y_data;
                        end
                        % 保存结构数组为 .mat 文件
                        file_name = strcat('channel_',image_names_save{i},'.mat');  % 文件名称
                        full_file_path = fullfile(save_path, file_name);
                        save(full_file_path, 'line_data');

                    end
                end
                mes=strcat('Files have been successfully saved to ', save_path);
                mf.add_logs('saveSps_channelPushed', ['Files have been successfully saved to ', save_path]);
                msgbox(mes);
            end
        end

        % Button pushed function: ChangeALparametersButton
        function ChangeALparametersButtonPushed(app, event)
            prompt={'Temperature(℃)','Atmospheric pressure(kPa)','Water vapour density(g/m3)'};
            defans={num2str(app.CallingApp.lsf_confs.T),num2str(app.CallingApp.lsf_confs.P),num2str(app.CallingApp.lsf_confs.W)};
            p = inputdlg(prompt,'AL parameters',1,defans);
            if ~isempty(p)
                app.CallingApp.lsf_confs.T = str2double(p{1});
                app.CallingApp.lsf_confs.P = str2double(p{2});
                app.CallingApp.lsf_confs.W = str2double(p{3});
            end
        end

        % Button down function: Panel_timeDomain
        function Panel_timeDomainButtonDown(app, event)
            users_tx = app.CallingApp.UITable_tx.Data{:,1};
            users_rx = app.CallingApp.UITable_rx.Data{:,1};
            % 设置 DropDown 的 items 属性
            app.DropDown_txUserIndex.Items = users_tx;
            app.DropDown_rxUserIndex.Items = users_rx;
            %设置天线
            %发送端
            userNum_tx = str2double(app.DropDown_txUserIndex.Value);                %发送端用户编号
            atNum_txuser = str2double(app.CallingApp.UITable_tx.Data{userNum_tx,4});%发送端用户天线序列编号
            if  contains(app.CallingApp.UITable_antenna.Data{atNum_txuser,12}, '均匀线阵(ULA)')          %线阵
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{atNum_txuser,3});%天线阵元总数
            else%面阵或圆柱阵
                %天线阵元总数
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{atNum_txuser,3}).*str2double(app.CallingApp.UITable_antenna.Data{atNum_txuser,4});
            end
            options_tx_atn = string(1:Num_atn_tx);
            app.DropDown_txAntennaIndex.Items = options_tx_atn;
            %接收端
            userNum_rx = str2double(app.DropDown_rxUserIndex.Value);                     %接收端端用户编号
            antennaNum_rxuser = str2double(app.CallingApp.UITable_rx.Data{userNum_rx,4});%接收端端用户天线序列编号
            if contains(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser,12}, '均匀线阵(ULA)')          %线阵
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser,3});%天线阵元总数
            else                                                                         %面阵或圆柱阵
                                                                                         %天线阵元总数
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser,3}).*str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser,4});
            end
            options_rx_atn = string(1:Num_atn_rx);
            app.DropDown_rxAntennaIndex.Items = options_rx_atn;
        end

        % Button down function: Panel_freqDomain
        function Panel_freqDomainButtonDown(app, event)
            users_tx_freq = app.CallingApp.UITable_tx.Data{:,1};
            users_rx_freq = app.CallingApp.UITable_rx.Data{:,1};
            % 设置 DropDown 的 items 属性
            app.DropDown_txUserIndex_freq.Items = users_tx_freq;
            app.DropDown_rxUserIndex_freq.Items = users_rx_freq;
            %设置天线
            %发送端
            userNum_tx_freq = str2double(app.DropDown_txUserIndex_freq.Value);                     %发送端用户编号
            antennaNum_txuser_freq = str2double(app.CallingApp.UITable_tx.Data{userNum_tx_freq,4});%发送端用户天线序列编号
            if  app.CallingApp.UITable_antenna.Data{antennaNum_txuser_freq,4} == "\"               %线阵
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_freq,3});%天线阵元总数
            else                                                                                   %面阵或圆柱阵
                                                                                                   %天线阵元总数
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_freq,3}).*str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_freq,4});
            end
            options_tx_atn_freq = string(1:Num_atn_tx);
            app.DropDown_txAntennaIndex_freq.Items = options_tx_atn_freq;
            %接收端
            userNum_rx_freq = str2double(app.DropDown_rxUserIndex_freq.Value);                     %发送端用户编号
            antennaNum_rxuser_freq = str2double(app.CallingApp.UITable_rx.Data{userNum_rx_freq,4});%发送端用户天线序列编号
            if  app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_freq,4} == "\"               %线阵
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_freq,3});%天线阵元总数
            else                                                                                   %面阵或圆柱阵
                                                                                                   %天线阵元总数
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_freq,3}).*str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_freq,4});
            end
            options_rx_atn = string(1:Num_atn_rx);
            app.DropDown_rxAntennaIndex_freq.Items = options_rx_atn;
        end

        % Button down function: Panel_spaceDomain
        function Panel_spaceDomainButtonDown(app, event)
            users_tx_space = app.CallingApp.UITable_tx.Data{:,1};
            users_rx_space = app.CallingApp.UITable_rx.Data{:,1};
            app.DropDown_txUserIndex_space.Items = users_tx_space;
            app.DropDown_rxUserIndex_space.Items = users_rx_space;
        end

        % Button down function: Panel_lsfs
        function Panel_lsfsButtonDown(app, event)
            users_tx_lsfs = app.CallingApp.UITable_tx.Data{:,1};
            users_rx_lsfs = app.CallingApp.UITable_rx.Data{:,1};
            app.DropDown_txUserIndex_lsfs.Items = users_tx_lsfs;
            app.DropDown_rxUserIndex_lsfs.Items = users_rx_lsfs;
        end

        % Button down function: Panel_systemLevel
        function Panel_systemLevelButtonDown(app, event)
            users_tx_system = app.CallingApp.UITable_tx.Data{:,1};
            users_rx_system = app.CallingApp.UITable_rx.Data{:,1};
            % 设置 DropDown 的 items 属性
            app.DropDown_txUserIndex_system.Items = users_tx_system;
            app.DropDown_rxUserIndex_system.Items = users_rx_system;
            %设置天线
            %发送端
            userNum_tx_system = str2double(app.DropDown_txUserIndex_system.Value);                     %发送端用户编号
            antennaNum_txuser_system = str2double(app.CallingApp.UITable_tx.Data{userNum_tx_system,4});%发送端用户天线序列编号
            if  app.CallingApp.UITable_antenna.Data{antennaNum_txuser_system,4} == "\"               %线阵
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_system,3});%天线阵元总数
            else                                                                                   %面阵或圆柱阵
                                                                                                   %天线阵元总数
                Num_atn_tx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_system,3}).*str2double(app.CallingApp.UITable_antenna.Data{antennaNum_txuser_system,4});
            end
            options_tx_atn_system = string(1:Num_atn_tx);
            app.DropDown_txAntennaIndex_system.Items = options_tx_atn_system;
            %接收端
            userNum_rx_system = str2double(app.DropDown_rxUserIndex_system.Value);                     %发送端用户编号
            antennaNum_rxuser_system = str2double(app.CallingApp.UITable_rx.Data{userNum_rx_system,4});%发送端用户天线序列编号
            if  app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_system,4} == "\"               %线阵
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_system,3});%天线阵元总数
            else                                                                                   %面阵或圆柱阵
                                                                                                   %天线阵元总数
                Num_atn_rx = str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_system,3}).*str2double(app.CallingApp.UITable_antenna.Data{antennaNum_rxuser_system,4});
            end
            options_rx_atn = string(1:Num_atn_rx);
            app.DropDown_rxAntennaIndex_system.Items = options_rx_atn;
        end

        % Value changed function: SNRMinimum
        function SNRMinimumValueChanged(app, event)
            value = app.SNRMaximum.Value;
            app.SNRMinimum.Limits = [-100 value];
        end

        % Value changed function: SNRMaximum
        function SNRMaximumValueChanged(app, event)
            value = app.SNRMinimum.Value;
            app.SNRMaximum.Limits = [value 100];
        end

        % Callback function
        function Panel_lsfsSizeChanged(app, event)
            position = app.Panel_lsfs.Position;
            
        end

        % Value changed function: OTCheckBox
        function OTCheckBoxValueChanged(app, event)
            value = app.OTCheckBox.Value;
            if value && (contains(app.CallingApp.scenarios,'UMi') || contains(app.CallingApp.scenarios,'UMa') || contains(app.CallingApp.scenarios,'RMa'))
                app.SetrainfallrateButton.Visible = 'on';
            else
                app.SetrainfallrateButton.Visible = 'off';
            end
        end

        % Button pushed function: SetrainfallrateButton
        function ChangeOTparametersButtonPushed(app, event)
            prompt={'Rainfall rate (mm/h)'};
            defans={num2str(10)};
            r = inputdlg(prompt,'Rainfall rate',1,defans);
            if  ~isempty(r)
            %     app.CallingApp.lsf_confs.R = 0;
            % else
                app.CallingApp.lsf_confs.R = str2double(r{1});
            end
        end

        % Value changed function: EditField_loop_freq
        function EditField_loop_freqValueChanged(app, event)
            value = app.EditField_loop_freq.Value;
            
        end

        % Button pushed function: Button_saveSps_Largescale
        function Button_saveSps_LargescalePushed(app, event)
            mf.add_logs('saveSps_LargescalePushed', 'Saving Large scale simulation graphics');
            image_names = {'UIAxes_DisRxTx', 'UIAxes_PL', 'UIAxes_BL', 'UIAxes_AL', 'UIAxes_OT', 'UIAxes_RxTx'};
            image_names_save = {'DisRxTx', 'PL', 'BL', 'AL', 'OT', 'RxTx'};
            %判断当前有没有生成图像
            axes_component = findobj(app.UIFigure, 'Tag', image_names{1});
            line_object = findobj(axes_component, 'Type', 'line');
            if  isempty(line_object)
                mf.add_logs('saveSps_LargescalePushed', 'Please click the Generate button to generate the image');
                msgbox('Please click the Generate button to generate the image')
            else
                %生成保存路径
                save_path = app.CallingApp.channelresults.savePath;
                assignin('base', 'save_path', save_path);
                % 创建目录
                if exist(save_path, 'dir')
                else
                    mkdir(save_path);
                end
                for i = 1:length(image_names)
                    axes_component = findobj(app.UIFigure, 'Tag', image_names{i});
                    line_objects = findobj(axes_component, 'Type', 'line');
                    assignin('base', 'line_object', line_object);
                    if  isempty(line_object)
                    else
                        %保存图像为fig格式
                        file_name_fig = strcat('Large_Scale_',image_names_save{i}, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        figure_handle = figure;
                        axes_copy = copyobj(axes_component, figure_handle);
                        ax = gca;  % 创建axes对象
                        set(figure_handle, 'Units', 'normalized');  % 设置figure对象的Units属性为'normalized'
                        set(ax, 'Units', 'normalized');  % 设置axes对象的Units属性为'normalized'
                        set(ax, 'OuterPosition', [0, 0, 1, 1]);  % 设置axes对象的OuterPosition属性
                        savefig(figure_handle,full_file_path_fig);
                        close(figure_handle);

                        %保存数据为mat格式
                        % 创建一个结构数组来保存线条的数据
                        line_data = struct();
                        % 遍历所有线条对象
                        for j = 1:numel(line_objects)
                            % 获取当前线条对象
                            line_object = line_objects(j);

                            % 获取线条对象的 x 和 y 坐标值
                            x_data = get(line_object, 'XData');
                            y_data = get(line_object, 'YData');

                            % 将坐标值保存到结构数组中
                            line_data(j).x = x_data;
                            line_data(j).y = y_data;
                        end
                        % 保存结构数组为 .mat 文件
                        file_name = strcat('Large_Scale_',image_names_save{i},'.mat');  % 文件名称
                        full_file_path = fullfile(save_path, file_name);
                        save(full_file_path, 'line_data');

                    end
                end
                mes=strcat('Files have been successfully saved to ', save_path);
                mf.add_logs('saveSps_LargeScale_', ['Files have been successfully saved to ', save_path]);
                msgbox(mes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_timeDomain = uipanel(app.UIFigure);
            app.Panel_timeDomain.Title = '时域、多普勒域统计特性分析(Time- and Doppler-domain Statistical Property Analysis)';
            app.Panel_timeDomain.ButtonDownFcn = createCallbackFcn(app, @Panel_timeDomainButtonDown, true);
            app.UIAxes_TACF = uiaxes(app.Panel_timeDomain);
            title(app.UIAxes_TACF, '时间自相关函数(Temporal  Auto-correlation Function)')
            xlabel(app.UIAxes_TACF, 'Time difference (s)')
            ylabel(app.UIAxes_TACF, 'Absolute value of local temporal ACF')
            zlabel(app.UIAxes_TACF, 'Z')
            app.UIAxes_TACF.Tag = 'UIAxes_TACF';
            app.UIA_DopplerPSD = uiaxes(app.Panel_timeDomain);
            title(app.UIA_DopplerPSD, '多普勒功率谱密度(Doppler Power Spectral Density)')
            xlabel(app.UIA_DopplerPSD, 'Doppler frequency (Hz)')
            ylabel(app.UIA_DopplerPSD, 'Doppler PSDs (dB)')
            zlabel(app.UIA_DopplerPSD, 'Z')
            app.UIA_DopplerPSD.Tag = 'UIA_DopplerPSD';
            app.UIAxes_cohTime = uiaxes(app.Panel_timeDomain);
            title(app.UIAxes_cohTime, '相干时间(Coherence Time)')
            xlabel(app.UIAxes_cohTime, 'Coherence time (s)')
            ylabel(app.UIAxes_cohTime, 'Threshold')
            zlabel(app.UIAxes_cohTime, 'Z')
            app.UIAxes_cohTime.Tag = 'UIAxes_cohTime';
            app.UIADopplerSpread = uiaxes(app.Panel_timeDomain);
            title(app.UIADopplerSpread, '多普勒扩展(Doppler Spread)')
            xlabel(app.UIADopplerSpread, 'Doppler spread (Hz)')
            ylabel(app.UIADopplerSpread, 'CDF')
            zlabel(app.UIADopplerSpread, 'Z')
            app.UIADopplerSpread.Tag = 'UIADopplerSpread';
            app.UIAxes_SI = uiaxes(app.Panel_timeDomain);
            title(app.UIAxes_SI, '平稳时间间隔(Stationary Time Interval)')
            xlabel(app.UIAxes_SI, 'Stationary interval (s)')
            ylabel(app.UIAxes_SI, 'CDF ')
            zlabel(app.UIAxes_SI, 'Z')
            app.UIAxes_SI.Tag = 'UIAxes_SI';
            app.UIAxes_LCRt = uiaxes(app.Panel_timeDomain);
            title(app.UIAxes_LCRt, '电平切割率(Level Crossing Rate)')
            xlabel(app.UIAxes_LCRt, 'Envelope level')
            ylabel(app.UIAxes_LCRt, 'Envelope LCR in the time domain')
            zlabel(app.UIAxes_LCRt, 'Z')
            app.UIAxes_LCRt.Tag = 'UIAxes_LCRt';
            app.Label_10 = uilabel(app.Panel_timeDomain);
            app.Label_10.Text = '根接收天线 ';
            app.Label_11 = uilabel(app.Panel_timeDomain);
            app.Label_11.Text = '第';
            app.DropDown_txAntennaIndex = uidropdown(app.Panel_timeDomain);
            app.DropDown_txAntennaIndex.Items = {'1', '2'};
            app.DropDown_txAntennaIndex.Value = '1';
            app.Label_12 = uilabel(app.Panel_timeDomain);
            app.Label_12.Text = '根发射天线';
            app.DropDown_rxAntennaIndex = uidropdown(app.Panel_timeDomain);
            app.DropDown_rxAntennaIndex.Items = {'1', '2'};
            app.DropDown_rxAntennaIndex.Value = '1';
            app.Label_13 = uilabel(app.Panel_timeDomain);
            app.Label_13.Text = '仿真次数';
            app.EditField_loop = uieditfield(app.Panel_timeDomain, 'numeric');
            app.EditField_loop.Limits = [2 20000];
            app.EditField_loop.Value = 20;
            app.Label_14 = uilabel(app.Panel_timeDomain);
            app.Label_14.Text = '仿真快拍数量';
            app.EditField_simPoints = uieditfield(app.Panel_timeDomain, 'numeric');
            app.EditField_simPoints.Limits = [1 2000];
            app.EditField_simPoints.Value = 200;
            app.DropDown_type = uidropdown(app.Panel_timeDomain);
            app.DropDown_type.Items = {'时域/多普勒域信道统计特性 (Time domain)', '频域/时延域信道统计特性 (Frequency domain)', '空域/角度域信道统计特性 (Space domain)', '其他(Other channel analysis)', '大尺度衰落 (Large scale fadings)'};
            app.DropDown_type.ValueChangedFcn = createCallbackFcn(app, @DropDown_typeValueChanged, true);
            app.DropDown_type.Value = '时域/多普勒域信道统计特性 (Time domain)';
            app.Button_generate = uibutton(app.Panel_timeDomain, 'push');
            app.Button_generate.ButtonPushedFcn = createCallbackFcn(app, @Button_generatePushed, true);
            app.Button_generate.Text = {'生 成'; 'Generate'};
            app.Button_saveSps_timeDomain = uibutton(app.Panel_timeDomain, 'push');
            app.Button_saveSps_timeDomain.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_timeDomainPushed, true);
            app.Button_saveSps_timeDomain.Text = {'保存所有结果'; 'Save All Results'};
            app.Label_22 = uilabel(app.Panel_timeDomain);
            app.Label_22.Text = '第';
            app.TransmittingAntennaIndexLabel_3 = uilabel(app.Panel_timeDomain);
            app.TransmittingAntennaIndexLabel_3.Text = 'Transmitting Antenna Index';
            app.ReceivingAntennaIndexLabel_3 = uilabel(app.Panel_timeDomain);
            app.ReceivingAntennaIndexLabel_3.Text = 'Receiving Antenna Index';
            app.NumberofSimulationsLabel = uilabel(app.Panel_timeDomain);
            app.NumberofSimulationsLabel.Text = 'Number of Simulations';
            app.NumberofSimulationSnapshotsLabel = uilabel(app.Panel_timeDomain);
            app.NumberofSimulationSnapshotsLabel.Text = 'Number of Simulation Snapshots';
            app.Label_26 = uilabel(app.Panel_timeDomain);
            app.Label_26.Text = '第';
            app.Label_27 = uilabel(app.Panel_timeDomain);
            app.Label_27.Text = '发射端用户';
            app.DropDown_rxUserIndex = uidropdown(app.Panel_timeDomain);
            app.DropDown_rxUserIndex.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex.Value = '1';
            app.Label_28 = uilabel(app.Panel_timeDomain);
            app.Label_28.Text = '第';
            app.TransmittingUserIndexLabel = uilabel(app.Panel_timeDomain);
            app.TransmittingUserIndexLabel.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex = uidropdown(app.Panel_timeDomain);
            app.DropDown_txUserIndex.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex.Value = '1';
            app.Label_29 = uilabel(app.Panel_timeDomain);
            app.Label_29.Text = '接收端用户';
            app.ReceivingUserIndexLabel = uilabel(app.Panel_timeDomain);
            app.ReceivingUserIndexLabel.Text = 'Receiving User Index';
            app.ClickthepaneltoupdateLabel = uilabel(app.Panel_timeDomain);
            app.ClickthepaneltoupdateLabel.Text = 'Click the panel to update';
            app.Panel_freqDomain = uipanel(app.UIFigure);
            app.Panel_freqDomain.Title = '频域、时延域统计特性分析(Frequency- and Delay-domain Statistical Property Analysis)';
            app.Panel_freqDomain.ButtonDownFcn = createCallbackFcn(app, @Panel_freqDomainButtonDown, true);
            app.UIAxes_FCF = uiaxes(app.Panel_freqDomain);
            title(app.UIAxes_FCF, '频率相关函数(Frequency Cross-correlation Function)')
            xlabel(app.UIAxes_FCF, 'Frequency difference (MHz)')
            ylabel(app.UIAxes_FCF, 'Normalized absolute value of local FCF')
            zlabel(app.UIAxes_FCF, 'Z')
            app.UIAxes_FCF.Tag = 'UIAxes_FCF';
            app.UIA_delayPSD = uiaxes(app.Panel_freqDomain);
            title(app.UIA_delayPSD, '时延功率谱密度(Delay Power Spectral Density)')
            xlabel(app.UIA_delayPSD, 'Delay (ns)')
            ylabel(app.UIA_delayPSD, 'Power')
            zlabel(app.UIA_delayPSD, 'Z')
            app.UIA_delayPSD.Tag = 'UIA_delayPSD';
            app.UIAxes_cohBandWidth = uiaxes(app.Panel_freqDomain);
            title(app.UIAxes_cohBandWidth, '相干带宽(Coherence Bandwidth)')
            xlabel(app.UIAxes_cohBandWidth, 'Coherence frequency (MHz)')
            ylabel(app.UIAxes_cohBandWidth, 'Threshold')
            zlabel(app.UIAxes_cohBandWidth, 'Z')
            app.UIAxes_cohBandWidth.YLim = [0 1];
            app.UIAxes_cohBandWidth.Tag = 'UIAxes_cohBandWidth';
            app.UIAxes_delaySpread = uiaxes(app.Panel_freqDomain);
            title(app.UIAxes_delaySpread, '时延扩展(Delay Spread)')
            xlabel(app.UIAxes_delaySpread, 'RMS delay spread (ns)')
            ylabel(app.UIAxes_delaySpread, 'CDF of RMS delay spread')
            zlabel(app.UIAxes_delaySpread, 'Z')
            app.UIAxes_delaySpread.Tag = 'UIAxes_delaySpread';
            app.UIAxes_SB = uiaxes(app.Panel_freqDomain);
            title(app.UIAxes_SB, '平稳带宽(Stationary Bandwidth)')
            xlabel(app.UIAxes_SB, 'Stationary bandwidth (MHz)')
            ylabel(app.UIAxes_SB, 'CDF')
            zlabel(app.UIAxes_SB, 'Z')
            app.UIAxes_SB.Tag = 'UIAxes_SB';
            app.UIAxes_LCRf = uiaxes(app.Panel_freqDomain);
            title(app.UIAxes_LCRf, '电平切割率(Level-crossing Rate)')
            xlabel(app.UIAxes_LCRf, 'Envelope level')
            ylabel(app.UIAxes_LCRf, 'Envelope LCR in the frequency domain')
            zlabel(app.UIAxes_LCRf, 'Z')
            app.UIAxes_LCRf.Tag = 'UIAxes_LCRf';
            app.Label_15 = uilabel(app.Panel_freqDomain);
            app.Label_15.Text = '根接收天线 ';
            app.Label_16 = uilabel(app.Panel_freqDomain);
            app.Label_16.Text = '第';
            app.DropDown_txAntennaIndex_freq = uidropdown(app.Panel_freqDomain);
            app.DropDown_txAntennaIndex_freq.Items = {'1', '2'};
            app.DropDown_txAntennaIndex_freq.Value = '1';
            app.Label_17 = uilabel(app.Panel_freqDomain);
            app.Label_17.Text = '根发射天线 ';
            app.DropDown_rxAntennaIndex_freq = uidropdown(app.Panel_freqDomain);
            app.DropDown_rxAntennaIndex_freq.Items = {'1', '2'};
            app.DropDown_rxAntennaIndex_freq.Value = '1';
            app.Label_18 = uilabel(app.Panel_freqDomain);
            app.Label_18.Text = '仿真次数';
            app.EditField_loop_freq = uieditfield(app.Panel_freqDomain, 'numeric');
            app.EditField_loop_freq.Limits = [2 20000];
            app.EditField_loop_freq.ValueChangedFcn = createCallbackFcn(app, @EditField_loop_freqValueChanged, true);
            app.EditField_loop_freq.Value = 20;
            app.TransmittingAntennaIndexLabel = uilabel(app.Panel_freqDomain);
            app.TransmittingAntennaIndexLabel.Text = 'Transmitting Antenna Index';
            app.Label_24 = uilabel(app.Panel_freqDomain);
            app.Label_24.Text = '第';
            app.ReceivingAntennaIndexLabel = uilabel(app.Panel_freqDomain);
            app.ReceivingAntennaIndexLabel.Text = 'Receiving Antenna Index';
            app.NumberofSimulationsLabel_2 = uilabel(app.Panel_freqDomain);
            app.NumberofSimulationsLabel_2.Text = 'Number of Simulations';
            app.NumberofFrequencyPointsLabel = uilabel(app.Panel_freqDomain);
            app.NumberofFrequencyPointsLabel.Text = 'Number of  Frequency Points';
            app.Label_30 = uilabel(app.Panel_freqDomain);
            app.Label_30.Text = '第';
            app.Button_saveSps_freqDomain = uibutton(app.Panel_freqDomain, 'push');
            app.Button_saveSps_freqDomain.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_freqDomainPushed, true);
            app.Button_saveSps_freqDomain.Text = {'保存所有结果'; 'Save All Results'};
            app.Label_31 = uilabel(app.Panel_freqDomain);
            app.Label_31.Text = '发射端用户';
            app.DropDown_rxUserIndex_freq = uidropdown(app.Panel_freqDomain);
            app.DropDown_rxUserIndex_freq.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex_freq.Value = '1';
            app.Label_32 = uilabel(app.Panel_freqDomain);
            app.Label_32.Text = '第';
            app.TransmittingUserIndexLabel_2 = uilabel(app.Panel_freqDomain);
            app.TransmittingUserIndexLabel_2.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex_freq = uidropdown(app.Panel_freqDomain);
            app.DropDown_txUserIndex_freq.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex_freq.Value = '1';
            app.Label_33 = uilabel(app.Panel_freqDomain);
            app.Label_33.Text = '接收端用户';
            app.ReceivingUserIndexLabel_2 = uilabel(app.Panel_freqDomain);
            app.ReceivingUserIndexLabel_2.Text = 'Receiving User Index';
            app.Label_50 = uilabel(app.Panel_freqDomain);
            app.Label_50.Text = '仿真频点数';
            app.EditField_simPoints_freq = uieditfield(app.Panel_freqDomain, 'numeric');
            app.EditField_simPoints_freq.Limits = [1 2000];
            app.EditField_simPoints_freq.Editable = 'off';
            app.EditField_simPoints_freq.Value = 1;
            app.Panel_spaceDomain = uipanel(app.UIFigure);
            app.Panel_spaceDomain.Title = '空域、角度域统计特性分析(Space- and Angle-domain Statistical Property Analysis)';
            app.Panel_spaceDomain.ButtonDownFcn = createCallbackFcn(app, @Panel_spaceDomainButtonDown, true);
            app.UIAxes_SCCF = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_SCCF, '空间互相关函数(Spatial Cross-correlation Function)')
            xlabel(app.UIAxes_SCCF, 'Antenna spacing (m)')
            ylabel(app.UIAxes_SCCF, 'Normalized absolute value of local SCCF')
            zlabel(app.UIAxes_SCCF, 'Z')
            app.UIAxes_SCCF.Tag = 'UIAxes_SCCF';
            app.UIAxes_cohDistance = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_cohDistance, '相干距离(Coherence Distance)')
            xlabel(app.UIAxes_cohDistance, 'Coherence distance (m)')
            ylabel(app.UIAxes_cohDistance, 'Threshold')
            zlabel(app.UIAxes_cohDistance, 'Z')
            app.UIAxes_cohDistance.Tag = 'UIAxes_cohDistance';
            app.UIAxes_angleSpreads = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_angleSpreads, '角度扩展(Angular Spread)')
            xlabel(app.UIAxes_angleSpreads, 'RMS angular spread (\circ)')
            ylabel(app.UIAxes_angleSpreads, 'CDF')
            zlabel(app.UIAxes_angleSpreads, 'Z')
            app.UIAxes_angleSpreads.Tag = 'UIAxes_angleSpreads';
            app.UIAxes_sptialdopplerPSD = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_sptialdopplerPSD, '空间多普勒功率谱密度(Spatial Doppler Power Spectral Density)')
            xlabel(app.UIAxes_sptialdopplerPSD, 'Spatial doppler frequency (Hz)')
            ylabel(app.UIAxes_sptialdopplerPSD, 'Power')
            zlabel(app.UIAxes_sptialdopplerPSD, 'Z')
            app.UIAxes_sptialdopplerPSD.Tag = 'UIAxes_sptialdopplerPSD';
            app.UIAxes_SD = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_SD, '平稳距离(Stationary Distance)')
            xlabel(app.UIAxes_SD, 'Stationary distance (m)')
            ylabel(app.UIAxes_SD, 'CDF')
            zlabel(app.UIAxes_SD, 'Z')
            app.UIAxes_SD.Tag = 'UIAxes_SD';
            app.UIAxes_LCRs = uiaxes(app.Panel_spaceDomain);
            title(app.UIAxes_LCRs, '电平切割率(Level-crossing Rate)')
            xlabel(app.UIAxes_LCRs, 'Envelope level')
            ylabel(app.UIAxes_LCRs, 'Envelope LCR in the space domain')
            zlabel(app.UIAxes_LCRs, 'Z')
            app.UIAxes_LCRs.Tag = 'UIAxes_LCRs';
            app.NumberofSimulationsLabel_4 = uilabel(app.Panel_spaceDomain);
            app.NumberofSimulationsLabel_4.Text = '仿真次数(Number of Simulations)';
            app.EditField_loop_space = uieditfield(app.Panel_spaceDomain, 'numeric');
            app.EditField_loop_space.Limits = [2 20000];
            app.EditField_loop_space.Value = 20;
            app.DeterminewhichsidetoanalyzeButtonGroup = uibuttongroup(app.Panel_spaceDomain);
            app.DeterminewhichsidetoanalyzeButtonGroup.Tooltip = {'光无线信道只能计算发射端LED阵列的空域统计特性，因为接收端是光电二极管'};
            app.DeterminewhichsidetoanalyzeButtonGroup.Title = '确定分析哪一侧(Determine which side to analyze)';
            app.Button_tx = uiradiobutton(app.DeterminewhichsidetoanalyzeButtonGroup);
            app.Button_tx.Text = '发射端(Tx)';
            app.Button_tx.Value = true;
            app.Button_rx = uiradiobutton(app.DeterminewhichsidetoanalyzeButtonGroup);
            app.Button_rx.Text = '接收端(Rx)';
            app.Label_34 = uilabel(app.Panel_spaceDomain);
            app.Label_34.Text = '第';
            app.Label_35 = uilabel(app.Panel_spaceDomain);
            app.Label_35.Text = '发射端用户';
            app.DropDown_rxUserIndex_space = uidropdown(app.Panel_spaceDomain);
            app.DropDown_rxUserIndex_space.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex_space.Value = '1';
            app.Label_36 = uilabel(app.Panel_spaceDomain);
            app.Label_36.Text = '第';
            app.TransmittingUserIndexLabel_3 = uilabel(app.Panel_spaceDomain);
            app.TransmittingUserIndexLabel_3.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex_space = uidropdown(app.Panel_spaceDomain);
            app.DropDown_txUserIndex_space.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex_space.Value = '1';
            app.Button_saveSps_spaceDomain = uibutton(app.Panel_spaceDomain, 'push');
            app.Button_saveSps_spaceDomain.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_spaceDomainPushed, true);
            app.Button_saveSps_spaceDomain.Text = {'保存所有结果'; 'Save All Results'};
            app.Label_37 = uilabel(app.Panel_spaceDomain);
            app.Label_37.Text = '接收端用户';
            app.ReceivingUserIndexLabel_3 = uilabel(app.Panel_spaceDomain);
            app.ReceivingUserIndexLabel_3.Text = 'Receiving User Index';
            app.Panel_systemLevel = uipanel(app.UIFigure);
            app.Panel_systemLevel.Title = '其他信道特性分析(Analysis of Other Channel Properties)';
            app.Panel_systemLevel.ButtonDownFcn = createCallbackFcn(app, @Panel_systemLevelButtonDown, true);
            app.UIAxes_channelCapacity = uiaxes(app.Panel_systemLevel);
            title(app.UIAxes_channelCapacity, '信道容量(Channel Capacity)')
            xlabel(app.UIAxes_channelCapacity, 'SNR')
            ylabel(app.UIAxes_channelCapacity, 'Capacity (bps)')
            zlabel(app.UIAxes_channelCapacity, 'Z')
            app.UIAxes_channelCapacity.Tag = 'UIAxes_channelCapacity';
            app.UIAxes_pdfA = uiaxes(app.Panel_systemLevel);
            title(app.UIAxes_pdfA, '幅度PDF(Amplitude PDF)')
            xlabel(app.UIAxes_pdfA, 'Amplitude')
            ylabel(app.UIAxes_pdfA, 'PDF')
            zlabel(app.UIAxes_pdfA, 'Z')
            app.UIAxes_pdfA.Tag = 'UIAxes_pdfA';
            app.UIAxes_pdfP = uiaxes(app.Panel_systemLevel);
            title(app.UIAxes_pdfP, '相位PDF(Phase PDF)')
            xlabel(app.UIAxes_pdfP, 'Phase')
            ylabel(app.UIAxes_pdfP, 'PDF')
            zlabel(app.UIAxes_pdfP, 'Z')
            app.UIAxes_pdfP.Tag = 'UIAxes_pdfP';
            app.UIAxes_AAoAs = uiaxes(app.Panel_systemLevel);
            title(app.UIAxes_AAoAs, '球面波前特性(Spherical Wavefront Property)')
            xlabel(app.UIAxes_AAoAs, 'Antenna Intex')
            ylabel(app.UIAxes_AAoAs, 'AAoA of LOS Path(°)')
            zlabel(app.UIAxes_AAoAs, 'Z')
            app.UIAxes_AAoAs.Tag = 'UIAxes_AAoAs';
            app.NumberofSimulationsLabel_3 = uilabel(app.Panel_systemLevel);
            app.NumberofSimulationsLabel_3.Text = {'仿真次数'; '(Number of Simulations)'};
            app.EditField_loop_system = uieditfield(app.Panel_systemLevel, 'numeric');
            app.EditField_loop_system.Limits = [1 20000];
            app.EditField_loop_system.Value = 20;
            app.Button_saveSps_system = uibutton(app.Panel_systemLevel, 'push');
            app.Button_saveSps_system.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_systemPushed, true);
            app.Button_saveSps_system.Text = {'保存所有结果'; 'Save All Results'};
            app.Label_38 = uilabel(app.Panel_systemLevel);
            app.Label_38.Text = '第';
            app.Label_39 = uilabel(app.Panel_systemLevel);
            app.Label_39.Text = '发射端用户';
            app.DropDown_rxUserIndex_system = uidropdown(app.Panel_systemLevel);
            app.DropDown_rxUserIndex_system.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex_system.Value = '1';
            app.Label_40 = uilabel(app.Panel_systemLevel);
            app.Label_40.Text = '第';
            app.TransmittingUserIndexLabel_4 = uilabel(app.Panel_systemLevel);
            app.TransmittingUserIndexLabel_4.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex_system = uidropdown(app.Panel_systemLevel);
            app.DropDown_txUserIndex_system.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex_system.Value = '1';
            app.Label_41 = uilabel(app.Panel_systemLevel);
            app.Label_41.Text = '接收端用户';
            app.ReceivingUserIndexLabel_4 = uilabel(app.Panel_systemLevel);
            app.ReceivingUserIndexLabel_4.Text = 'Receiving User Index';
            app.Label_46 = uilabel(app.Panel_systemLevel);
            app.Label_46.Text = '根接收天线 ';
            app.Label_47 = uilabel(app.Panel_systemLevel);
            app.Label_47.Text = '第';
            app.DropDown_txAntennaIndex_system = uidropdown(app.Panel_systemLevel);
            app.DropDown_txAntennaIndex_system.Items = {'1', '2'};
            app.DropDown_txAntennaIndex_system.Value = '1';
            app.Label_48 = uilabel(app.Panel_systemLevel);
            app.Label_48.Text = '根发射天线 ';
            app.DropDown_rxAntennaIndex_system = uidropdown(app.Panel_systemLevel);
            app.DropDown_rxAntennaIndex_system.Items = {'1', '2'};
            app.DropDown_rxAntennaIndex_system.Value = '1';
            app.Label_49 = uilabel(app.Panel_systemLevel);
            app.Label_49.Text = '第';
            app.ReceivingAntennaIndexLabel_2 = uilabel(app.Panel_systemLevel);
            app.ReceivingAntennaIndexLabel_2.Text = 'Receiving Antenna Index';
            app.MaximumEditFieldLabel = uilabel(app.Panel_systemLevel);
            app.MaximumEditFieldLabel.Text = 'Maximum ';
            app.TransmittingAntennaIndexLabel_2 = uilabel(app.Panel_systemLevel);
            app.TransmittingAntennaIndexLabel_2.Text = 'Transmitting Antenna Index';
            app.SNRMaximum = uieditfield(app.Panel_systemLevel, 'numeric');
            app.SNRMaximum.Limits = [-100 100];
            app.SNRMaximum.ValueChangedFcn = createCallbackFcn(app, @SNRMaximumValueChanged, true);
            app.SNRMaximum.Value = 10;
            app.MinimumEditFieldLabel = uilabel(app.Panel_systemLevel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.SNRMinimum = uieditfield(app.Panel_systemLevel, 'numeric');
            app.SNRMinimum.Limits = [-100 100];
            app.SNRMinimum.ValueChangedFcn = createCallbackFcn(app, @SNRMinimumValueChanged, true);
            app.SNRMinimum.Value = -10;
            app.IntervalEditFieldLabel = uilabel(app.Panel_systemLevel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.SNRInterval = uieditfield(app.Panel_systemLevel, 'numeric');
            app.SNRInterval.Limits = [0 200];
            app.SNRInterval.Value = 5;
            app.Label_51 = uilabel(app.Panel_systemLevel);
            app.Label_51.Text = '最大值';
            app.Label_52 = uilabel(app.Panel_systemLevel);
            app.Label_52.Text = '最小值';
            app.Label_53 = uilabel(app.Panel_systemLevel);
            app.Label_53.Text = '间隔';
            app.SNRLabel = uilabel(app.Panel_systemLevel);
            app.SNRLabel.Text = 'SNR：';
            app.Panel_lsfs = uipanel(app.UIFigure);
            app.Panel_lsfs.Title = '大尺度衰落分析(Analysis of Large-scale Fading)';
            app.Panel_lsfs.ButtonDownFcn = createCallbackFcn(app, @Panel_lsfsButtonDown, true);
            app.UIAxes_DisRxTx = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_DisRxTx, 'Distance between transmitter and receiver')
            xlabel(app.UIAxes_DisRxTx, 't(s)')
            ylabel(app.UIAxes_DisRxTx, 'Distance (m)')
            zlabel(app.UIAxes_DisRxTx, 'Z')
            app.UIAxes_DisRxTx.Tag = 'UIAxes_DisRxTx';
            app.UIAxes_RxTx = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_RxTx, ' Motion trajectory of the Transmitter and receiver')
            xlabel(app.UIAxes_RxTx, 'x-axis (m)')
            ylabel(app.UIAxes_RxTx, 'y-axis (m)')
            zlabel(app.UIAxes_RxTx, {'z轴（单位为米）'; '(z-axis (m))'})
            app.UIAxes_RxTx.Tag = 'UIAxes_RxTx';
            app.UIAxes_PL = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_PL, '路径损耗(Pass Loss)')
            xlabel(app.UIAxes_PL, 't(s)')
            ylabel(app.UIAxes_PL, 'PL(dB)')
            zlabel(app.UIAxes_PL, 'Z')
            app.UIAxes_BL = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_BL, '阻挡损耗(Blockage Loss)')
            xlabel(app.UIAxes_BL, 't(s)')
            ylabel(app.UIAxes_BL, 'BL(dB)')
            zlabel(app.UIAxes_BL, 'Z')
            app.UIAxes_AL = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_AL, '大气损耗(Atmospheric Loss)')
            xlabel(app.UIAxes_AL, 't(s)')
            ylabel(app.UIAxes_AL, 'AL(dB)')
            zlabel(app.UIAxes_AL, 'Z')
            app.UIAxes_OT = uiaxes(app.Panel_lsfs);
            title(app.UIAxes_OT, '其他损耗(Other Loss)')
            xlabel(app.UIAxes_OT, 't(s)')
            ylabel(app.UIAxes_OT, '(dB)')
            zlabel(app.UIAxes_OT, 'Z')
            app.ALCheckBox = uicheckbox(app.Panel_lsfs);
            app.ALCheckBox.ValueChangedFcn = createCallbackFcn(app, @ALCheckBoxValueChanged, true);
            app.ALCheckBox.Text = '大气损耗(AL)';
            app.OTCheckBox = uicheckbox(app.Panel_lsfs);
            app.OTCheckBox.ValueChangedFcn = createCallbackFcn(app, @OTCheckBoxValueChanged, true);
            app.OTCheckBox.Text = '其他损耗(降雨衰减等)';
            app.CheckBox_3dRxTx = uicheckbox(app.Panel_lsfs);
            app.CheckBox_3dRxTx.Text = {'显示三维运动轨迹'; '(Display 3D motion trajectory)'};
            app.Label_OTConcerned = uilabel(app.Panel_lsfs);
            app.Label_OTConcerned.Visible = 'off';
            app.Label_OTConcerned.Text = {'       未选        '; '    或对应场景不考虑降雨衰减'; '     (Not selected or not concerned '; 'in this situation )'};
            app.Label_PLConcerned = uilabel(app.Panel_lsfs);
            app.Label_PLConcerned.Visible = 'off';
            app.Label_PLConcerned.Text = {'    未选路径损耗！'; '   (Not selected)'};
            app.Label_ALConcerned = uilabel(app.Panel_lsfs);
            app.Label_ALConcerned.Visible = 'off';
            app.Label_ALConcerned.Text = {'未选'; '     或此频率下不考虑大气吸收损耗'; '      (Not selected or not concerned '; 'in this situation )'};
            app.Label_BLConcerned = uilabel(app.Panel_lsfs);
            app.Label_BLConcerned.Visible = 'off';
            app.Label_BLConcerned.Text = {'未选'; '      或对应场景频段不考虑阻挡损耗'; '    (Not selected or not concerned '; 'in this situation )'};
            app.PLCheckBox = uicheckbox(app.Panel_lsfs);
            app.PLCheckBox.Text = '路径损耗(PL)';
            app.BLCheckBox = uicheckbox(app.Panel_lsfs);
            app.BLCheckBox.Text = '阻挡损耗(BL)';
            app.ChangeALparametersButton = uibutton(app.Panel_lsfs, 'push');
            app.ChangeALparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeALparametersButtonPushed, true);
            app.ChangeALparametersButton.Visible = 'off';
            app.ChangeALparametersButton.Text = 'Change AL parameters';
            app.Label_42 = uilabel(app.Panel_lsfs);
            app.Label_42.Text = '第';
            app.Label_43 = uilabel(app.Panel_lsfs);
            app.Label_43.Text = '发射端用户';
            app.DropDown_rxUserIndex_lsfs = uidropdown(app.Panel_lsfs);
            app.DropDown_rxUserIndex_lsfs.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex_lsfs.Value = '1';
            app.Label_44 = uilabel(app.Panel_lsfs);
            app.Label_44.Text = '第';
            app.TransmittingUserIndexLabel_5 = uilabel(app.Panel_lsfs);
            app.TransmittingUserIndexLabel_5.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex_lsfs = uidropdown(app.Panel_lsfs);
            app.DropDown_txUserIndex_lsfs.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex_lsfs.Value = '1';
            app.Label_45 = uilabel(app.Panel_lsfs);
            app.Label_45.Text = '接收端用户';
            app.ReceivingUserIndexLabel_5 = uilabel(app.Panel_lsfs);
            app.ReceivingUserIndexLabel_5.Text = 'Receiving User Index';
            app.SetrainfallrateButton = uibutton(app.Panel_lsfs, 'push');
            app.SetrainfallrateButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeOTparametersButtonPushed, true);
            app.SetrainfallrateButton.Visible = 'off';
            app.SetrainfallrateButton.Text = 'Set rainfall rate ';
            app.Button_saveSps_Largescale = uibutton(app.Panel_lsfs, 'push');
            app.Button_saveSps_Largescale.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_LargescalePushed, true);
            app.Button_saveSps_Largescale.Text = {'保存所有结果'; 'Save All Results'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end