classdef guiCmParamsShow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelModelParameterSettingsUIFigure  matlab.ui.Figure
        ChannelModelParameterSettingsPanel  matlab.ui.container.Panel
        SaveCurrentSettingsButton   matlab.ui.control.Button
        RestoreDefaultValuesButton  matlab.ui.control.Button
        PropagationConditionLabel   matlab.ui.control.Label
        ScenarioLabel               matlab.ui.control.Label
        FrequencyBandLabel          matlab.ui.control.Label
        Propagation_condition_EditField  matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        Scenario_EditField          matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        Frequency_EditField         matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        Property2 % Description
        CallingApp % Main App object
        isERROR
    end

    properties (Access = 'public')
        filename_conf_read;
        parameter_channel;
        UIData_save;
    end

    methods (Access = public)
        function wirte_scen_para(app,data_write,confFileName)
            add_table_1 = table();%添加的第一个table，用于存储等号
            add_table_2 = table();%添加的第二个table，用于存储百分号
            for i = 1:height(data_write)
                add_table_1 = [add_table_1; table("=")];
            end
            for i = 1:height(data_write)
                add_table_2 = [add_table_2; table("%")];
            end
            %设置两个新table的变量名称
            add_table_1.Properties.VariableNames = {'等号'};
            add_table_2.Properties.VariableNames = {'百分号'};
            data_write_1=data_write{:, 1};%保存参数的英文名
            if contains(app.filename_conf_read,'Satellite')
                name = ["DS_mu","DS_sigma","KF_mu","KF_sigma","SF_sigma","AS_D_mu","AS_D_sigma","AS_A_mu","AS_A_sigma","ES_D_mu","ES_D_sigma","ES_A_mu","ES_A_sigma","XPR_mu","XPR_sigma","r_DS","PerClusterAS_D","PerClusterAS_A","PerClusterES_A","CL"];
                for i = 1:height(data_write)
                    if find(ismember(data_write_1(i),name))==1
                        data_write_4 = table2array(data_write(i,2:10));
                        data_write_4 = cell2mat(data_write_4);
                        data_write_4 = table(data_write_4);
                        data_write_2(i,:) = table2cell(data_write_4);
                    else
                        data_write_2(i,:) = num2cell(data_write{i, 2});
                    end
                end
                data_write_3=data_write{:, 11};
            else
            data_write_2=num2cell(data_write{:, 2});%保存参数的值，转成cell形式
            data_write_3=data_write{:, 3};%保存参数的注释
            end
            %组合成一个新table，一共5列，进行table的拼接，创建需要保存的table
            table_write=horzcat(data_write_1,add_table_1,data_write_2,add_table_2,data_write_3);

            % 需要先转入成CSV文件
            % 保存table为CSV文件
            csvFileName = 'table_data.csv';
            writetable(table_write, csvFileName, 'Delimiter','\t');
            % 写入conf文件
            confFileID = fopen(confFileName, 'w'); % conf文件的读写权限为写
            csvFileID = fopen(csvFileName, 'r'); % csv文件的读写权限为读
            % 读取整个csv文件内容
            fileContent = fread(csvFileID, 'char=>char');
            fclose(csvFileID);
            % 将新的第一行内容插入到文件内容的开头
            newFirstLine = ['%Frequency = ',app.Frequency_EditField.Value,';  Scenario = ',app.Scenario_EditField.Value,';  Propagation_condition = ',app.Propagation_condition_EditField.Value,';'];
            fileContent = strjoin([string(newFirstLine'); string(fileContent)], '');
            % 将更新后的内容写入conf文件
            fwrite(confFileID, fileContent, 'char');
            fclose(confFileID);
            % 删除临时的CSV文件
            delete(csvFileName);
        end
        
        function [para_Chinese, scen_para, scen_explanation] = get_para_Chinese(app, filename)
            database_para=tools.database(app);  
            
            [scen_para, scen_explanation] = mf.read_scen_para(filename, app.CallingApp.scenarios);
            assignin('base','scen_explanation1111', scen_explanation);
            %将strct类型转换成table类型
            scen_explanation = struct2table(scen_explanation);
            para_Chinese = rows2vars(scen_explanation);
            para_Chinese = renamevars(para_Chinese, {'OriginalVariableNames', 'Var1'}, {'name', 'Var2'});
            para_Chinese = removevars(para_Chinese, 'Var2');
            para_Chinese = join(para_Chinese, database_para, 'Keys', 'name');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, frequency, scenario, propagation_condition, conf_filename)
            try
                app.CallingApp = mainapp;
                app.Frequency_EditField.Value = frequency;
                app.Scenario_EditField.Value = scenario;
                app.Propagation_condition_EditField.Value = propagation_condition;
                app.filename_conf_read = conf_filename;
                % 获取当前文件夹路径
                currentFolder = fileparts(mfilename('fullpath'));
                % 构建配置文件的完整路径，假设配置文件名为 'LoS.conf'
                configFilePath = fullfile(currentFolder, 'config', conf_filename);
                % 如果 'LoS.conf' 文件不存在，则尝试读取 'NLoS.conf'
                if ~exist(configFilePath, 'file')
                    configFilePath = conf_filename;
                end

                read_filename = configFilePath;

                [para_Chinese, scen_para, scen_explanation] = get_para_Chinese(app,read_filename);
                scen_para_mine_col1 = rows2vars(scen_explanation);
                scen_para_mine_col22 = struct2cell(scen_para);
                if contains(conf_filename,'Satellite')
                    app.UITable.ColumnName = {'参数(Parameters)';'10°';'20°';'30°';'40°';'50°';'60°';'70°';'80°';'90°'};
                    app.UITable.ColumnEditable = true;
                    for i = 1:size(scen_para_mine_col22,1)
                        if size((cell2mat(scen_para_mine_col22(i))),2) == 1 || contains(class(cell2mat(scen_para_mine_col22(i))),'char')
                            if contains(class(cell2mat(scen_para_mine_col22(i))),'char')
                                for j = 1:9
                                scen_para_c(1,j) = scen_para_mine_col22(i);
                                end
                                scen_para_mine_colsat(i,:) = scen_para_c;
                            else
                                scen_para_mine = cell2mat(scen_para_mine_col22(i))*ones(1,9);
                                scen_para_mine_colsat(i,:) = num2cell(scen_para_mine);
                            end
                        else
                            A = cell2mat(scen_para_mine_col22(i));
                            scen_para_mine_colsat(i,:) = num2cell(A);
                        end
                    end
                    scen_para_mine_col2 = cell2table(scen_para_mine_colsat);
                else
                    scen_para_mine_col2 = cell2table(scen_para_mine_col22);
                end
                scen_para_mine_display = [para_Chinese{:,2} scen_para_mine_col2 scen_para_mine_col1{:, 2}];
                scen_para_mine_save = [scen_para_mine_col1{:,1} scen_para_mine_col2 scen_para_mine_col1{:, 2}];
                if contains(conf_filename,'Satellite')
                    app.UITable.Data = scen_para_mine_display(:, 1:10);
                else
                    app.UITable.Data = scen_para_mine_display(:, 1:2);
                end
                %设置表格的数据元素居中
                s = uistyle('HorizontalAlignment','center');
                addStyle(app.UITable, s, 'table','');
                app.parameter_channel=scen_para_mine_save;
                % 1028增加，原来在界面上三列数据，现在把这个三列数据存到app.UIData_save中
                app.UIData_save = scen_para_mine_display;
            catch exception 
                delete(app);  
                throw(exception);
            end
        end

        % Button pushed function: RestoreDefaultValuesButton
        function RestoreDefaultValuesButtonPushed2(app, event)
            %%%%%%%%%%%%%%%%%20231021新加，一键恢复默认值
            % 获取当前文件夹路径
            currentFolder = fileparts(mfilename('fullpath'));
            % 创建一个名为 "config" 的文件夹
            configFolder = fullfile(currentFolder, 'config');
            if ~exist(configFolder, 'dir')
                mkdir(configFolder);
            end
            configFilePath = fullfile(configFolder, app.filename_conf_read);

            %%%%%%%%%%%%%%%%%20231021新加，一键恢复默认值
            % 把保存的原始conf文件的内容读进来
            baseconf_file_name = app.filename_conf_read;                    
            tools.write_savingconf(app,baseconf_file_name,configFilePath);
            
            % 读保存的原始conf文件并且显示
            [para_Chinese, scen_para, scen_explanation] = get_para_Chinese(app, app.filename_conf_read);
            scen_para_mine_col1 = rows2vars(scen_explanation);
            scen_para_mine_col22 = struct2cell(scen_para);
            if contains(baseconf_file_name,'Satellite')
                app.UITable.ColumnName = {'参数(Parameters)';'10°(仰角)';'20°';'30°';'40°';'50°';'60°';'70°';'80°';'90°'};
                for i = 1:size(scen_para_mine_col22,1)
                    if size((cell2mat(scen_para_mine_col22(i))),2) == 1 || contains(class(cell2mat(scen_para_mine_col22(i))),'char')
                        if contains(class(cell2mat(scen_para_mine_col22(i))),'char')
                            for j = 1:9
                                scen_para_c(1,j) = scen_para_mine_col22(i);
                            end
                            scen_para_mine_colsat(i,:) = scen_para_c;
                        else
                            scen_para_mine = cell2mat(scen_para_mine_col22(i))*ones(1,9);
                            scen_para_mine_colsat(i,:) = num2cell(scen_para_mine);
                        end
                    else
                        A = cell2mat(scen_para_mine_col22(i));
                        scen_para_mine_colsat(i,:) = num2cell(A);
                    end
                end
                scen_para_mine_col2 = cell2table(scen_para_mine_colsat);
            else
                scen_para_mine_col2 = cell2table(scen_para_mine_col22);
            end
            scen_para_mine_display = [para_Chinese{:,2} scen_para_mine_col2 scen_para_mine_col1{:, 2}];
            scen_para_mine_save = [scen_para_mine_col1{:,1} scen_para_mine_col2 scen_para_mine_col1{:, 2}];
            if contains(baseconf_file_name,'Satellite')
                app.UITable.Data = scen_para_mine_display(:, 1:10);
            else
                app.UITable.Data = scen_para_mine_display(:, 1:2);
            end
            %设置表格的数据元素居中
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable, s, 'table','');
            app.parameter_channel=scen_para_mine_save;
            app.UIData_save = scen_para_mine_display;            
            uiwait(msgbox('Successfully recovered','Success','modal'));
        end

        % Button pushed function: SaveCurrentSettingsButton
        function SaveCurrentSettingsButtonPushed(app, event)
            % "保存当前设置"按钮
            % 卫星场景的参数维度与其他场景不同
            if  contains(app.filename_conf_read,'Satellite')
                Sat = 8;
            else
                Sat = 0;
            end
            data_current = app.UIData_save; % 此时的第一列为中文名，需要置换
            data_current(:, 1:2+Sat) = app.UITable.Data;

            for i=1:size(data_current, 1)
                data_current{i,3+Sat} = erase(data_current{i,3+Sat}, "'");  % 删除单引号
                data_current{i,3+Sat} = erase(data_current{i,3+Sat}, '"');  % 删除双引号
                data_current{i,3+Sat} = erase(data_current{i,3+Sat}, sprintf('\t'));  % 删除制表符
            end

            % 获取当前文件夹路径
            currentFolder = fileparts(mfilename('fullpath'));
            % 创建一个名为 "config" 的文件夹
            configFolder = fullfile(currentFolder, 'config');
            if ~exist(configFolder, 'dir')
                mkdir(configFolder);
            end
            configFilePath = fullfile(configFolder, app.filename_conf_read);
            
            [~, scen_explanation] = mf.read_scen_para(app.filename_conf_read, extractBetween(app.filename_conf_read, "scen_",".conf"));
            scen_para = rows2vars(struct2table(scen_explanation));
            data_current(:,1) = scen_para(:,1);
            wirte_scen_para(app, data_current, configFilePath);
            %%%%%%%%%%%%%%%%
            uiwait(msgbox('Successfully saved！','Success','modal'));
        end

        % Close request function: ChannelModelParameterSettingsUIFigure
        function ChannelModelParameterSettingsUIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            % 弹出确认关闭对话框
            choice = uiconfirm(app.ChannelModelParameterSettingsUIFigure,'Confirm to close?', 'Confirm close', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if strcmp(choice, 'Confirm')
                app.CallingApp.choose_parameter.Enable = "on";
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelModelParameterSettingsUIFigure = uifigure('Visible', 'off');
            app.ChannelModelParameterSettingsUIFigure.Name = '信道模型参数设置(Channel Model Parameter Settings)';
            app.ChannelModelParameterSettingsUIFigure.Icon = 'icon.png';
            app.ChannelModelParameterSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChannelModelParameterSettingsUIFigureCloseRequest, true);
            app.ChannelModelParameterSettingsPanel = uipanel(app.ChannelModelParameterSettingsUIFigure);
            app.ChannelModelParameterSettingsPanel.Title = '信道模型参数设置(Channel Model Parameter Settings)';
            app.UITable = uitable(app.ChannelModelParameterSettingsPanel);
            app.UITable.ColumnName = {'参数(Parameters)'; '值(Values)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.Label = uilabel(app.ChannelModelParameterSettingsPanel);
            app.Label.Text = '频段';
            app.Frequency_EditField = uieditfield(app.ChannelModelParameterSettingsPanel, 'text');
            app.Frequency_EditField.Editable = 'off';
            app.Frequency_EditField.Value = 'sub-6 GHz';
            app.Label_2 = uilabel(app.ChannelModelParameterSettingsPanel);
            app.Label_2.Text = '场景';
            app.Scenario_EditField = uieditfield(app.ChannelModelParameterSettingsPanel, 'text');
            app.Scenario_EditField.Editable = 'off';
            app.Scenario_EditField.Value = 'Indoor';
            app.Label_3 = uilabel(app.ChannelModelParameterSettingsPanel);
            app.Label_3.Text = '传播条件';
            app.Propagation_condition_EditField = uieditfield(app.ChannelModelParameterSettingsPanel, 'text');
            app.Propagation_condition_EditField.Editable = 'off';
            app.Propagation_condition_EditField.Value = 'LoS';
            app.FrequencyBandLabel = uilabel(app.ChannelModelParameterSettingsPanel);
            app.FrequencyBandLabel.Text = 'Frequency Band';
            app.ScenarioLabel = uilabel(app.ChannelModelParameterSettingsPanel);
            app.ScenarioLabel.Text = 'Scenario';
            app.PropagationConditionLabel = uilabel(app.ChannelModelParameterSettingsPanel);
            app.PropagationConditionLabel.Text = 'Propagation Condition';
            app.RestoreDefaultValuesButton = uibutton(app.ChannelModelParameterSettingsPanel, 'push');
            app.RestoreDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultValuesButtonPushed2, true);
            app.RestoreDefaultValuesButton.Text = {'一键恢复建议值'; 'Restore Default Values'};
            app.SaveCurrentSettingsButton = uibutton(app.ChannelModelParameterSettingsPanel, 'push');
            app.SaveCurrentSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentSettingsButtonPushed, true);
            app.SaveCurrentSettingsButton.Text = {'保存当前设置'; 'Save Current Settings'};
            % Show the figure after all components are created
            app.ChannelModelParameterSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiCmParamsShow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelModelParameterSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelModelParameterSettingsUIFigure)
        end
    end
end