classdef guiOptimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParameterOptimizationUIFigure  matlab.ui.Figure
        Panel_timeDomain_TACF          matlab.ui.container.Panel
        ClearfigureButton              matlab.ui.control.Button
        ReceivingUserIndexLabel        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        DropDown_txUserIndex           matlab.ui.control.DropDown
        TransmittingUserIndexLabel     matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        DropDown_rxUserIndex           matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MatFilePathLabel               matlab.ui.control.Label
        settings                       matlab.ui.control.TextArea
        CurrentResult                  matlab.ui.control.Button
        FittingProcessLabel            matlab.ui.control.Label
        Button_saveSps_Opti            matlab.ui.control.Button
        Button_Optimization            matlab.ui.control.Button
        DropDown_type_property         matlab.ui.control.DropDown
        LoadMeasData                   matlab.ui.control.Button
        LoopNum                        matlab.ui.control.NumericEditField
        NumberofSimulationsLabel       matlab.ui.control.Label
        Figure_Fitting                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        MeasureData  % Description
        Line_Fitting_Meas % Description
        CallingApp;  % Description
        Line_InitialData % Description

        Line_BestData % Description
    end
    properties (Access = public)
    matFilePath = ''; % 初始化为一个空字符串
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.ParameterOptimizationUIFigure.Name = '参数优化(Parameter Optimization)';
            
        end

        % Button pushed function: LoadMeasData
        function LoadMeasDataButtonPushed(app, event)
            try
                [f, p] = uigetfile('*.mat', 'Select a MAT file');
                if isequal(f, 0)
                    disp('User canceled the file selection');
                else
                    % 验证是否为MAT文件
                    [~,~,ext] = fileparts(f);
                    if ~isequal(ext, '.mat')
                        app.MatFilePathLabel.Text = 'The file uploaded is not a .mat file';
                    else
                        % 这里可以编写处理上传的MAT文件的代码
                        matFilePath = fullfile(p, f);
                        app.matFilePath = matFilePath;
                        disp(['User selected MAT file: ', matFilePath]);

                        % 在界面上显示文件路径
                        app.MatFilePathLabel.Text = ['User selected MAT file: ', matFilePath];

                        % 读取MAT文件
                        matData = load(matFilePath);

                        % 检验是否为两个一样长的一维数组，并且'y'在[0, 1]的范围内
                        if isfield(matData, 'x') && isfield(matData, 'y') && isvector(matData.x) && isvector(matData.y) && numel(matData.x) == numel(matData.y)

                            tolerance = 1e-10; % 设置容忍度

                            if all(matData.y >= 0 & matData.y < 1 + tolerance)
                                % 'y' 数组的所有元素都小于 1
                                app.MatFilePathLabel.Text = 'Successfully uploaded';
                                cla(app.Figure_Fitting);
                                if (ischar(p))
                                    fname = [p f];
                                    app.MeasureData=load(fname);
                                    app.Line_Fitting_Meas = plot(app.Figure_Fitting,app.MeasureData.x,app.MeasureData.y,'k-.','LineWidth',1);
                                    app.Figure_Fitting.NextPlot = "add";
                                    legend(app.Figure_Fitting,app.Line_Fitting_Meas,'measurement data');
                                end
                            else
                                % 'y' 数组中有元素不在 0 到 1 的范围内
                                app.MatFilePathLabel.Text = 'Measurement data range error';
                            end

                            % 在这里你可以继续处理MAT文件的数据
                        else
                            app.MatFilePathLabel.Text = 'Measurement data dimension error';
                        end

                    end
                end

            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Value changed function: DropDown_type_property
        function DropDown_type_propertyValueChanged(app, event)
            if strfind(app.DropDown_type_property.Value, 'FCF')
                
                xlabel(app.Figure_Fitting,'Frequency difference (MHz)');
                ylabel(app.Figure_Fitting,'Normalized absolute value of FCF');
                title(app.Figure_Fitting,'频率相关函数(Frequency Cross-correlation Function)');

                % xlabel(app.Figure_Result,'Frequency difference (MHz)');
                % ylabel(app.Figure_Result,'Normalized absolute value of FCF');
                % title(app.Figure_Result,'Frequency correlation function (FCF)');

            elseif  strfind(app.DropDown_type_property.Value, 'DelaySpread')

                xlabel(app.Figure_Fitting,'RMS delay spread (ns)');
                ylabel(app.Figure_Fitting,'CDF of RMS delay spread');
                title(app.Figure_Fitting,'Delay spread');

                % xlabel(app.Figure_Result,'RMS delay spread (ns)');
                % ylabel(app.Figure_Result,'CDF of RMS delay spread');
                % title(app.Figure_Result,'Delay spread');

            elseif  strfind(app.DropDown_type_property.Value, 'Tx_AngularSpread')

                xlabel(app.Figure_Fitting,'RMS angular spread (ns)');
                ylabel(app.Figure_Fitting,'CDF of RMS angular spread');
                title(app.Figure_Fitting,'Tx angular spread');

                % xlabel(app.Figure_Result,'RMS angular spread (ns)');
                % ylabel(app.Figure_Result,'CDF of RMS angular spread');
                % title(app.Figure_Result,'Tx angular spread');    

            elseif  strfind(app.DropDown_type_property.Value, 'Rx_AngularSpread')

                xlabel(app.Figure_Fitting,'RMS angular spread (ns)');
                ylabel(app.Figure_Fitting,'CDF of RMS angular spread');
                title(app.Figure_Fitting,'Rx angular spread');

                % xlabel(app.Figure_Result,'RMS angular spread (ns)');
                % ylabel(app.Figure_Result,'CDF of RMS angular spread');
                % title(app.Figure_Result,'Rx angular spread');

            elseif  strfind(app.DropDown_type_property.Value, 'DopplerSpread')

                xlabel(app.Figure_Fitting,'RMS doppler spread (ns)');
                ylabel(app.Figure_Fitting,'CDF of RMS doppler spread');
                title(app.Figure_Fitting,'Doppler spread');

                % xlabel(app.Figure_Result,'RMS doppler spread (ns)');
                % ylabel(app.Figure_Result,'CDF of RMS doppler spread');
                % title(app.Figure_Result,'Doppler spread');
                
            elseif strfind(app.DropDown_type_property.Value, 'Tx_CCF')
                
                xlabel(app.Figure_Fitting,'Antenna spacing (m)');
                ylabel(app.Figure_Fitting,'Normalized absolute value of SCCF');
                title(app.Figure_Fitting,'空间互相关函数(Spatial Cross-correlation Function)');

                % xlabel(app.Figure_Result,'Antenna spacing (m)');
                % ylabel(app.Figure_Result,'Normalized absolute value of CCF');
                % title(app.Figure_Result,'Space correlation function (CCF)');                

            elseif strfind(app.DropDown_type_property.Value, 'Rx_CCF')
                
                xlabel(app.Figure_Fitting,'Antenna spacing (m)');
                ylabel(app.Figure_Fitting,'Normalized absolute value of SCCF');
                title(app.Figure_Fitting,'空间互相关函数(Spatial Cross-correlation Function)');

                % xlabel(app.Figure_Result,'Antenna spacing (m)');
                % ylabel(app.Figure_Result,'Normalized absolute value of CCF');
                % title(app.Figure_Result,'Space correlation function (CCF)');           

            elseif strfind(app.DropDown_type_property.Value, 'ACF')
                
                xlabel(app.Figure_Fitting,'Time difference (s)');
                ylabel(app.Figure_Fitting,'Absolute value of local temporal ACF');
                title(app.Figure_Fitting,'时间自相关函数(Temporal Auto-correlation function)');

                % xlabel(app.Figure_Result,'Time difference (s)');
                % ylabel(app.Figure_Result,'Normalized absolute value of ACF');
                % title(app.Figure_Result,'Time correlation function (ACF)');   
            end
        end

        % Button pushed function: Button_Optimization
        function Button_OptimizationPushed(app, event)
            try
                if app.CallingApp.canAnalysis == 0
                    mf.add_logs('OptimizationPushed', 'After modifying the frequency band and scenario, click the Run button first to generate the channel matrix');
                    errordlg('After modifying the frequency band and scenario, click the Run button first to generate the channel matrix', 'error: 400',"modal");
                    return;
                end
                tools.setAppScenarios(app.CallingApp)
    
                [freqs, band, ~] = mf.str2frequencys(app.CallingApp.EditField.Value,app.CallingApp.bandwidth.Value,app.CallingApp.freqSamples.Value);
                app.CallingApp.sps.carrier_frequency = freqs*1e9;
                [cm, tx_track, rx_track] = tools.preGenSPs(app.CallingApp, band*1e6);
                result = app.CallingApp.channelresults.results;
    
                if isempty(result)
                    errordlg('Please click the Run button to generate the channel matrix', 'error: 400',"modal");
                    return;
                end
    
                if isempty(app.MeasureData)
                    errordlg('Please upload measurement data first', 'error: 400',"modal");
                    return;
                end
                keywords = {'OW', 'IR', 'VL', 'UV', 'RIS', 'IIoT', 'Maritime', app.CallingApp.sps.scenario_SATELLITE, 'UWA', 'US'};
                if any(contains(app.CallingApp.scenarios, keywords))
                    errordlg('This function is not supported in the current frequency band/scenario', 'error: 400',"modal");
                    return;
                end

                %% 优化函数
                if strfind(app.DropDown_type_property.Value, 'FCF')
                    opti.Opti_FCF(app, cm, tx_track, rx_track); 
                elseif  strfind(app.DropDown_type_property.Value, 'DelaySpread')  
                    opti.Opti_DelaySpread(app, cm, tx_track, rx_track);
                elseif  strfind(app.DropDown_type_property.Value, 'Tx_AngularSpread')
                    isTx = 1;
                    opti.Opti_AngularSpread(app,cm,tx_track,rx_track,isTx);
                elseif  strfind(app.DropDown_type_property.Value, 'Rx_AngularSpread')
                    isTx = 0;
                    opti.Opti_AngularSpread(app,cm,tx_track,rx_track,isTx);
                elseif  strfind(app.DropDown_type_property.Value, 'DopplerSpread')
                    opti.Opti_DopplerSpread(app,cm,tx_track,rx_track);
                elseif  strfind(app.DropDown_type_property.Value, 'Tx_CCF') 
                    isTx = 1;
                    opti.Opti_CCF(app, cm, tx_track, rx_track, isTx); 
                elseif  strfind(app.DropDown_type_property.Value, 'Rx_CCF') 
                    isTx = 0;
                    opti.Opti_CCF(app, cm, tx_track, rx_track, isTx); 
                elseif  strfind(app.DropDown_type_property.Value, 'ACF')  
                    opti.Opti_ACF(app, cm, tx_track, rx_track); 
                end
                opti.show_check_allsettings_Opti(app, app.CallingApp.scenarios,1,1,1,1);
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Button pushed function: CurrentResult
        function CurrentResultButtonPushed(app, event)
           try
                tools.setAppScenarios(app.CallingApp)
                [freqs, band, ~] = mf.str2frequencys(app.CallingApp.EditField.Value,app.CallingApp.bandwidth.Value,app.CallingApp.freqSamples.Value);
                app.CallingApp.sps.carrier_frequency = freqs*1e9;
                [cm, tx_track, rx_track] = tools.preGenSPs(app.CallingApp, band*1e6);
                result = app.CallingApp.channelresults.results;

                if isempty(result)
                    errordlg('Please click the Run button to generate the channel matrix', 'error: 400',"modal");
                    return;
                end

                keywords = {'OW', 'IR', 'VL', 'UV', 'RIS', 'IIoT', 'Maritime'};
                if any(contains(app.CallingApp.scenarios, keywords))
                    errordlg('This function is not supported in the current frequency band/scenario', 'error: 400',"modal");
                    return;
                end
                if strfind(app.DropDown_type_property.Value, 'FCF')
                    opti.get_statisticalProps_Opti_FCF(app, cm, tx_track, rx_track);                 
                elseif  strfind(app.DropDown_type_property.Value, 'DelaySpread')    
                    opti.get_statisticalProps_Opti_DelaySpread(app, cm, tx_track, rx_track);
                elseif  strfind(app.DropDown_type_property.Value, 'Tx_AngularSpread')
                    isTx = 1;
                    opti.get_statisticalProps_Opti_AngularSpread(app, cm, tx_track, rx_track, isTx)
                elseif  strfind(app.DropDown_type_property.Value, 'Rx_AngularSpread')
                    isTx = 0;
                    opti.get_statisticalProps_Opti_AngularSpread(app, cm, tx_track, rx_track, isTx)
                elseif  strfind(app.DropDown_type_property.Value, 'DopplerSpread')
                    opti.get_statisticalProps_Opti_DopplerSpread(app, cm, tx_track, rx_track)
                elseif  strfind(app.DropDown_type_property.Value, 'Tx_CCF') 
                    isTx = 1;
                    opti.get_statisticalProps_Opti_CCF(app, cm, tx_track, rx_track, isTx);
                elseif  strfind(app.DropDown_type_property.Value, 'Rx_CCF') 
                    isTx = 0;
                    opti.get_statisticalProps_Opti_CCF(app, cm, tx_track, rx_track, isTx);
                elseif  strfind(app.DropDown_type_property.Value, 'ACF')    
                    opti.get_statisticalProps_Opti_ACF(app, cm, tx_track, rx_track);
                end
                opti.show_check_allsettings_Opti(app, app.CallingApp.scenarios,1,1,1,1);

            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Close request function: ParameterOptimizationUIFigure
        function ParameterOptimizationUIFigureCloseRequest(app, event)
            app.CallingApp.Button_Optimization.Enable = 'on';
            delete(app)
            
        end

        % Callback function
        function ButtonPushed(app, event)

            opti.show_check_allsettings_Opti(app, app.CallingApp.scenarios,1,1,1,1);

        end

        % Value changed function: LoopNum
        function LoopNumValueChanged(app, event)
            value = app.LoopNum.Value;
            
        end

        % Callback function
        function ButtonPushed2(app, event)
            show_check_allsettings_Opti(app, app.CallingApp.scenarios,1, 1, 1, 1);
        end

        % Button pushed function: Button_saveSps_Opti
        function Button_saveSps_OptiPushed(app, event)
            try
                image_names_tmp = extractBetween(app.DropDown_type_property.Value, "(",")");
                image_names_save = image_names_tmp{1};
                %判断当前有没有生成图像
                axes_component = app.Figure_Fitting;
                line_object = findobj(axes_component, 'Type', 'line');
                if  isempty(line_object)
                    msgbox('Please click the Impart Measurement Data button to generate the image')
                else
                    %生成保存路径
                    save_path = app.CallingApp.channelresults.savePath;
                    assignin('base', 'save_path', save_path);
                    % 创建目录
                    if exist(save_path, 'dir')
                    else
                        mkdir(save_path);
                    end
                    if  isempty(line_object)
                    else
                        h = figure;
                        legend_name = {};
                        i_legend = 1;
                        for i = 1:length(line_object)
                            if ~isempty(line_object(i).DisplayName)
                                plot(line_object(i).XData, line_object(i).YData);
                                legend_name{i_legend} = line_object(i).DisplayName;
                                i_legend = i_legend +1;
                                hold on;
                            end
                        end
                        legend(legend_name);
                        file_name_fig = strcat(image_names_save, '.fig');  % 文件名称
                        full_file_path_fig = fullfile(save_path, file_name_fig);
                        savefig(h, full_file_path_fig);
                        close(h)
                    end
                end
                mes=strcat('Files have been successful saved to', save_path);
                msgbox(mes);
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Drop down opening function: DropDown_txUserIndex
        function DropDown_txUserIndexOpening(app, event)
            %确定多用户
            users_tx = app.CallingApp.UITable_tx.Data{:,1};
            users_rx = app.CallingApp.UITable_rx.Data{:,1};
            app.DropDown_txUserIndex.Items = users_tx;
            app.DropDown_rxUserIndex.Items = users_rx;
        end

        % Drop down opening function: DropDown_rxUserIndex
        function DropDown_rxUserIndexOpening(app, event)
            %确定多用户
            users_tx = app.CallingApp.UITable_tx.Data{:,1};
            users_rx = app.CallingApp.UITable_rx.Data{:,1};
            app.DropDown_txUserIndex.Items = users_tx;
            app.DropDown_rxUserIndex.Items = users_rx;
        end

        % Button pushed function: ClearfigureButton
        function ClearfigureButtonPushed(app, event)
            cla(app.Figure_Fitting);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParameterOptimizationUIFigure = uifigure('Visible', 'off');
            app.ParameterOptimizationUIFigure.Name = '参数优化（Parameter Optimization）';
            app.ParameterOptimizationUIFigure.Icon = 'icon.png';
            app.ParameterOptimizationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParameterOptimizationUIFigureCloseRequest, true);
            app.Panel_timeDomain_TACF = uipanel(app.ParameterOptimizationUIFigure);
            app.Panel_timeDomain_TACF.Title = '参数优化（当前仅适用于时延扩展）Parameter Optimization (Currently Only Available for Delay Spread)';
            app.Figure_Fitting = uiaxes(app.Panel_timeDomain_TACF);
            title(app.Figure_Fitting, '时延扩展(Delay Spread)')
            xlabel(app.Figure_Fitting, 'RMS delay spread (ns)')
            ylabel(app.Figure_Fitting, 'CDF of RMS delay spread')
            zlabel(app.Figure_Fitting, 'Z')
            app.Figure_Fitting.Tag = 'UIAxes_TACF';
            app.NumberofSimulationsLabel = uilabel(app.Panel_timeDomain_TACF);
            app.NumberofSimulationsLabel.Text = '仿真次数(Number of Simulations)';
            app.LoopNum = uieditfield(app.Panel_timeDomain_TACF, 'numeric');
            app.LoopNum.Limits = [1 2000];
            app.LoopNum.ValueChangedFcn = createCallbackFcn(app, @LoopNumValueChanged, true);
            app.LoopNum.Value = 100;
            app.LoadMeasData = uibutton(app.Panel_timeDomain_TACF, 'push');
            app.LoadMeasData.ButtonPushedFcn = createCallbackFcn(app, @LoadMeasDataButtonPushed, true);
            app.LoadMeasData.Text = {'导入测量数据'; 'Import Measurement Data'};
            app.DropDown_type_property = uidropdown(app.Panel_timeDomain_TACF);
            app.DropDown_type_property.Items = {'时延扩展(DelaySpread)', 'Tx端角度扩展(Tx_AngularSpread)', 'Rx端角度扩展(Rx_AngularSpread)', '多普勒扩展(DopplerSpread)', '频域自相关(FCF)', 'Tx端空域自相关(Tx_CCF)', 'Rx端空域自相关(Rx_CCF)', '时域自相关(ACF)'};
            app.DropDown_type_property.ValueChangedFcn = createCallbackFcn(app, @DropDown_type_propertyValueChanged, true);
            app.DropDown_type_property.Value = '时延扩展(DelaySpread)';
            app.Button_Optimization = uibutton(app.Panel_timeDomain_TACF, 'push');
            app.Button_Optimization.ButtonPushedFcn = createCallbackFcn(app, @Button_OptimizationPushed, true);
            app.Button_Optimization.Text = {'优化'; 'Optimize'};
            app.Button_saveSps_Opti = uibutton(app.Panel_timeDomain_TACF, 'push');
            app.Button_saveSps_Opti.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSps_OptiPushed, true);
            app.Button_saveSps_Opti.Text = {'保存所有结果'; 'Save All Results'};
            app.FittingProcessLabel = uilabel(app.Panel_timeDomain_TACF);
            app.FittingProcessLabel.Text = '拟合过程(Fitting Process)';
            app.CurrentResult = uibutton(app.Panel_timeDomain_TACF, 'push');
            app.CurrentResult.ButtonPushedFcn = createCallbackFcn(app, @CurrentResultButtonPushed, true);
            app.CurrentResult.Text = {'生成当前配置特性'; 'Generate Current Configuration Properties'};
            app.settings = uitextarea(app.Panel_timeDomain_TACF);
            app.MatFilePathLabel = uilabel(app.Panel_timeDomain_TACF);
            app.MatFilePathLabel.Text = '';
            app.Label = uilabel(app.Panel_timeDomain_TACF);
            app.Label.Text = '第';
            app.Label_2 = uilabel(app.Panel_timeDomain_TACF);
            app.Label_2.Text = '发射端用户';
            app.DropDown_rxUserIndex = uidropdown(app.Panel_timeDomain_TACF);
            app.DropDown_rxUserIndex.Items = {'1', '2', '3', '4'};
            app.DropDown_rxUserIndex.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_rxUserIndexOpening, true);
            app.DropDown_rxUserIndex.Value = '1';
            app.Label_3 = uilabel(app.Panel_timeDomain_TACF);
            app.Label_3.Text = '第';
            app.TransmittingUserIndexLabel = uilabel(app.Panel_timeDomain_TACF);
            app.TransmittingUserIndexLabel.Text = 'Transmitting User Index';
            app.DropDown_txUserIndex = uidropdown(app.Panel_timeDomain_TACF);
            app.DropDown_txUserIndex.Items = {'1', '2', '3', '4'};
            app.DropDown_txUserIndex.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_txUserIndexOpening, true);
            app.DropDown_txUserIndex.Value = '1';
            app.Label_4 = uilabel(app.Panel_timeDomain_TACF);
            app.Label_4.Text = '接收端用户';
            app.ReceivingUserIndexLabel = uilabel(app.Panel_timeDomain_TACF);
            app.ReceivingUserIndexLabel.Text = 'Receiving User Index';
            app.ClearfigureButton = uibutton(app.Panel_timeDomain_TACF, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButtonPushed, true);
            app.ClearfigureButton.Text = '清空图像数据 ( Clear figure )';
            % Show the figure after all components are created
            app.ParameterOptimizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiOptimization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParameterOptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParameterOptimizationUIFigure)
        end
    end
end