% description: 如果您使用本软件，可引用以下论文:
% For any researchers using the simulator or its modified versions in your scientific work, please cite the following papers:
% [1] C.-X. Wang, Z. Lv, X. Gao, X. You, Y. Hao, and H. Haas, “Pervasive wireless channel modeling theory and applications to 6G GBSMs for all frequency bands and all scenarios,”  IEEE Trans. Veh.
% Technol., vol. 71, no. 9, pp. 9159-9173, Sept. 2022.
% [2] C.-X. Wang, Z. Lv, Y. Chen, and H. Haas, “A complete study of space-time-frequency statistical properties of the 6G pervasive channel model,” IEEE Trans. Commun., vol. 71, no. 12, pp. 7273-7287,
% Dec. 2023.
classdef main_6GPCM_GBSM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        ISACPanel                       matlab.ui.container.Panel
        EditField_14Label_62            matlab.ui.control.Label
        noCluster_10                    matlab.ui.control.NumericEditField
        EditField_14Label_61            matlab.ui.control.Label
        EditField_14Label_60            matlab.ui.control.Label
        noCluster_9                     matlab.ui.control.NumericEditField
        EditField_14Label_59            matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        GHzEditFieldLabel_9             matlab.ui.control.Label
        bandwidth_2                     matlab.ui.control.EditField
        EditField_59                    matlab.ui.control.EditField
        GHzEditFieldLabel_8             matlab.ui.control.Label
        Image16                         matlab.ui.control.Image
        ButtonGroup_txTrack             matlab.ui.container.Panel
        txInitialPos_3                  matlab.ui.control.NumericEditField
        txInitialPos_2                  matlab.ui.control.NumericEditField
        txInitialPos                    matlab.ui.control.NumericEditField
        mInitialpositionLabel           matlab.ui.control.Label
        DirectionLabel_5                matlab.ui.control.Label
        txTrackOri                      matlab.ui.control.EditField
        ms2Accelerationms2Label         matlab.ui.control.Label
        txAcceleration                  matlab.ui.control.NumericEditField
        msVelocitymsLabel               matlab.ui.control.Label
        txSpeed                         matlab.ui.control.NumericEditField
        Label_33                        matlab.ui.control.Label
        txTrackType                     matlab.ui.control.DropDown
        TargettypeDropDown              matlab.ui.control.DropDown
        TargettypeDropDownLabel         matlab.ui.control.Label
        EditField_15Label_23            matlab.ui.control.Label
        noRaysEachCluster_10            matlab.ui.control.NumericEditField
        EditField_15Label_22            matlab.ui.control.Label
        EditField_15Label_21            matlab.ui.control.Label
        EditField_14Label_58            matlab.ui.control.Label
        noRaysEachCluster_9             matlab.ui.control.NumericEditField
        EditField_15Label_20            matlab.ui.control.Label
        noCluster_8                     matlab.ui.control.NumericEditField
        EditField_14Label_57            matlab.ui.control.Label
        SensingmodelDropDown            matlab.ui.control.DropDown
        SensingmodelDropDownLabel       matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        LoSNLoSButton                   matlab.ui.control.RadioButton
        NLoSNLoSButton                  matlab.ui.control.RadioButton
        NLoSLoSButton                   matlab.ui.control.RadioButton
        LoSLoSButton                    matlab.ui.control.RadioButton
        uwaPanel                        matlab.ui.container.Panel
        Image15                         matlab.ui.control.Image
        EditField_14Label_56            matlab.ui.control.Label
        Distance_tx_rx                  matlab.ui.control.NumericEditField
        EditField_15Label_19            matlab.ui.control.Label
        EditField_14Label_55            matlab.ui.control.Label
        acousticSpeed                   matlab.ui.control.NumericEditField
        EditField_15Label_18            matlab.ui.control.Label
        EditField_14Label_54            matlab.ui.control.Label
        rxDepth                         matlab.ui.control.NumericEditField
        EditField_15Label_17            matlab.ui.control.Label
        EditField_14Label_53            matlab.ui.control.Label
        txDepth                         matlab.ui.control.NumericEditField
        EditField_15Label_16            matlab.ui.control.Label
        EditField_14Label_52            matlab.ui.control.Label
        height_water_uwa                matlab.ui.control.NumericEditField
        EditField_15Label_15            matlab.ui.control.Label
        EditField_14Label_51            matlab.ui.control.Label
        wind_speed_uwa                  matlab.ui.control.NumericEditField
        EditField_15Label_14            matlab.ui.control.Label
        EditField_14Label_50            matlab.ui.control.Label
        EditField_14Label_49            matlab.ui.control.Label
        noRaysEachCluster_8             matlab.ui.control.NumericEditField
        EditField_15Label_13            matlab.ui.control.Label
        noCluster_7                     matlab.ui.control.NumericEditField
        EditField_14Label_48            matlab.ui.control.Label
        skywavePanel                    matlab.ui.container.Panel
        EditField_14Label_47            matlab.ui.control.Label
        Bx                              matlab.ui.control.NumericEditField
        eLabel_3                        matlab.ui.control.Label
        EditField_14Label_46            matlab.ui.control.Label
        R12                             matlab.ui.control.NumericEditField
        omegaLabel_4                    matlab.ui.control.Label
        omegaLabel_3                    matlab.ui.control.Label
        Hour                            matlab.ui.control.NumericEditField
        EditField_14Label_45            matlab.ui.control.Label
        EditField_14Label_44            matlab.ui.control.Label
        satHeight_4                     matlab.ui.control.NumericEditField
        Label_32                        matlab.ui.control.Label
        EditField_14Label_43            matlab.ui.control.Label
        satHeight_3                     matlab.ui.control.NumericEditField
        Label_31                        matlab.ui.control.Label
        Image14_2                       matlab.ui.control.Image
        EditField_14Label_42            matlab.ui.control.Label
        EditField_14Label_41            matlab.ui.control.Label
        EditField_14Label_40            matlab.ui.control.Label
        EditField_14Label_39            matlab.ui.control.Label
        noRaysEachCluster_7             matlab.ui.control.NumericEditField
        EditField_15Label_10            matlab.ui.control.Label
        satHeight_2                     matlab.ui.control.NumericEditField
        Label_30                        matlab.ui.control.Label
        Latitude                        matlab.ui.control.NumericEditField
        omegaLabel_2                    matlab.ui.control.Label
        Day                             matlab.ui.control.NumericEditField
        eLabel_2                        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        timechecking                    matlab.ui.control.TextArea
        DaysremainingLabel              matlab.ui.control.Label
        OWCPanel                        matlab.ui.container.Panel
        Image9                          matlab.ui.control.Image
        EditField_14Label_35            matlab.ui.control.Label
        EditField_14Label_38            matlab.ui.control.Label
        EditField_14Label_37            matlab.ui.control.Label
        EditField_14Label_36            matlab.ui.control.Label
        EditField_14Label_34            matlab.ui.control.Label
        EditField_14Label_33            matlab.ui.control.Label
        noRaysEachCluster_6             matlab.ui.control.NumericEditField
        EditField_15Label_8             matlab.ui.control.Label
        noCluster_6                     matlab.ui.control.NumericEditField
        EditField_14Label_5             matlab.ui.control.Label
        AntennaPD                       matlab.ui.container.ButtonGroup
        EditField_14Label_32            matlab.ui.control.Label
        EditField_14Label_31            matlab.ui.control.Label
        EditField_14Label_30            matlab.ui.control.Label
        EditField_14Label_29            matlab.ui.control.Label
        PD_omegavR_E                    matlab.ui.control.NumericEditField
        elevationPD_2                   matlab.ui.control.Label
        PD_omegavR_A                    matlab.ui.control.NumericEditField
        PD_azi                          matlab.ui.control.Label
        elevationAnglePD                matlab.ui.control.NumericEditField
        elevationPD                     matlab.ui.control.Label
        azimuthAnglePD                  matlab.ui.control.NumericEditField
        azimuthPD                       matlab.ui.control.Label
        AntennaLED                      matlab.ui.container.ButtonGroup
        EditField_14Label_28            matlab.ui.control.Label
        EditField_14Label_27            matlab.ui.control.Label
        EditField_14Label_26            matlab.ui.control.Label
        EditField_14Label_25            matlab.ui.control.Label
        percolumnLabel_8                matlab.ui.control.Label
        percolumnLabel_11               matlab.ui.control.Label
        percolumnLabel_10               matlab.ui.control.Label
        percolumnLabel_9                matlab.ui.control.Label
        elevationAngleLEDColumn         matlab.ui.control.NumericEditField
        EditField_13Label_2             matlab.ui.control.Label
        azimuthAngleLEDColumn           matlab.ui.control.NumericEditField
        EditField_12Label_2             matlab.ui.control.Label
        spacingLEDColumn                matlab.ui.control.NumericEditField
        EditField_11Label_2             matlab.ui.control.Label
        noLEDColumn                     matlab.ui.control.NumericEditField
        Label_29                        matlab.ui.control.Label
        elevationAngleLEDRow            matlab.ui.control.NumericEditField
        Label_28                        matlab.ui.control.Label
        azimuthAngleLEDRow              matlab.ui.control.NumericEditField
        Label_27                        matlab.ui.control.Label
        spacingLEDRow                   matlab.ui.control.NumericEditField
        LEDLabel_2                      matlab.ui.control.Label
        noLEDRow                        matlab.ui.control.NumericEditField
        LEDLabel                        matlab.ui.control.Label
        LEDType                         matlab.ui.control.DropDown
        LEDDropDown_3Label              matlab.ui.control.Label
        LEDColor                        matlab.ui.control.DropDown
        LEDDropDown_2Label              matlab.ui.control.Label
        RISPanel                        matlab.ui.container.Panel
        noCluster_RIS3                  matlab.ui.control.NumericEditField
        BSUELabel_2                     matlab.ui.control.Label
        noRaysEachCluster_RIS3          matlab.ui.control.NumericEditField
        EditField_15Label_12            matlab.ui.control.Label
        noCluster_RIS1                  matlab.ui.control.NumericEditField
        BSRISLabel_2                    matlab.ui.control.Label
        noRaysEachCluster_RIS1          matlab.ui.control.NumericEditField
        EditField_15Label_11            matlab.ui.control.Label
        ButtonGroup_RIS                 matlab.ui.container.ButtonGroup
        NLoSButton_RIS                  matlab.ui.control.RadioButton
        LoSButton_RIS                   matlab.ui.control.RadioButton
        EditField_14Label_24            matlab.ui.control.Label
        EditField_14Label_23            matlab.ui.control.Label
        EditField_14Label_22            matlab.ui.control.Label
        EditField_14Label_21            matlab.ui.control.Label
        EditField_14Label_20            matlab.ui.control.Label
        EditField_14Label_19            matlab.ui.control.Label
        EditField_14Label_18            matlab.ui.control.Label
        EditField_14Label_17            matlab.ui.control.Label
        LoSLabel                        matlab.ui.control.Label
        LoSLabel_2                      matlab.ui.control.Label
        EditField_RISposx               matlab.ui.control.NumericEditField
        RISLabel                        matlab.ui.control.Label
        EditField_RISposz               matlab.ui.control.NumericEditField
        EditField_RISposy               matlab.ui.control.NumericEditField
        EditField_RISdelta_x            matlab.ui.control.NumericEditField
        Label_21                        matlab.ui.control.Label
        EditField_RISdelta_y            matlab.ui.control.NumericEditField
        Label_20                        matlab.ui.control.Label
        EditField_RISwidth              matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        EditField_RISheight             matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        EditField_RISy                  matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        EditField_RISx                  matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        vectorEditField_0               matlab.ui.control.NumericEditField
        RISEditFieldLabel               matlab.ui.control.Label
        vectorEditField_8               matlab.ui.control.NumericEditField
        vectorEditField_7               matlab.ui.control.NumericEditField
        vectorEditField_6               matlab.ui.control.NumericEditField
        vectorEditField_5               matlab.ui.control.NumericEditField
        vectorEditField_4               matlab.ui.control.NumericEditField
        vectorEditField_3               matlab.ui.control.NumericEditField
        vectorEditField_2               matlab.ui.control.NumericEditField
        vectorEditField_1               matlab.ui.control.NumericEditField
        Image12                         matlab.ui.control.Image
        satellitePanel                  matlab.ui.container.Panel
        Image14                         matlab.ui.control.Image
        EditField_14Label_16            matlab.ui.control.Label
        EditField_14Label_15            matlab.ui.control.Label
        EditField_14Label_14            matlab.ui.control.Label
        EditField_14Label_13            matlab.ui.control.Label
        EditField_14Label_12            matlab.ui.control.Label
        EditField_14Label_11            matlab.ui.control.Label
        EditField_14Label_10            matlab.ui.control.Label
        EditField_14Label_9             matlab.ui.control.Label
        EditField_14Label_8             matlab.ui.control.Label
        EditField_14Label_7             matlab.ui.control.Label
        rainRate                        matlab.ui.control.NumericEditField
        mmhEditFieldLabel               matlab.ui.control.Label
        DropDown_8                      matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        noRaysEachCluster_4             matlab.ui.control.NumericEditField
        EditField_15Label_6             matlab.ui.control.Label
        noCluster_4                     matlab.ui.control.NumericEditField
        EditField_14Label_3             matlab.ui.control.Label
        satHeight                       matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        FinEditField                    matlab.ui.control.NumericEditField
        nuLabel                         matlab.ui.control.Label
        EinEditField                    matlab.ui.control.NumericEditField
        omegaLabel                      matlab.ui.control.Label
        BinEditField                    matlab.ui.control.NumericEditField
        eLabel                          matlab.ui.control.Label
        DinEditField                    matlab.ui.control.NumericEditField
        OmegaLabel                      matlab.ui.control.Label
        CinEditField                    matlab.ui.control.NumericEditField
        iotaLabel                       matlab.ui.control.Label
        maritimePanel                   matlab.ui.container.Panel
        Image11                         matlab.ui.control.Image
        SubchannelofseasurfacePanel_label  matlab.ui.control.Label
        SubchannelofseasurfaceevaporationwaveguidePanel_label  matlab.ui.control.Label
        wind_speed                      matlab.ui.control.NumericEditField
        WindvelocitymsLabel             matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        SeasurfaceevaporationwaveguideheightmLabel  matlab.ui.control.Label
        SubchannelofseasurfacePanel     matlab.ui.container.Panel
        GHzEditFieldLabel_7             matlab.ui.control.Label
        noRaysEachCluster_3             matlab.ui.control.NumericEditField
        GHzEditFieldLabel_6             matlab.ui.control.Label
        noCluster_3                     matlab.ui.control.NumericEditField
        SubchannelofseasurfaceevaporationductPanel  matlab.ui.container.Panel
        GHzEditFieldLabel_5             matlab.ui.control.Label
        noRaysEachCluster_2             matlab.ui.control.NumericEditField
        GHzEditFieldLabel_4             matlab.ui.control.Label
        noCluster_2                     matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        MHzEditFieldLabel               matlab.ui.control.Label
        GHzEditFieldLabel               matlab.ui.control.Label
        freqSamples                     matlab.ui.control.EditField
        bandwidth                       matlab.ui.control.EditField
        Number_of_subcarriers_Label     matlab.ui.control.Label
        Bandwidth_Label                 matlab.ui.control.Label
        Carrierfrequency_Label          matlab.ui.control.Label
        MHzEditFieldLabel_2             matlab.ui.control.Label
        choose_parameter                matlab.ui.control.Button
        panelPos1                       matlab.ui.container.Panel
        DropDown_7                      matlab.ui.control.DropDown
        panelPos2                       matlab.ui.container.Panel
        Image13                         matlab.ui.control.Image
        EditField_15Label_9             matlab.ui.control.Label
        EditField_14Label_6             matlab.ui.control.Label
        noRaysEachCluster_5             matlab.ui.control.NumericEditField
        EditField_15Label_7             matlab.ui.control.Label
        noCluster_5                     matlab.ui.control.NumericEditField
        EditField_14Label_4             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NLoSvButton                     matlab.ui.control.RadioButton
        NLoSO2IButton                   matlab.ui.control.RadioButton
        LoSO2IButton                    matlab.ui.control.RadioButton
        NLoSButton                      matlab.ui.control.RadioButton
        LoSButton                       matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.EditField
        Tree_2                          matlab.ui.container.Tree
        Node_2                          matlab.ui.container.TreeNode
        SatelliteNode                   matlab.ui.container.TreeNode
        SatelliteRuralNode              matlab.ui.container.TreeNode
        SatelliteUrbanNode              matlab.ui.container.TreeNode
        SatelliteSuburbanNode           matlab.ui.container.TreeNode
        SatelliteDenseUrbanNode         matlab.ui.container.TreeNode
        UAVNode                         matlab.ui.container.TreeNode
        UAVUMiNode                      matlab.ui.container.TreeNode
        UAVUMaNode                      matlab.ui.container.TreeNode
        UAVRMaNode                      matlab.ui.container.TreeNode
        TerrestrialcommuNode            matlab.ui.container.TreeNode
        UMiNode                         matlab.ui.container.TreeNode
        UMaNode                         matlab.ui.container.TreeNode
        RMaNode                         matlab.ui.container.TreeNode
        RMiNode                         matlab.ui.container.TreeNode
        OfficeOutdoorNode               matlab.ui.container.TreeNode
        InterOfficeNode                 matlab.ui.container.TreeNode
        IndoorNode                      matlab.ui.container.TreeNode
        IndoorCorridorNode              matlab.ui.container.TreeNode
        ExpresswaymainroadNode          matlab.ui.container.TreeNode
        ExpresswaysideroadNode          matlab.ui.container.TreeNode
        SkyWaveNode                     matlab.ui.container.TreeNode
        GroundWaveNode                  matlab.ui.container.TreeNode
        MaritimeNode                    matlab.ui.container.TreeNode
        MaritimeShiptoshipNode          matlab.ui.container.TreeNode
        MaritimeShiptolandNode          matlab.ui.container.TreeNode
        V2VNode                         matlab.ui.container.TreeNode
        V2VHighwayNode                  matlab.ui.container.TreeNode
        V2VUrbanNode                    matlab.ui.container.TreeNode
        RISNode                         matlab.ui.container.TreeNode
        RISIndoorNode                   matlab.ui.container.TreeNode
        RISUMiNode                      matlab.ui.container.TreeNode
        RISUMaNode                      matlab.ui.container.TreeNode
        RISRMaNode                      matlab.ui.container.TreeNode
        IIoTNode_2                      matlab.ui.container.TreeNode
        IIoT_LoSNode                    matlab.ui.container.TreeNode
        IIoTDH_NLoSNode                 matlab.ui.container.TreeNode
        IIoTDL_NLoSNode                 matlab.ui.container.TreeNode
        IIoTSH_NLoSNode                 matlab.ui.container.TreeNode
        IIoTSL_NLoSNode                 matlab.ui.container.TreeNode
        ISACNode                        matlab.ui.container.TreeNode
        ISACUMaNode                     matlab.ui.container.TreeNode
        ISACUMiNode                     matlab.ui.container.TreeNode
        ISACRMaNode                     matlab.ui.container.TreeNode
        ISACIndoorNode                  matlab.ui.container.TreeNode
        UHSTNode                        matlab.ui.container.TreeNode
        USNode                          matlab.ui.container.TreeNode
        UStunnelNode                    matlab.ui.container.TreeNode
        USgarageNode                    matlab.ui.container.TreeNode
        UWANode                         matlab.ui.container.TreeNode
        UWAShallowWaterNode             matlab.ui.container.TreeNode
        Tree                            matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        acousticNode                    matlab.ui.container.TreeNode
        ShortWaveNode                   matlab.ui.container.TreeNode
        VHFNode                         matlab.ui.container.TreeNode
        sub6GHzNode                     matlab.ui.container.TreeNode
        cmWaveNode                      matlab.ui.container.TreeNode
        mmWaveNode                      matlab.ui.container.TreeNode
        THzNode                         matlab.ui.container.TreeNode
        OWNode                          matlab.ui.container.TreeNode
        IRNode                          matlab.ui.container.TreeNode
        VLNode                          matlab.ui.container.TreeNode
        UVNode                          matlab.ui.container.TreeNode
        AntennasettingsTab              matlab.ui.container.Tab
        edit_antenna                    matlab.ui.control.Button
        UITable_antenna                 matlab.ui.control.Table
        delete_antenna                  matlab.ui.control.Button
        add_antenna                     matlab.ui.control.Button
        Tab_3                           matlab.ui.container.Tab
        ButtonGroup_preview             matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        TxButton                        matlab.ui.control.RadioButton
        RxButton                        matlab.ui.control.RadioButton
        Tx_RxPosVisualize               matlab.ui.control.Button
        MovementtrackofRxPanel          matlab.ui.container.Panel
        DropDown_rx                     matlab.ui.control.DropDown
        DropDown_9Label_2               matlab.ui.control.Label
        UITable_rx                      matlab.ui.control.Table
        settingButton_2                 matlab.ui.control.Button
        MovementtrackofTxPanel          matlab.ui.container.Panel
        DropDown_tx                     matlab.ui.control.DropDown
        DistributionLabel               matlab.ui.control.Label
        UITable_tx                      matlab.ui.control.Table
        settingButton                   matlab.ui.control.Button
        CheckLabel                      matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        switchSampleRate                matlab.ui.control.Switch
        ButtonGroup_cluster             matlab.ui.container.Panel
        msLastbounceClustersvelocityLabel  matlab.ui.control.Label
        znSpeed                         matlab.ui.control.NumericEditField
        DirectionLabel_3                matlab.ui.control.Label
        anOri                           matlab.ui.control.EditField
        DirectionLabel_4                matlab.ui.control.Label
        znOri                           matlab.ui.control.EditField
        msFirstbounceClustersvelocityLabel  matlab.ui.control.Label
        anSpeed                         matlab.ui.control.NumericEditField
        sampleRate                      matlab.ui.control.NumericEditField
        SamplingratepersecondHzLabel    matlab.ui.control.Label
        trackLength                     matlab.ui.control.NumericEditField
        MovedurationsLabel              matlab.ui.control.Label
        Tab_4                           matlab.ui.container.Tab
        Model_Selection                 matlab.ui.control.DropDown
        ChooseachannelmodelLabel        matlab.ui.control.Label
        ChoosechannelinfostobesavedLabel  matlab.ui.control.Label
        Tree_save                       matlab.ui.container.CheckBoxTree
        AllNode_save                    matlab.ui.container.TreeNode
        LargescaleparametersNode        matlab.ui.container.TreeNode
        Node_saveTxtrack                matlab.ui.container.TreeNode
        Node_saveRxtrack                matlab.ui.container.TreeNode
        Node_saveCIR                    matlab.ui.container.TreeNode
        Node_saveCTF                    matlab.ui.container.TreeNode
        Node_saveDalays                 matlab.ui.container.TreeNode
        Node_saveClusters               matlab.ui.container.TreeNode
        BeamdomainresultsNode           matlab.ui.container.TreeNode
        Run_times                       matlab.ui.control.NumericEditField
        NumofSimulationsLabel           matlab.ui.control.Label
        Button_Optimization             matlab.ui.control.Button
        statisticalProps                matlab.ui.control.Button
        CIR                             matlab.ui.control.Button
        Button_Analysis                 matlab.ui.control.Button
        settings                        matlab.ui.control.TextArea
        Tab_5                           matlab.ui.container.Tab
        Button_copy                     matlab.ui.control.Button
        SoutheastUniversityPurpleMountainlaboratoriesLabel  matlab.ui.control.Label
        advice                          matlab.ui.control.Button
        Label_about_4                   matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        GV30Label                       matlab.ui.control.Label
        Label_about_2                   matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        TxtrackMenu                     matlab.ui.container.ContextMenu
        ConfiguremodifyantennaarrayidMenu  matlab.ui.container.Menu
        ConfiguremodifymotiontrajectoryMenu_2  matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        RxtrackMenu                     matlab.ui.container.ContextMenu
        ConfiguremodifyantennaarrayidMenu_2  matlab.ui.container.Menu
        ConfiguremodifymotiontrajectoryMenu  matlab.ui.container.Menu
        DeleteMenu_2                    matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        EditselectedantennaarrayMenu    matlab.ui.container.Menu
        DeleteselectedantennaarrayMenu  matlab.ui.container.Menu
    end


    properties (Access = public)
        sps = simulation_parameters; % 仿真参数控制类
        h_satellite;
        lsf_confs = struct('T', 15, 'P', 101300.0, 'W', 7.5, 'N', 1, 'X', [1.0], 'Y', [1.0], 'Z', [1.0], 'R', 10);
        channelresults = struct('results', [], 'delays', [], 'lsps', [], 'ssps', [], 'savePath', []);
        scenarios = 'Indoor';  % 默认选择的场景;
        propagation_condition;
        canAnalysis = 0;
        UserId;
        timer;  % 定时器
        selection_tx;
        selection_rx;
        user_number = 100;
        selection_an % 选中的天线
    end

    properties (Access = public)
        parameterApp; % 信道模型参数界面
        guiRunApp; % 信道矩阵生成界面
        guiAnalysisApp; % 信道分析界面
        guiOptimizationApp; %信道优化界面
        guiAdviceApp;
        track_Tx_straightApp;
        track_Tx_circleApp;
        track_Tx_randomApp;
        track_Rx_straightApp;
        track_Rx_circleApp;
        track_Rx_randomApp;
        chooseAntennaApp;
        track_Tx_changeApp;
        track_Rx_changeApp;
        antenna_change;
    end

    properties (Access = private)
        mouse_ind; % 天线配置中鼠标点击删除
    end

    methods (Access = private)

        function changeAntennaRxState(app, state)
            app.noAntennaRx_2.Value = 1;
            app.noAntennaRx_2.Editable = state;
            app.noAntennaRx_2.Enable = state;
            app.spacingRx_2.Value = 0.5;
            app.spacingRx_2.Editable = state;
            app.spacingRx_2.Enable = state;
            app.beta_z_Rx.Value = 30;
            app.beta_z_Rx.Enable = state;
            app.beta_z_Rx.Editable = state;
            app.elevationAngleRx_2.Value = 45;
            app.elevationAngleRx_2.Enable = state;
        end

        function changeAntennaTxState(app, state)
            app.noAntennaTx_2.Value = 1;
            app.noAntennaTx_2.Enable = state;
            app.noAntennaTx_2.Editable = state;
            app.spacingTx_2.Value = 0.5;
            app.spacingTx_2.Enable = state;
            app.spacingTx_2.Editable = state;
            app.beta_z_Tx.Value = 30;
            app.beta_z_Tx.Enable = state;
            app.beta_z_Tx.Editable = state;
            app.beta_x_Tx.Value = 45;
            app.beta_x_Tx.Enable = state;
        end

        function saveSettingsFile(app, savePath)
            try
                tools.check_freqAndScensChosen(app);
                tools.setAppScenarios(app);
                % 检查天线运动轨迹等是否已经设置
                tools.check_ant_traj_chosen(app, app.scenarios);

                allSettings = transpose(tools.app2settings(app, app.scenarios));
                assignin('base','allSettings',allSettings);
                settingsFileName = strcat(savePath, filesep, 'saveconfs_', app.scenarios, '_', datestr(now,'yyyymmddHHMMss'), '.mat');
                assignin('base','settingsFileName',settingsFileName);
                if exist(savePath, 'dir')
                else
                    mkdir(savePath)
                end
                %保存为.mat函数
                save(settingsFileName, 'allSettings');
                msgbox(['The configuration file ', settingsFileName, ' is saved'], "modal");
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        function checktime(app)

            try
                % 连接数据库
                conn = databaseConnection();
                if ~isempty(conn.Message)
                    error(['Connection error: ' conn.Message]);
                end

                % 更新 nowtime 字段为当前时间
                updateQuery = sprintf('UPDATE time SET now_time = NOW() WHERE time_id = %d', 5);

                exec(conn, updateQuery);

                % 读取 release_time 值
                selectQuery = sprintf('SELECT release_time FROM time WHERE time_id = %d', 5);
                curs = exec(conn, selectQuery);
                curs = fetch(curs);
                if ~isempty(curs.Message)
                    error(['Query error: ' curs.Message]);
                end

                % 检查是否有数据返回
                if isempty(curs.Data)
                    error('No corresponding data found');
                end

                % 获取 release_time 值
                releaseTimeValue = curs.Data{1};
                disp(releaseTimeValue);

                % 读取 now_time 值
                selectQuery = sprintf('SELECT now_time FROM time WHERE time_id = %d', 5);
                curs = exec(conn, selectQuery);
                curs = fetch(curs);
                if ~isempty(curs.Message)
                    error(['Query error: ' curs.Message]);
                end

                % 检查是否有数据返回
                if isempty(curs.Data)
                    error('No corresponding data found');
                end

                % 获取 now_time 值
                nowTimeValue = curs.Data{1};
                disp(nowTimeValue);
                % 计算两个时间的差值
                % 将字符串格式的日期转换为 MATLAB 日期类型
                releaseTime = datetime(releaseTimeValue);
                nowTime = datetime(nowTimeValue);

                % 计算两个日期之间的差值
                timeDiff = nowTime - releaseTime;
                disp(timeDiff);

                remainingDays = 30 - days(timeDiff);
                disp(remainingDays);

                remainingDaysStr = num2str(remainingDays);

                % 然后将字符向量赋值给 app.timechecking.Value
                app.timechecking.Value = remainingDaysStr;

                % 关闭数据库连接
                close(conn);
                if remainingDays < 0
                    if isvalid(app) && ishandle(app.UIFigure)
                        % 显示提示消息
                        uialert(app.UIFigure, 'Software usage period has expired.', 'Expiration Alert', 'Icon', 'warning');
                        pause(3);

                        % 关闭程序
                        delete(app);
                    else
                        return;
                    end
                end
            catch exception
                message = regexprep(exception.message, '://.*', '');
                errordlg(message, 'error: 400',"modal");
                if isvalid(app) && ishandle(app.UIFigure)
                    delete(app);
                    return;
                end
                return;
            end
        end

        function startTimer(app)
            % 创建定时器
            app.timer = timer(...
                'ExecutionMode', 'fixedRate', ...  % 定时器模式
                'Period', 12*60*60, ...            % 间隔时间（12小时）
                'TimerFcn', @(~,~) app.checktime()); % 定时器回调函数

            % 启动定时器
            start(app.timer);
        end

        function onAppClosed(app)
            global isAppOpened;
            isAppOpened = false; % 当新界面关闭时，重置全局变量(app)
        end
        function selectan(app)
            app.selection_an=app.UITable_antenna.Selection;
        end
    end

    methods (Access = public)
        function setuserid(app,userid)
            app.UserId = userid;
        end

        % 更新天线配置模块table
        function updata(app,number,name,numberantenna,numberantenna1,spacing,spacing1,betaz,betay,betax,identify,directory,type)
            team = {string(number) string(name) string(numberantenna) string(numberantenna1) string(spacing) string(spacing1) string(betaz) string(betay) string(betax) string(identify) string(directory) string(type)};
            teamTable = cell2table(team);
            newColumnNames = teamTable.Properties.VariableNames;

            % 更新现有表格的列名以匹配 teamTable 的列名
            app.UITable_antenna.Data.Properties.VariableNames = newColumnNames;
            app.UITable_antenna.Data = [app.UITable_antenna.Data;teamTable];
            app.UITable_antenna.Data(:,1) = mat2cell(num2str((1:1:size(app.UITable_antenna.Data,1)).'),ones(size(app.UITable_antenna.Data,1),1));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default values
            addpath(genpath('GUI'));
            app.UIFigure.Name = '东南大学-紫金山实验室-6G普适信道仿真器(SEU-PML-6GPCS) v3.0';
            app.RISPanel.Parent = app.Tab;
            app.ISACPanel.Parent = app.Tab;
            app.satellitePanel.Parent = app.panelPos1;
            app.skywavePanel.Parent = app.panelPos1;
            app.maritimePanel.Parent = app.Tab;
            app.OWCPanel.Parent = app.Tab;
            app.uwaPanel.Parent = app.Tab;

            app.Tree.SelectedNodes = app.sub6GHzNode;
            app.Tree_2.SelectedNodes = app.IndoorNode;
            app.TabGroup.SelectedTab = app.Tab_5;
            
            tools.check_display_freqTreeSelected(app);

            team1 = {'1', 'Antenna_1', '2', '\', '0.5', '\', '0', '0', '0', '理想点源(Ideal point source)', '', '均匀线阵(ULA)'};
            app.UITable_antenna.Data = [app.UITable_antenna.Data;cell2table(team1)];
            team= {'1', 'point_1', '[0 0 20]', '1', 'linear', '5', '1', '[1,1,0]'};
            app.UITable_tx.Data = [app.UITable_tx.Data; cell2table(team)];
            team= {'1', 'point_1', '[20 10 0]', '1', 'static', '0', '0', '[1,1,0]'};
            app.UITable_rx.Data = [app.UITable_rx.Data; cell2table(team)];

%             startTimer(app);
        end

        % Callback function
        function AntennaTxSelectionChanged(app, event)
            selectedButton = app.AntennaTx.SelectedObject;
            if contains(selectedButton.Text, 'ULA')
                changeAntennaTxState(app, 'off');
            else
                changeAntennaTxState(app, 'on')
            end
        end

        % Callback function
        function AntennaRxSelectionChanged(app, event)
            selectedButton = app.AntennaRx.SelectedObject;
            if contains(selectedButton.Text, 'ULA')
                changeAntennaRxState(app, 'off');
            else
                changeAntennaRxState(app, 'on')
            end
        end

        % Button pushed function: CIR
        function CIRButtonPushed(app, event)
            try
                app.sps = simulation_parameters;
                mf.add_logs('CIRButtonPushed', '------------------------Start-------------------------');
                tools.setAppScenarios(app)
                tools.get_skywave_confs(app);
                scenario = app.scenarios;
                % 生成信道，最开始先进行参数检查
                [freqs, band, freq_sample] = mf.str2frequencys(app.EditField.Value, app.bandwidth.Value, app.freqSamples.Value);
                if strfind(scenario, app.sps.scenario_UWA)
                    app.sps.carrier_frequency = freqs*1e3;
                    B = band*1e3;
                else
                    app.sps.carrier_frequency = freqs*1e9;
                    B = band*1e6;
                end


                [cm, tx_track, rx_track, cm_l] = tools.preGenSPs(app, B);

                %显示当前参数
                mf.add_logs('CIRButtonPushed', '---------------Parameter settings are checked and displayed---------------');
                show_check_allsettings(app, scenario);

                savePath = strcat('results', filesep, app.Model_Selection.Value, filesep, scenario, '_', datestr(now,'yyyymmddHHMMss'));
                mf.add_logs('CIRButtonPushed', ['Results will be saved to ', savePath]);

                app.sps.use_space_consistency_lsps = true;

                %% 小尺度衰落计算
                lsps = [];
                ssps = [];

                num_simulations = app.Run_times.Value; %黄昱崧添加

                mf.add_logs('CIRButtonPushed', '---------------Generating the channel matrix--------------');

                for sim_idx = 1:num_simulations
                    d_waitbar = uiprogressdlg(app.UIFigure, 'Title', 'Generate channel coefficients', 'Message',strcat('Simulating: ',int2str(sim_idx),'/',int2str(num_simulations), ', please wait patiently...'),'Cancelable','on');
                    d_waitbar.Value = 0.1/num_simulations;
                    switch  app.Model_Selection.Value
                        case '6GPCS'
                            if strfind(scenario, app.sps.freqband_SHORTWAVE)
                                ori_scp = cm.sim_params.scen_para;
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR(ori_scp, scenario);
                                [H, delay] = mf.result2H(result, result_delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay,[],d_waitbar); % H, freq_sample, B, delay,
                                h_CIR = ifft(H_CTF, [], 4);
                            elseif strfind(scenario, app.sps.scenario_ISAC)
                                ori_scp = cm.sim_params.scen_para;
                                [result, result_delay, lsps, ssps] = cm.get_CIR_ISAC(cm.tx_track, cm.rx_track, cm.tar_track, ori_scp, 0);
                                h_CIR = result;
                                H_CTF = mf.H2ctf(h_CIR, freq_sample, B, result_delay,[]); % H, freq_sample, B, delay, 第一对用户
                                % h_CIR = ifft(H_CTF, [], 4);


                                % % 感知接收天线位置与发射天线相对静止，只横坐标相隔0.5 m
                                % cm_back = tools.constructCM(app, scenario);
                                % cm_back.rx_track = cm.rx_track;
                                % cm_back.tx_track = cm.tx_track;
                                % [ ~, ~, ~, share ]  = cm.get_CIR_Sensing([]);
                                % [ result, delay, lsps, ssps] = cm.get_CIR_Com(cm_back, [],[], share, []);
                                % [H, delay] = mf.result2H(result, delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                % H_CTF = mf.H2ctf(H{1,1}, freq_sample, B, delay{1,1},[],d_waitbar); % H, freq_sample, B, delay,
                                % h_CIR = ifft(H_CTF, [], 4);
                            elseif strfind(scenario, app.sps.scenario_IIOT)
                                d_waitbar.Value = 0.2/num_simulations;
                                if d_waitbar.CancelRequested
                                    close(d_waitbar);
                                    return;
                                end
                                mf.add_logs('CIRGenerate', '-----------------------Generating SMC channel coefficients-------------------------');
                                [ result_smc, delay_smc,result_dmc, delay_dmc, LSP ] = cm.get_CIR_IIoT([]);
                                [H1, delay11] = mf.result2H_IIoT(result_smc, delay_smc,0);
                                H_CTF_SMC = mf.H2ctf(H1, freq_sample, B, delay11,[],d_waitbar);
                                h_SMC = ifft(H_CTF_SMC,[],4);

                                d_waitbar.Value = 0.3/num_simulations;
                                if d_waitbar.CancelRequested
                                    close(d_waitbar);
                                    return;
                                end

                                mf.add_logs('CIRGenerate', '-----------------------Generating DMC channel coefficients-------------------------');
                                [H2, delay22] = mf.result2H_IIoT(result_dmc, delay_dmc,1);
                                H_CTF_DMC = mf.H2ctf(H2, freq_sample, B, delay22,[],d_waitbar);
                                h_DMC = ifft(H_CTF_DMC, [], 4);

                                h_CIR = h_SMC + h_DMC;  % result: no_txAntenna * no_rxAntenna * snaps * delays
                                lsps.lsps = LSP;
                                ssps = [];
                                H_CTF.H_CTF_SMC = H_CTF_SMC;
                                H_CTF.H_CTF_DMC = H_CTF_DMC;

                            elseif strfind(scenario, app.sps.scenario_RIS)
                                [ H, delay_total, H_tx2RIS, H_RIS2rx, G_RIS_diag, delay_tx2RIS, delay_RIS2rx ] = cm.get_CIR_totalRIS(cm.ris);
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay_total,[],d_waitbar);
                                H_CTF_BR = mf.H2ctf(H_tx2RIS, freq_sample, B, delay_tx2RIS,[],d_waitbar);
                                H_CTF_RU = mf.H2ctf(H_RIS2rx, freq_sample, B, delay_RIS2rx,[],d_waitbar);
                                h_CIR = ifft(H_CTF, [], 4);
                                h_CIR_BR = ifft(H_CTF_BR, [], 4);
                                h_CIR_RU = ifft(H_CTF_RU, [], 4);

                            elseif strfind(scenario, app.sps.scenario_UHST)
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR(cm.sim_params.scen_para, app.sps.scenario_UHST);
                                [H, delay] = mf.result2H(result, result_delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay,[],d_waitbar);
                                h_CIR = ifft(H_CTF, [], 4);

                            elseif strfind(scenario, app.sps.scenario_MARITIME)
                                cm.sim_params.scen_para.wind_speed = app.wind_speed.Value;
                                cm.sim_params.scen_para.h_duct = app.EditField_7.Value;
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR_maritime( cm_l, tx_track, rx_track);
                                [H, delay] = mf.result2H(result, result_delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay,[],d_waitbar); % H, freq_sample, B, delay,
                                h_CIR = ifft(H_CTF, [], 4);

                            elseif strfind(scenario, app.sps.scenario_UWA)
                                cm.sim_params.scen_para.wind_speed = app.wind_speed_uwa.Value;
                                cm.sim_params.scen_para.height_water = app.height_water_uwa.Value;
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR_UWA();
                                [H, delay] = mf.result2H(result, result_delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay,[],d_waitbar); % H, freq_sample, B, delay,
                                h_CIR = ifft(H_CTF, [], 4);

                            elseif strfind(scenario, app.sps.scenario_SATELLITE)
                                % EOA = mf.get_satellite_elevation(tx_track, rx_track); % 卫星的仰角
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR_Satellite(tx_track, rx_track, cm.sim_params.scen_para, 1, 'single-clusters');
                                [H, delay] = mf.result2H(result, result_delay); % B5G中的H no_txAntenna * no_rxAntenna * snaps * m*n
                                H_CTF = mf.H2ctf(H, freq_sample, B, delay,[],d_waitbar); % H, freq_sample, B, delay,
                                h_CIR = ifft(H_CTF, [], 4);

                            elseif tools.is_owc_band(scenario, app)
                                owc = tools.gen_owc_gui(app);
                                [h_CIR, ~] = cm.get_CIR_OWC(owc, tx_track, rx_track);
                                lsps = [];
                                H_CTF = [];
                            elseif strfind(scenario, app.sps.scenario_VHF)
                                SoS = cm.get_lsf_SOS(cm.decorr_dist);
                                [ result, result_delay, lsps, ssps_temp] = cm.get_CIR_VHF(cm, tx_track, rx_track, SoS);
                                h_CIR = cell(size(result,1),size(result,2));
                                H_CTF = cell(size(result,1),size(result,2));
                                ssps = cell(size(result,1),size(result,2));
                                for i_user_tx = 1:size(result,1)
                                    for i_user_rx = 1:size(result,2)
                                        H_CTF_temp = mf.H2ctf(result{i_user_tx,i_user_rx}, freq_sample, B, result_delay{i_user_tx,i_user_rx},[],d_waitbar); % H, freq_sample, B, delay,
                                        h_CIR_temp = ifft(H_CTF_temp, [], 4);
                                        h_CIR{i_user_tx, i_user_rx} = h_CIR_temp;
                                        H_CTF{i_user_tx, i_user_rx} = H_CTF_temp;
                                        ssps{i_user_tx,i_user_rx} = ssps_temp;
                                    end
                                end

                            elseif length(cm.sim_params.carrier_frequency)>1
                                ori_scp = cm.sim_params.scen_para;
                                cm.sim_params.use_3GPP_baseline = 0;
                                [ result, result_delay, lsps, ssps ] = cm.get_CIR_MF( ori_scp, 'MF', []);
                                H_CTF = mf.H2ctf_multiF(result, result_delay,B,freq_sample);
                                h_CIR = cell(size(H_CTF));
                                for i_user_tx = 1:size(H_CTF,1)
                                    for i_user_rx = 1:size(H_CTF,2)
                                        for i_snap = 1:size(H_CTF,3)
                                            for i_freq = 1:size(H_CTF,4)
                                                h_CIR{i_user_tx,i_user_rx,i_snap,i_freq} = ifft(H_CTF{i_user_tx,i_user_rx,i_snap,i_freq},[],3);
                                            end
                                        end
                                    end
                                end
                            else
                                ori_scp = cm.sim_params.scen_para;
                                SoS = cm.get_lsf_SOS(cm.decorr_dist);
                                [result, result_delay, lsps, ssps] = cm.get_CIR(ori_scp, 'others', [],SoS);
                                % [H, delay] = mf.result2H(result, result_delay);
                                [H, delay] = mf.result2H_multi_users(result, result_delay);
                                H_CTF = cell(size(result,1),size(result,2));
                                h_CIR = cell(size(result,1),size(result,2));
                                for i_user_tx = 1:size(result, 1)
                                    for i_user_rx = 1:size(result, 2)
                                        H_CTF_singleUser = mf.H2ctf(H{i_user_tx, i_user_rx}, freq_sample, B, delay{i_user_tx, i_user_rx}, [], d_waitbar); % H, freq_sample, B, delay,
                                        h_CIR_singleUser = ifft(H_CTF_singleUser, [], 4);
                                        H_CTF{i_user_tx, i_user_rx} = H_CTF_singleUser;
                                        h_CIR{i_user_tx, i_user_rx} = h_CIR_singleUser;
                                    end
                                end
                            end
                        case {'3GPP TR 38.901', '3GPP TR 38.811', '3GPP TR 37.885', '3GPP TR 36.777', 'IMT2020'}
                            ori_scp = cm.sim_params.scen_para;
                            [ result, result_delay, lsps, ssps_temp ] = cm.get_CIR_3GPP(tx_track, rx_track, ori_scp,0);

                            h_CIR = cell(size(result,1),size(result,2));
                            H_CTF = cell(size(result,1),size(result,2));
                            ssps = cell(size(result,1),size(result,2));
                            for i_user_tx = 1:size(result,1)
                                for i_user_rx = 1:size(result,2)
                                    H_CTF_temp = mf.H2ctf(result{i_user_tx,i_user_rx}, freq_sample, B, result_delay{i_user_tx,i_user_rx},[],d_waitbar); % H, freq_sample, B, delay,
                                    h_CIR_temp = ifft(H_CTF_temp, [], 4);
                                    h_CIR{i_user_tx, i_user_rx} = h_CIR_temp;
                                    H_CTF{i_user_tx, i_user_rx} = H_CTF_temp;
                                    ssps{i_user_tx,i_user_rx} = ssps_temp;
                                end
                            end
                    end

                    if strfind(scenario, app.sps.scenario_IIOT)
                        if contains(app.Model_Selection.Value, '6GPCS')
                            h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                            H_CTF_all.H_CTF_SMC(:,:,:,:,sim_idx) = H_CTF.H_CTF_SMC;
                            H_CTF_all.H_CTF_DMC(:,:,:,:,sim_idx) = H_CTF.H_CTF_DMC;

                            if ismember(app.BeamdomainresultsNode, app.Tree_save.CheckedNodes)
                                H_B_CTF_SMC = tools.get_bdcm_results(cm, H_CTF.H_CTF_SMC);
                                H_B_CTF_DMC = tools.get_bdcm_results(cm, H_CTF.H_CTF_DMC);
                                H_B_CTF_all.SMC(:,:,:,sim_idx) = H_B_CTF_SMC;
                                H_B_CTF_all.DMC(:,:,:,sim_idx) = H_B_CTF_DMC;
                            else
                                H_B_CTF_all = [];
                            end

                            ssps_all(:,:,:,:,sim_idx) = ssps;
                            H_CTF_BR_all = [];H_CTF_RU_all = [];h_CIR_BR_all = [];h_CIR_RU_all = [];
                            delay_all = [];
%                             power_all = [];
                            ssps_all = [];G_RIS_diag = [];%周勇杉添加，初始化为了保存参数
                        else
                            h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                            H_CTF_all(:,:,:,:,sim_idx) = H_CTF;
                            ssps_all(:,:,:,:,sim_idx) = ssps;

                            if ismember(app.BeamdomainresultsNode, app.Tree_save.CheckedNodes)
                                H_B_CTF = tools.get_bdcm_results(cm, H_CTF);
                                H_B_CTF_all(:,:,sim_idx) = H_B_CTF;

                            else
                                H_B_CTF_all = [];
                            end

                            H_CTF_BR_all = [];H_CTF_RU_all = [];h_CIR_BR_all = [];h_CIR_RU_all = [];
                            delay_all = [];
%                             power_all = [];
                            G_RIS_diag = [];%周勇杉添加，初始化为了保存参数
                        end
                    elseif strfind(scenario, app.sps.scenario_RIS)
                        h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                        H_CTF_BR_all(:,:,:,:,sim_idx) = H_CTF_BR;
                        H_CTF_RU_all(:,:,:,:,sim_idx) = H_CTF_RU;
                        h_CIR_BR_all(:,:,:,:,sim_idx) = h_CIR_BR;
                        h_CIR_RU_all(:,:,:,:,sim_idx) = h_CIR_RU;
                        delay_all(:,:,:,:,sim_idx) = delay_total;
                        H_CTF_all(:,:,:,:,sim_idx) = H_CTF;

                        if ismember(app.BeamdomainresultsNode, app.Tree_save.CheckedNodes)
                            H_B_CTF = tools.get_bdcm_results(cm, H_CTF);
                            H_B_CTF_all(:,:,:,sim_idx) = H_B_CTF;
                        else
                            H_B_CTF_all = [];
                        end

%                         power_all = [];
                        ssps_all = [];%周勇杉添加，初始化为了保存参数
                    elseif tools.is_owc_band(scenario, app)
                        h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                        H_B_CTF_all = [];
                        H_CTF_BR_all = [];H_CTF_RU_all = [];h_CIR_BR_all = [];h_CIR_RU_all = [];G_RIS_diag = [];
                        H_CTF_all = [];delay_all = [];
%                         power_all = [];
                        ssps_all = [];%周勇杉添加，初始化为了保存参数
                    elseif strfind(scenario, app.sps.scenario_SKYWAVE)
                        h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                        H_CTF_all(:,:,:,:,sim_idx) = H_CTF;
%                         power_all(:,:,:,:,sim_idx) = result;
                        delay_all(:,:,:,:,sim_idx) = result_delay;
                        ssps_all(:,:,:,:,sim_idx) = ssps;

                        H_CTF_BR_all = [];H_CTF_RU_all = [];h_CIR_BR_all = [];h_CIR_RU_all = [];G_RIS_diag = [];%周勇杉添加，初始化为了保存参数

                        if ismember(app.BeamdomainresultsNode, app.Tree_save.CheckedNodes)
                            H_B_CTF = tools.get_bdcm_results(cm, H_CTF);
                            H_B_CTF_all(:,:,sim_idx) = H_B_CTF;
                        else
                            H_B_CTF_all = [];
                        end

                        if length(cm.sim_params.carrier_frequency) <= 1
                            result_temp = zeros(size(delay,1), size(delay,2), size(delay,3), size(delay,4));
                            for i = 1:size(delay,3)
                                result_temp(:,:,i,:) = reshape(result{1,1,i}(:,:,1:size(delay,4)), size(delay,1), size(delay,2), []).^2;
                            end
                            result = {result_temp};
                        end

                    else
                        if ismember(app.BeamdomainresultsNode, app.Tree_save.CheckedNodes)
                            H_B_CTF = tools.get_bdcm_results(cm, H_CTF);
                            H_B_CTF_all(:,:,:,sim_idx) = H_B_CTF;
                        else
                            H_B_CTF_all = [];
                        end

                        h_CIR_all(:,:,:,:,sim_idx) = h_CIR;
                        H_CTF_all(:,:,:,:,sim_idx) = H_CTF;
                        delay_all(:,:,:,:,sim_idx) = result_delay;
                        ssps_all(:,:,:,:,sim_idx) = ssps;
                        
                        H_CTF_BR_all = [];H_CTF_RU_all = [];h_CIR_BR_all = [];h_CIR_RU_all = [];G_RIS_diag = [];%周勇杉添加，初始化为了保存参数
                    end

                    if d_waitbar.CancelRequested
                        close(d_waitbar);
                        return;
                    end
                    d_waitbar.Value = 1/num_simulations;
                end
                app.channelresults.results = h_CIR_all;%原：app.channelresults.results = h_CIR;
                app.channelresults.lsps = lsps;
                app.channelresults.ssps = ssps;
                app.channelresults.savePath = savePath;
                app.canAnalysis = 1; % 运行完成，可以分析

                d_waitbar.Value = 1;
                close(d_waitbar);

                mf.add_logs('CIRButtonPushed', '---------------End running--------------');
                %周勇杉添加，参数保存

                tools.saveparameters(app, savePath, scenario, lsps, tx_track, rx_track, h_CIR_all,h_CIR_BR_all,h_CIR_RU_all,H_CTF_BR_all, H_CTF_RU_all, H_CTF_all,delay_all, ssps_all,G_RIS_diag, H_B_CTF_all)

            catch exception
                %输出异常信息
                mf.add_logs('CIRGenerate', ['Error occurred in function: ', exception.stack(1).name]);
                mf.add_logs('CIRGenerate', ['Error message: ', exception.message]);

                %显示错误对话框
                errordlg(exception.message, 'Error', 'modal');
                return;
            end
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            app.saveSettingsFile('confs');
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [file, path] = uigetfile({'*.mat', 'a comma to separate files (*.mat)'}, 'select files to be read');
            if isequal(file,0)
                disp('Cancel loading');
            else
                fullFileName = fullfile(path, file);
                %载入.mat文件
                loadedSettings = load(fullFileName);
                allSettings = loadedSettings.allSettings;
                tools.settings2app(app, allSettings);
                tools.update_ModelselectionItems(app);
                show_check_allsettings(app, allSettings{1});
                msgbox(['The configuration files loaded successfully: ', fullfile(path,file)], "modal");
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            tools.check_display_freqTreeSelected(app);
            app.canAnalysis = 0;
        end

        % Selection changed function: Tree_2
        function Tree_2SelectionChanged(app, event)
            tools.check_display_scenarioTreeSelected(app);
            app.canAnalysis = 0;
        end

        % Callback function
        function ButtonPushed(app, event)
            tx_element_spacing = app.spacingTx.Value;
            tx_elevation_angle = app.beta_x_Tx.Value*pi/180;
            tx_azimuth_angle = app.beta_z_Tx.Value*pi/180;
            tx_no_elements = app.noAntennaTx.Value;

            deltap = (0: tx_no_elements - 1) * tx_element_spacing * app.sps.wavelength(1);
            element_position_tx(1,:) = cos(tx_elevation_angle) .* cos(tx_azimuth_angle+pi/2) .* deltap;
            element_position_tx(2,:) =  cos(tx_elevation_angle) .* sin(tx_azimuth_angle+pi/2) .* deltap;
            element_position_tx(3,:) = sin(tx_elevation_angle) .* deltap;
            element_position_tx(abs(element_position_tx)<1e-7) = 0;

            scatter3(app.UIAxes_Tx, element_position_tx(1,:), element_position_tx(2,:), element_position_tx(3,:));
        end

        % Callback function
        function Button_2Pushed(app, event)
            rx_element_spacing = app.spacingRx.Value;
            rx_elevation_angle = app.beta_x_Rx.Value*pi/180;
            rx_azimuth_angle = app.beta_z_Rx.Value*pi/180;
            rx_no_elements = app.noAntennaRx.Value;

            deltaq = (0: rx_no_elements - 1) * rx_element_spacing * app.sps.wavelength(1);
            element_position_rx(1,:) = cos(rx_elevation_angle) .* cos(rx_azimuth_angle+pi/2) .* deltaq;
            element_position_rx(2,:) =  cos(rx_elevation_angle) .* sin(rx_azimuth_angle+pi/2) .* deltaq;
            element_position_rx(3,:) = sin(rx_elevation_angle) .* deltaq;
            element_position_rx(abs(element_position_rx)<1e-7) = 0;

            scatter3(app.UIAxes_Rx, element_position_rx(1,:), element_position_rx(2,:), element_position_rx(3,:));
        end

        % Callback function
        function ALCheckBoxValueChanged(app, event)
            value = app.ALCheckBox.Value;
            if value
                app.ALButton.Enable = 'on';
            else
                app.ALButton.Enable = 'off';
            end
        end

        % Callback function
        function ALButtonValueChanged(app, event)
            AL_paras = inputdlg({'Temperature (℃)','Atmospheric pressure (kPa)','Water vapour density (g/m3)'},'Parameter settings related to AL',1);
            app.lsf_confs.T = str2double(AL_paras{1});
            app.lsf_confs.P = str2double(AL_paras{2});
            app.lsf_confs.W = str2double(AL_paras{3});
        end

        % Callback function
        function BLCheckBoxValueChanged(app, event)
            value = app.BLCheckBox.Value;
            if value
                app.BLButton.Enable = 'on';
            else
                app.BLButton.Enable = 'off';
            end
        end

        % Button pushed function: Button_Analysis
        function Button_AnalysisPushed(app, event)
            mf.add_logs('Button_AnalysisPushed', 'Open the channel analysis page');
            app.guiAnalysisApp = guiAnalysis(app);
            app.Button_Analysis.Enable = 'off';
        end

        % Button pushed function: choose_parameter
        function choose_parameterButtonPushed(app, event)
            global isAppOpened;  % 定义全局变量

            if isempty(isAppOpened)
                isAppOpened = false; % 初始化变量
            end

            if isAppOpened
                % 如果应用已经打开，则不再继续
                return;
            end

            isAppOpened = true;  % 设置全局变量，标记应用正在打开
            app.choose_parameter.Enable = "off"; % 禁用按钮
            try
                % 检查用户是否选择了频段和场景
                tools.check_freqAndScensChosen(app);
                % 设置场景名称，拼接而成
                if contains(app.Tree_2.SelectedNodes.Tag, {app.sps.scenario_MARITIME, app.sps.scenario_IIOT, app.sps.scenario_UHST})...
                        || tools.is_owc_band(app.Tree.SelectedNodes.Tag, app)
                    conf_scenarios = strcat(app.Tree.SelectedNodes.Tag,'_',app.Tree_2.SelectedNodes.Tag);
                    app.propagation_condition = '/'; % 此时无传播条件
                elseif strfind(app.Tree_2.SelectedNodes.Tag, app.sps.scenario_RIS)
                    conf_scenarios = strcat(app.Tree.SelectedNodes.Tag,'_',app.Tree_2.SelectedNodes.Tag,'_', app.ButtonGroup_RIS.SelectedObject.Text);
                    app.propagation_condition = app.ButtonGroup_RIS.SelectedObject.Text; % RIS的传播条件单独设置
                else
                    conf_scenarios = strcat(app.Tree.SelectedNodes.Tag,'_',app.Tree_2.SelectedNodes.Tag,'_', app.ButtonGroup.SelectedObject.Text);
                    app.propagation_condition = app.ButtonGroup.SelectedObject.Text; % 设置传播条件
                end
                conf_filename = strcat('scen_',conf_scenarios,'.conf');
                app.parameterApp = guiCmParamsShow(app, num2str(app.Tree.SelectedNodes.Tag), num2str(app.Tree_2.SelectedNodes.Tag), app.propagation_condition, conf_filename);
                addlistener(app.parameterApp, 'ObjectBeingDestroyed', @(src, evnt) onAppClosed(app));

                app.choose_parameter.Enable = "on"; % 重新启用按钮
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                app.choose_parameter.Enable = "on"; % 重新启用按钮
                isAppOpened = false;
                return;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 弹出确认关闭对话框
            choice = uiconfirm(app.UIFigure,'Confirm to close?', 'Confirm close', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2);
            if strcmp(choice, 'Confirm')
%                 stop(app.timer);
                delete(app.parameterApp);
                delete(app.guiAnalysisApp);
                delete(app.guiOptimizationApp);
                delete(app.guiAdviceApp);
                delete(app.track_Tx_straightApp);
                delete(app.track_Tx_circleApp);
                delete(app.track_Tx_randomApp);
                delete(app.chooseAntennaApp);
                delete(app.track_Tx_changeApp);
                delete(app);
            end
        end

        % Callback function
        function txTrackTypeValueChanged(app, event)
            if contains(app.txTrackType.Value, 'Static', 'IgnoreCase',true)
                app.txSpeed.Value = 0;
                app.txAcceleration.Value = 0;
            else
                app.txSpeed.Value = 5;
                app.txAcceleration.Value = 1;
            end
        end

        % Callback function
        function rxTrackTypeValueChanged(app, event)
            if contains(app.rxTrackType.Value, 'Static', 'IgnoreCase',true)
                app.rxSpeed.Value = 0;
                app.rxAcceleration.Value = 0;
            else
                app.rxSpeed.Value = 5;
                app.rxAcceleration.Value = 1;
            end
        end

        % Button pushed function: statisticalProps
        function statisticalPropsButtonPushed3(app, event)
            try
                mf.add_logs('statisticalPropsButtonPushed', 'Check whether the parameter settings in each frequency band and scenario are reasonable');
                tools.check_freqAndScensChosen(app);
                tools.setAppScenarios(app)

                [freqs, ~, ~] = mf.str2frequencys(app.EditField.Value,app.bandwidth.Value,app.freqSamples.Value);
                app.sps.carrier_frequency = freqs*1e9; % 因为下面会对频率的个数进行检查，只有sub-6 GHz支持多频率信道

                % 检查天线运动轨迹等是否已经设置
                tools.check_ant_traj_chosen(app, app.scenarios);
                % 检查用户在各个频段和场景中配置的参数是否合理

                tools.check_parasSetting(app);
                tools.update_ModelselectionItems(app);

                if ~contains(app.Model_Selection.Value, '6GPCS')
                    tools.check_settings_3GPP(app, app.Model_Selection.Value);
                end

                show_check_allsettings(app, app.scenarios);
                mf.add_logs('statisticalPropsButtonPushed', 'The check is complete and all parameter settings are displayed');
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Value changed function: switchSampleRate
        function switchSampleRateValueChanged(app, event)
            value = app.switchSampleRate.Value;
            if strcmp(value, 'Open')
                app.Lamp.Color = 'g';
            else
                app.Lamp.Color = 'r';
            end
        end

        % Button pushed function: Button_Optimization
        function Button_OptimizationPushed(app, event)
            try
                keywords = {'OW', 'IR', 'VL', 'UV', app.sps.scenario_RIS, app.sps.scenario_IIOT, app.sps.scenario_MARITIME, app.sps.scenario_SATELLITE, 'UWA', 'US'};  % 支持：ISAC，UHST

                if any(contains(app.scenarios, keywords)) || (length(str2double(app.EditField.Value))~=1) || (isnan(str2double(app.EditField.Value)))
                    msgbox('This function is not supported in the current frequency band/scenario');
                elseif ~contains(app.Model_Selection.Value, '6GPCS')
                    msgbox('This function is only supported in 6GPCS');

                else
                    app.guiOptimizationApp = guiOptimization(app);
                    app.Button_Optimization.Enable = 'off';
                end
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Button pushed function: advice
        function adviceButtonPushed(app, event)
            try
                app.guiAdviceApp = guiAdvice(app);
                app.advice.Enable = 'off';
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Callback function
        function Button_3Pushed(app, event)
            checktime(app);
        end

        % Button pushed function: Button_copy
        function Button_copyPushed(app, event)
            % 创建格式选择对话框
            selection = uiconfirm(app.UIFigure,...
                {'请选择引用格式 / Please select citation format:',...
                '1. 文本格式 (Text)',...
                '2. BibTeX 格式',...
                '3. RIS 格式'},...
                '选择格式 / Format Selection',...
                'Options',{'文本','BibTeX','RIS'},...
                'DefaultOption',1,...
                'CancelOption','文本');

            % 根据选择生成对应内容
            switch selection
                case 'BibTeX'
                    % BibTeX 格式内容
                    textToCopy = ...
                        "@ARTICLE{9786750," + newline + ...
                        "  author = {Wang, Cheng-Xiang and Lv, Zhen and Gao, Xiqi and You, Xiaohu and Hao, Yang and Haas, Harald}," + newline + ...
                        "  journal = {IEEE Transactions on Vehicular Technology}," + newline + ...
                        "  title = {Pervasive Wireless Channel Modeling Theory and Applications to 6G {GBSMs} for All Frequency Bands and All Scenarios}," + newline + ...
                        "  year = {2022}," + newline + ...
                        "  volume = {71}," + newline + ...
                        "  number = {9}," + newline + ...
                        "  pages = {9159--9173}," + newline + ...
                        "  doi = {10.1109/TVT.2022.3179695}" + newline + ...
                        "}" + newline + newline + ...
                        "@ARTICLE{10225614," + newline + ...
                        "  author = {Wang, Cheng-Xiang and Lv, Zhen and Chen, Yunfei and Haas, Harald}," + newline + ...
                        "  journal = {IEEE Transactions on Communications}," + newline + ...
                        "  title = {A Complete Study of Space-Time-Frequency Statistical Properties of the 6G Pervasive Channel Model}," + newline + ...
                        "  year = {2023}," + newline + ...
                        "  volume = {71}," + newline + ...
                        "  number = {12}," + newline + ...
                        "  pages = {7273--7287}," + newline + ...
                        "  doi = {10.1109/TCOMM.2023.3307144}" + newline + ...
                        "}";

                case 'RIS'
                    % RIS格式
                    textToCopy = ...
                        "TY  - JOUR" + newline + ...
                        "TI  - Pervasive Wireless Channel Modeling Theory and Applications to 6G GBSMs for All Frequency Bands and All Scenarios" + newline + ...
                        "T2  - IEEE Transactions on Vehicular Technology" + newline + ...
                        "SP  - 9159" + newline + ...
                        "EP  - 9173" + newline + ...
                        "AU  - C. -X. Wang" + newline + ...  % 作者格式修改
                        "AU  - Z. Lv" + newline + ...
                        "AU  - X. Gao" + newline + ...
                        "AU  - X. You" + newline + ...
                        "AU  - Y. Hao" + newline + ...
                        "AU  - H. Haas" + newline + ...
                        "PY  - 2022" + newline + ...
                        "DO  - 10.1109/TVT.2022.3179695" + newline + ...
                        "JO  - IEEE Transactions on Vehicular Technology" + newline + ...
                        "IS  - 9" + newline + ...
                        "SN  - 1939-9359" + newline + ...
                        "VO  - 71" + newline + ...  % 保留VO和VL字段
                        "VL  - 71" + newline + ...
                        "JA  - IEEE Transactions on Vehicular Technology" + newline + ...
                        "Y1  - Sept. 2022" + newline + ...
                        "ER  - " + newline + newline + ...  
                        "TY  - JOUR" + newline + ...
                        "TI  - A Complete Study of Space-Time-Frequency Statistical Properties of the 6G Pervasive Channel Model" + newline + ...
                        "T2  - IEEE Transactions on Communications" + newline + ...
                        "SP  - 7273" + newline + ...
                        "EP  - 7287" + newline + ...
                        "AU  - C. -X. Wang" + newline + ...
                        "AU  - Z. Lv" + newline + ...
                        "AU  - Y. Chen" + newline + ...
                        "AU  - H. Haas" + newline + ...
                        "PY  - 2023" + newline + ...
                        "DO  - 10.1109/TCOMM.2023.3307144" + newline + ...
                        "JO  - IEEE Transactions on Communications" + newline + ...
                        "IS  - 12" + newline + ...
                        "SN  - 1558-0857" + newline + ...
                        "VO  - 71" + newline + ...
                        "VL  - 71" + newline + ...
                        "JA  - IEEE Transactions on Communications" + newline + ...
                        "Y1  - Dec. 2023" + newline + ...
                        "ER  - ";

                otherwise % 文本格式
                    textToCopy = ...
                        "[1] C.-X. Wang, Z. Lv, X. Gao, X. You, Y. Hao, and H. Haas, " + ...
                        "“Pervasive wireless channel modeling theory and applications to 6G GBSMs for all frequency bands and all scenarios,” " + ...
                        "IEEE Trans. Veh. Technol., vol. 71, no. 9, pp. 9159-9173, Sept. 2022." + newline + ...
                        "[2] C.-X. Wang, Z. Lv, Y. Chen, and H. Haas, " + ...
                        "“A complete study of space-time-frequency statistical properties of the 6G pervasive channel model,” " + ...
                        "IEEE Trans. Commun., vol. 71, no. 12, pp. 7273-7287, Dec. 2023.";
            end

            % 复制到剪贴板
            clipboard('copy', textToCopy);

            % 显示双语成功提示
            message = sprintf([...
                '复制到剪切板成功!\n'...
                'Text copied to clipboard!\n\n'...
                '格式/Format: %s\n'...
                '字符数/Character count: %d'], selection, strlength(textToCopy));

            uialert(app.UIFigure, message, 'Success 成功', 'Icon', 'success');
        end

        % Callback function
        function Button_4Pushed(app, event)
            [file, path] = uigetfile('.txt');
            if isequal(file, 0)
                disp('Cancel loading');
            else
                app.EfieldTx.Value = fullfile(path, file);
                msgbox(['The XXX loaded successfully：', fullfile(path, file)], "modal");
            end
        end

        % Callback function
        function Button_5Pushed(app, event)
            [file, path] = uigetfile('.txt');
            if isequal(file, 0)
                disp('Cancel loading');
            else
                app.EfieldRx.Value = fullfile(path, file);
                msgbox(['The XXX loaded successfully：', fullfile(path, file)], "modal");
            end
        end

        % Button pushed function: add_antenna
        function add_antennaButtonPushed(app, event)
            app.add_antenna.Enable = 'off';
            antenna(app,0,[],'add');
        end

        % Button pushed function: delete_antenna
        function delete_antennaButtonPushed(app, event)
            if isempty(app.mouse_ind)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first.', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            %app.UITable_antenna.Data(app.mouse_ind,:)
            app.UITable_antenna.Data(app.mouse_ind,:) = [];
            app.UITable_antenna.Data(:,1) = mat2cell(num2str((1:1:size(app.UITable_antenna.Data,1)).'),ones(size(app.UITable_antenna.Data, 1), 1));
        end

        % Cell selection callback: UITable_antenna
        function UITable_antennaCellSelection(app, event)
            indices = event.Indices;
            app.mouse_ind = indices(:,1);  % 鼠标选择行
        end

        % Button pushed function: settingButton
        function settingButtonPushed(app, event)
            selected_option = app.DropDown_tx.Value; % 获取下拉框的当前选项
            app.settingButton.Enable="off";
            try
                txUser_num= size(app.UITable_tx.Data,1);
                if txUser_num < app.user_number
                    if contains(selected_option, 'point')||txUser_num==app.user_number-1
                        if txUser_num==app.user_number-1&&~contains(selected_option, 'point')
                            errordlg(sprintf('The number of %s must more than 2, please choose distribution of point', selected_option));
                            app.settingButton.Enable="on";
                            return;
                        end
                        tag_scen = app.Tree_2.SelectedNodes.Tag;
                        tag_freq = app.Tree.SelectedNodes.Tag;
                        scens =  {app.sps.scenario_SATELLITE, app.sps.scenario_UAV, app.sps.scenario_ISAC, app.sps.scenario_RIS, app.sps.scenario_MARITIME, app.sps.scenario_UHST, app.sps.scenario_V2V, app.sps.scenario_UWA};
                        if contains(tag_scen, scens) || contains(tag_freq, app.sps.freqband_IR) || contains(tag_freq, app.sps.freqband_VL)||contains(tag_freq, app.sps.freqband_UV)|| contains(app.sps.freqband_SHORTWAVE, tag_freq)
                            if isempty(app.UITable_tx.Data)
                                track_Tx(app);
                            else
                                errordlg(['Scenario ', tag_scen, ' or frequency band ', tag_freq, ' channels do not support multi-users.'], 'error: 400',"modal");
                                app.settingButton.Enable="on";
                                return;
                            end
                        else
                            track_Tx(app);
                        end
                    elseif contains(selected_option, 'straight')
                        app.track_Tx_straightApp = track_Tx_straight(app);

                    elseif contains(selected_option, 'circle')
                        app.track_Tx_circleApp = track_Tx_circle(app);

                    elseif contains(selected_option, 'random')
                        app.track_Tx_randomApp = track_Tx_random(app);
                    end
                else
                    app.settingButton.Enable="on";
                    errordlg(sprintf('The number of Tx users must be less than or equal to %d', app.user_number));
                end
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Button pushed function: settingButton_2
        function settingButton_2Pushed2(app, event)
            selected_option = app.DropDown_rx.Value; % 获取下拉框的当前选项
            app.settingButton_2.Enable="off";
            try
                rxUser_num= size(app.UITable_rx.Data,1);
                if rxUser_num < app.user_number
                    if contains(selected_option, 'point')||rxUser_num==app.user_number-1
                        if rxUser_num==app.user_number-1&&~contains(selected_option, 'point')
                            errordlg(sprintf('The number of %s must more than 2, please choose distribution of point', selected_option));
                            app.settingButton_2.Enable="on";
                            return;
                        end
                        tag_scen = app.Tree_2.SelectedNodes.Tag;
                        tag_freq = app.Tree.SelectedNodes.Tag;
                        scens =  {app.sps.scenario_SATELLITE, app.sps.scenario_ISAC, app.sps.scenario_RIS, app.sps.scenario_MARITIME, app.sps.scenario_UHST, app.sps.scenario_V2V, app.sps.scenario_UWA};
                        if contains(tag_scen, scens) || contains(tag_freq, app.sps.freqband_IR) || contains(tag_freq, app.sps.freqband_VL)||contains(tag_freq, app.sps.freqband_UV)|| contains(app.sps.freqband_SHORTWAVE, tag_freq)
                            if isempty(app.UITable_rx.Data)
                                track_Rx(app);
                            else
                                errordlg(['Scenario ', tag_scen, ' or frequency band ', tag_freq, ' channels do not support multi-users.'], 'error: 400',"modal");
                                app.settingButton_2.Enable="on";
                                return;
                            end
                        else
                            track_Rx(app);
                        end
                    elseif contains(selected_option, 'straight')
                        app.track_Rx_straightApp = track_Rx_straight(app);
                    elseif contains(selected_option, 'circle')
                        app.track_Rx_circleApp = track_Rx_circle(app);
                    elseif contains(selected_option, 'random')
                        app.track_Rx_randomApp = track_Rx_random(app);
                    end
                else
                    app.settingButton_2.Enable="on";
                    errordlg(sprintf('The number of Rx users must be less than or equal to %d', app.user_number));
                end
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Menu selected function: ConfiguremodifyantennaarrayidMenu
        function ConfiguremodifyantennaarrayidMenuSelected(app, event)
            if isempty(app.selection_tx)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first.', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            if isempty(app.UITable_antenna.Data)
                tools.setAppScenarios(app)
                if tools.is_owc_band(app.scenarios, app)
                    msgbox('Optical wireless channels do not need antenna array', 'Info');
                    return;
                else
                    uialert(app.UIFigure, 'Please create antenna array first.', 'Error');
                    return;
                end
            end
            antenna_list = table2array(app.UITable_antenna.Data);
            app.chooseAntennaApp = chooseAntenna_tx(app, antenna_list(:,1));
        end

        % Menu selected function: ConfiguremodifymotiontrajectoryMenu_2
        function ConfiguremodifymotiontrajectoryMenu_2Selected(app, event)
            if isempty(app.selection_tx)
                uialert(app.UIFigure, 'Please select the cells first', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            app.track_Tx_changeApp = track_Tx_change(app);
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            if isempty(app.selection_tx)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            if ~isempty(app.selection_tx)
                % 获取选中的所有行索引
                selectedRows = unique(app.selection_tx(:,1)); % 仅行索引，去除重复

                % 获取选中行的编号列数据
                selectedIds = app.UITable_tx.Data{selectedRows, 1}; %

                % 将表格列数据转换为字符串
                selectedIdsStr = strjoin(cellstr(selectedIds), ', ');

                % 构建确认消息，包含所有选中行的编号
                message = ['Confirm to delete the user(s) with number ' selectedIdsStr '？'];

                % 弹出确认对话框
                choice = uiconfirm(app.UIFigure, message, 'Confirm', ...
                    'Options', {'Confirm', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);

                % 检查用户的选择
                if strcmp(choice, 'Confirm')
                    % 按照从大到小的顺序删除行
                    for i = length(selectedRows):-1:1
                        rowIndex = selectedRows(i);
                        if rowIndex <= size(app.UITable_tx.Data, 1)
                            app.UITable_tx.Data(rowIndex, :) = [];
                        end
                    end
                    % 更新行号
                    for i = 1:height(app.UITable_tx.Data)
                        app.UITable_tx.Data{i, 'team1'} = {num2str(i)};
                    end
                end
            end
        end

        % Menu selected function: ConfiguremodifyantennaarrayidMenu_2
        function ConfiguremodifyantennaarrayidMenu_2Selected(app, event)
            if isempty(app.selection_rx)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            if isempty(app.UITable_antenna.Data)
                tools.setAppScenarios(app)
                if tools.is_owc_band(app.scenarios, app)
                    msgbox('Optical wireless channels do not need antenna array', 'Info');
                    return;
                else
                    uialert(app.UIFigure, 'Please create antenna array first.', 'Error');
                    return;
                end
            end
            antenna_list = table2array(app.UITable_antenna.Data);
            app.chooseAntennaApp = chooseAntenna_rx(app,antenna_list(:,1));
        end

        % Menu selected function: ConfiguremodifymotiontrajectoryMenu
        function ConfiguremodifymotiontrajectoryMenuSelected(app, event)
            if isempty(app.selection_rx)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            app.track_Rx_changeApp = track_Rx_change(app);
        end

        % Menu selected function: DeleteMenu_2
        function DeleteMenu_2Selected(app, event)
            if isempty(app.selection_rx)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            if ~isempty(app.selection_rx)
                % 获取选中的所有行索引
                selectedRows = unique(app.selection_rx(:,1)); % 仅行索引，去除重复

                % 获取选中行的编号列数据
                selectedIds = app.UITable_rx.Data{selectedRows, 1}; %

                % 将表格列数据转换为字符串
                selectedIdsStr = strjoin(cellstr(selectedIds), ', ');

                % 构建确认消息，包含所有选中行的编号
                message = ['Confirm to delete the user(s) with number ' selectedIdsStr '？'];

                % 弹出确认对话框
                choice = uiconfirm(app.UIFigure, message, 'Confirm', ...
                    'Options', {'Confirm', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);

                % 检查用户的选择
                if strcmp(choice, 'Confirm')
                    % 按照从大到小的顺序删除行
                    for i = length(selectedRows):-1:1
                        rowIndex = selectedRows(i);
                        if rowIndex <= size(app.UITable_rx.Data, 1)
                            app.UITable_rx.Data(rowIndex, :) = [];
                        end
                    end
                    % 更新行号
                    for i = 1:height(app.UITable_rx.Data)
                        app.UITable_rx.Data{i, 'team1'} = {num2str(i)};
                    end
                end
            end
        end

        % Cell selection callback: UITable_tx
        function UITable_txCellSelection(app, event)
            app.selection_tx = app.UITable_tx.Selection;
        end

        % Callback function
        function UITable_rxSelectionChanged(app, event)
            app.selection_rx = app.UITable_rx.Selection;
        end

        % Cell selection callback: UITable_rx
        function UITable_rxCellSelection(app, event)
            app.selection_rx = app.UITable_rx.Selection;
        end

        % Button pushed function: Tx_RxPosVisualize
        function Tx_RxPosVisualizeButtonPushed(app, event)
            txnumRows = size(app.UITable_tx.Selection,1); %发送端用户数
            rxnumRows = size(app.UITable_rx.Selection,1); %接受端用户数

            move_time = app.trackLength.Value;
            samp_rate = app.sampleRate.Value;
            mf.add_logs('TxPosVisualizeButtonPushed', 'Preview the motion trajectories of the Tx and Rx');

            %发端多用户轨迹
            for i = 1: txnumRows
                txuser_id = app.UITable_tx.Selection(i,1);
                selected_tx_data = app.UITable_tx.Data(txuser_id,:);
                initialPosTx = str2num(selected_tx_data.team3{1});
                trackOriTx = str2num(selected_tx_data.team8{1});
                txSpeed = str2num(selected_tx_data.team6{1});
                txAcceleration = str2num(selected_tx_data.team7{1});
                txTrackType = selected_tx_data.team5{1};
                if strfind(txTrackType, 'satellite')
                    tx_track(i) = tools.get_track_satellite(app, move_time, samp_rate, initialPosTx);
                else
                    tx_track(i) = track(txTrackType, move_time, txSpeed, txAcceleration, initialPosTx, trackOriTx, samp_rate);
                end
            end
            %收端多用户轨迹
            for j = 1:rxnumRows
                rxuser_id = app.UITable_rx.Selection(j,1);
                selected_rx_data = app.UITable_rx.Data(rxuser_id,:);
                initialPosRx = str2num(selected_rx_data.team3{1});
                trackOriRx = str2num(selected_rx_data.team8{1});
                rxSpeed = str2num(selected_rx_data.team6{1});
                rxAcceleration = str2num(selected_rx_data.team7{1});
                rxTrackType = selected_rx_data.team5{1};
                rx_track(j) = track(rxTrackType, app.trackLength.Value, rxSpeed, rxAcceleration, initialPosRx, trackOriRx, app.sampleRate.Value);
            end

            if ~isempty(app.UITable_tx.Selection)
                empty_tracktypeTx = strcmp(app.UITable_tx.Data{app.UITable_tx.Selection(:,1),5},{''});
            end

            if ~isempty(app.UITable_rx.Selection)
                empty_tracktypeRx = strcmp(app.UITable_rx.Data{app.UITable_rx.Selection(:,1),5},{''});
            end
            switch app.ButtonGroup_preview.SelectedObject.Text
                case 'Both'
                    if isempty(app.UITable_rx.Selection) || isempty(app.UITable_tx.Selection)
                        % 弹出提示框
                        uialert(app.UIFigure, sprintf('请先选择发送端和接收端用户\nPlease choose TxUser and RxUser first'), 'Error');
                        return; % 直接返回，不执行后面的代码
                    elseif any(empty_tracktypeTx)%存在发射端用户运动轨迹为空
                        uialert(app.UIFigure, sprintf('请为发送端用户配置轨迹类型\nPlease configure track type for TxUser'), 'Error');
                        return;
                    elseif any(empty_tracktypeRx)
                        uialert(app.UIFigure, sprintf('请为接收端用户配置轨迹类型\nPlease configure track type for RxUser'), 'Error');
                        return;
                    else
                        legend_handles = [];
                        for i=1:txnumRows%发端多用户预览
                            if strfind(tx_track(i).name, 'satellite')
                                app.h_satellite.visualize_earth(tx_track(i).time_scale');
                            end
                        end
                        figure()
                        for i=1:txnumRows%发端多用户预览
                            scatter3(tx_track(i).positions(:,1,:), tx_track(i).positions(:,2,:), tx_track(i).positions(:,3,:), 'MarkerEdgeColor','b', 'MarkerFaceColor','b');
                            if i== txnumRows
                                legend_handles = [legend_handles, {'Transmitter (Tx)'}];
                            else
                                legend_handles = [legend_handles, {''}];
                            end
                            hold on;
                        end

                        for i=1:rxnumRows%收端多用户预览
                            scatter3(rx_track(i).positions(:,1,:), rx_track(i).positions(:,2,:), rx_track(i).positions(:,3,:), 'MarkerEdgeColor','r', 'MarkerFaceColor','r');
                            if i== rxnumRows
                                legend_handles = [legend_handles, {'Receiver (Rx)'}];
                            else
                                legend_handles = [legend_handles, {''}];
                            end

                            hold on;
                        end
                        xlabel('x-axis (m)')
                        ylabel('y-axis (m)')
                        zlabel('z-axis (m)')
                        legend(legend_handles);
                        title('\fontsize{16}The trajectory of the Tx and Rx (m)');
                    end

                case 'Tx'
                    if isempty(app.UITable_tx.Selection)
                        % 弹出提示框
                        uialert(app.UIFigure, sprintf('请先选择发送端用户\nPlease choose TxUser first'), 'Error');
                        return; % 直接返回，不执行后面的代码
                    elseif any(empty_tracktypeTx)%存在发射端用户运动轨迹为空
                        uialert(app.UIFigure, sprintf('请为发送端用户配置轨迹类型\nPlease configure track type for TxUser'), 'Error');
                        return;
                    else
                        for i=1:txnumRows%卫星用户预览
                            if strfind(tx_track(i).name, 'satellite')
                                app.h_satellite.visualize_earth(tx_track(i).time_scale');
                            end
                        end
                        figure()
                        for i=1:txnumRows%发端多用户预览
                            scatter3(tx_track(i).positions(:,1,:), tx_track(i).positions(:,2,:), tx_track(i).positions(:,3,:), 'MarkerEdgeColor','b', 'MarkerFaceColor','b');
                            hold on;
                        end
                        title('\fontsize{16}The motion trajectory diagram of the Tx (m)');
                        xlabel('x-axis (m)')
                        ylabel('y-axis (m)')
                        zlabel('z-axis (m)')
                        legend({'Transmitter (Tx)'})
                    end

                case 'Rx'
                    if isempty(app.UITable_rx.Selection)
                        % 弹出提示框
                        uialert(app.UIFigure, sprintf('请先选择接收端用户\nPlease choose RxUser first'), 'Error');
                        return; % 直接返回，不执行后面的代码
                    elseif any(empty_tracktypeRx)
                        uialert(app.UIFigure, sprintf('请为接收端用户配置轨迹类型\nPlease configure track type for RxUser'), 'Error');
                    else
                        figure()
                        for i=1:rxnumRows%收端多用户预览
                            scatter3(rx_track(i).positions(:,1,:), rx_track(i).positions(:,2,:), rx_track(i).positions(:,3,:), 'MarkerEdgeColor','r', 'MarkerFaceColor','r');
                            hold on;
                        end
                        title('\fontsize{16}The motion trajectory diagram of the Rx (m)');
                        xlabel('x-axis (m)')
                        ylabel('y-axis (m)')
                        zlabel('z-axis (m)')
                        legend({'Receiver (Rx)'})
                    end
            end
            mf.add_logs('TxPosVisualizeButtonPushed', 'The Tx and Rx trajectory preview is complete');
        end

        % Drop down opening function: DropDown_tx
        function DropDown_txOpening(app, event)
            tools.check_freqAndScensChosen(app);
            tag_scen = app.Tree_2.SelectedNodes.Tag;
            tag_freq = app.Tree.SelectedNodes.Tag;
            scens =  {app.sps.scenario_SATELLITE, app.sps.scenario_UAV, app.sps.scenario_ISAC, app.sps.scenario_RIS, app.sps.scenario_MARITIME, app.sps.scenario_UHST, app.sps.scenario_UWA};
            freqs = {app.sps.freqband_IR, app.sps.freqband_VL, app.sps.freqband_UV, app.sps.freqband_SHORTWAVE};
            if contains(tag_scen, scens) || contains(tag_freq, freqs)
                app.DropDown_tx.Items = {'点(point)'};
                app.DropDown_rx.Items = {'点(point)'};
            else
                app.DropDown_tx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
                app.DropDown_rx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
            end
        end

        % Drop down opening function: DropDown_rx
        function DropDown_rxOpening(app, event)
            tools.check_freqAndScensChosen(app);
            tag_scen = app.Tree_2.SelectedNodes.Tag;
            tag_freq = app.Tree.SelectedNodes.Tag;
            scens =  {app.sps.scenario_SATELLITE, app.sps.scenario_ISAC, app.sps.scenario_RIS, app.sps.scenario_MARITIME, app.sps.scenario_UHST, app.sps.scenario_UWA};
            freqs = {app.sps.freqband_IR, app.sps.freqband_VL, app.sps.freqband_UV, app.sps.freqband_SHORTWAVE};
            if contains(tag_scen, scens) || contains(tag_freq, freqs)
                app.DropDown_tx.Items = {'点(point)'};
                app.DropDown_rx.Items = {'点(point)'};
            else
                app.DropDown_tx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
                app.DropDown_rx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
            end
        end

        % Drop down opening function: Model_Selection
        function Model_SelectionDropDownOpening(app, event)
            tools.update_ModelselectionItems(app);
        end

        % Button pushed function: edit_antenna
        function edit_antennaButtonPushed(app, event)
            if isempty(app.mouse_ind)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first.', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            antennadata=app.UITable_antenna.Data(app.mouse_ind,:);%记录选中天线数据
            % app.UITable_antenna.Data(app.mouse_ind,:) = [];
            antenna(app,app.mouse_ind,antennadata ,'edit');
        end

        % Callback function
        function UITable_txSelectionChanged(app, event)
            selection = app.UITable_tx.Selection;

        end

        % Display data changed function: UITable_tx
        function UITable_txDisplayDataChanged(app, event)
            newDisplayData = app.UITable_tx.DisplayData;

        end

        % Value changed function: txDepth
        function txDepthValueChanged(app, event)
            value = app.txDepth.Value;
            if value > app.height_water_uwa.Value
                % 弹出提示框
                uialert(app.UIFigure, sprintf('The depth of TX must not exceed the water depth'), 'Error');
                app.txDepth.Value = app.height_water_uwa.Value;
                return; % 直接返回，不执行后面的代码
            end
        end

        % Value changed function: rxDepth
        function rxDepthValueChanged(app, event)
            value = app.rxDepth.Value;
            if value > app.height_water_uwa.Value
                % 弹出提示框
                uialert(app.UIFigure, sprintf('The depth of RX must not exceed the water depth'), 'Error');
                app.rxDepth.Value = app.height_water_uwa.Value;
                return; % 直接返回，不执行后面的代码
            end
        end

        % Menu selected function: EditselectedantennaarrayMenu
        function EditselectedantennaarrayMenuSelected(app, event)
            if isempty(app.mouse_ind)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first.', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            antennadata=app.UITable_antenna.Data(app.mouse_ind,:);%记录选中天线数据
            % app.UITable_antenna.Data(app.mouse_ind,:) = [];
            antenna(app,app.mouse_ind,antennadata ,'edit');
        end

        % Menu selected function: DeleteselectedantennaarrayMenu
        function DeleteselectedantennaarrayMenuSelected(app, event)
            if isempty(app.mouse_ind)
                % 弹出提示框
                uialert(app.UIFigure, 'Please select the cells first.', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            %app.UITable_antenna.Data(app.mouse_ind,:)
            app.UITable_antenna.Data(app.mouse_ind,:) = [];
            app.UITable_antenna.Data(:,1) = mat2cell(num2str((1:1:size(app.UITable_antenna.Data,1)).'),ones(size(app.UITable_antenna.Data, 1), 1));
        end

        % Value changed function: height_water_uwa
        function height_water_uwaValueChanged(app, event)
            if app.txDepth.Value > app.height_water_uwa.Value
                % 弹出提示框
                uialert(app.UIFigure, sprintf('The depth of TX must not exceed the water depth'), 'Error');
                app.txDepth.Value = app.height_water_uwa.Value;
                return; % 直接返回，不执行后面的代码
            elseif app.rxDepth.Value > app.height_water_uwa.Value
                % 弹出提示框
                uialert(app.UIFigure, sprintf('The depth of RX must not exceed the water depth'), 'Error');
                app.rxDepth.Value = app.height_water_uwa.Value;
            end
        end

        % Value changed function: SensingmodelDropDown
        function SensingmodelDropDownValueChanged(app, event)
            if contains(app.SensingmodelDropDown.Value,'单站感知')
                app.LoSNLoSButton.Visible = "off";
                app.NLoSLoSButton.Visible = "off";
                if contains (app.ButtonGroup_2.SelectedObject.Text, {'LOS-NLOS','NLoS-LOS'})
                    app.LoSLoSButton.Value = 1;
                end
                if app.LoSLoSButton.Value == 1
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC6.png';
                else
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC5.png';
                end
            elseif contains(app.SensingmodelDropDown.Value,'双站感知')
                app.LoSNLoSButton.Visible = "on";
                app.NLoSLoSButton.Visible = "on";
                if app.LoSLoSButton.Value == 1
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC1.png';
                elseif  app.NLoSLoSButton.Value == 1
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC2.png';
                elseif  app.LoSNLoSButton.Value == 1
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC3.png';
                else
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC4.png';
                end
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'LoS-LoS'
                    if contains(app.SensingmodelDropDown.Value,'单站感知')
                        app.Image16.ImageSource = 'iconsAndFigures/ISAC6.png';
                    else 
                        app.Image16.ImageSource = 'iconsAndFigures/ISAC1.png';
                    end
                case 'NLoS-NLoS'
                    if contains(app.SensingmodelDropDown.Value,'单站感知')
                        app.Image16.ImageSource = 'iconsAndFigures/ISAC5.png';
                    else 
                        app.Image16.ImageSource = 'iconsAndFigures/ISAC4.png';
                    end
                case 'LoS-NLoS'
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC3.png';
                case 'NLoS-LoS'
                    app.Image16.ImageSource = 'iconsAndFigures/ISAC2.png';
            end
        end

        % Value changed function: TargettypeDropDown
        function TargettypeDropDownValueChanged(app, event)
            value = app.TargettypeDropDown.Value;
            switch value
                case '汽车'
                    app.noCluster_10.Value = 10;
                case 'AGV'
                    app.noCluster_10.Value = 10;
                case '人体'
                    app.noCluster_10.Value = 6;
                case '无人机'
                    app.noCluster_10.Value = 4;
            end

        end

        % Value changed function: txInitialPos
        function txInitialPosValueChanged(app, event)
            tar_initialPos = [app.txInitialPos.Value,app.txInitialPos_2.Value,app.txInitialPos_3.Value];
            [initialPosTx, initialPosRx] = tools.get_initialPos_table(app);
            if isequal(tar_initialPos,initialPosRx) || isequal(tar_initialPos, initialPosTx)
                uialert(app.UIFigure, 'The initial position of the target cannot be the same as that of the receiver or transmitter', 'Error');
                return; % 直接返回，不执行后面的代码
            end
        end

        % Value changed function: txInitialPos_2
        function txInitialPos_2ValueChanged(app, event)
            tar_initialPos = [app.txInitialPos.Value,app.txInitialPos_2.Value,app.txInitialPos_3.Value];
            [initialPosTx, initialPosRx] = tools.get_initialPos_table(app);
            if isequal(tar_initialPos,initialPosRx) || isequal(tar_initialPos, initialPosTx)
                uialert(app.UIFigure, 'The initial position of the target cannot be the same as that of the receiver or transmitter', 'Error');
                return; % 直接返回，不执行后面的代码
            end
            
        end

        % Value changed function: txInitialPos_3
        function txInitialPos_3ValueChanged(app, event)
            tar_initialPos = [app.txInitialPos.Value,app.txInitialPos_2.Value,app.txInitialPos_3.Value];
            [initialPosTx, initialPosRx] = tools.get_initialPos_table(app);
            if isequal(tar_initialPos,initialPosRx) || isequal(tar_initialPos, initialPosTx)
                uialert(app.UIFigure, 'The initial position of the target cannot be the same as that of the receiver or transmitter', 'Error');
                return; % 直接返回，不执行后面的代码
            end
        end

        % Value changed function: txTrackType
        function txTrackTypeValueChanged2(app, event)
            value = app.txTrackType.Value;
            if contains('circle',value)
                app.txSpeed.Value = 5;
            end
            if contains('static',value)
                app.txSpeed.Value = 0;
                app.txSpeed.Editable = "off";
            else
                app.txSpeed.Editable = "on";
            end
        end

        % Value changed function: txSpeed
        function txSpeedValueChanged(app, event)
            value = app.txSpeed.Value;
            if contains('circle',app.txTrackType.Value)
                if value == 0
                     uialert(app.UIFigure, 'The speed of the target cannot be 0 when the target track type is circle, please reset', 'Error');
                     app.txSpeed.Value = 5;
                return; % 直接返回，不执行后面的代码
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '工程 (Project)';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '加载配置 (Load configuration) ';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存配置 (Save configuration)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '系统设置(System settings)';
            app.Tree = uitree(app.Tab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'1) The shortwave model only supports outdoor scenarios in terrestrial mobile communications.'; '2) THz and OWC models only support indoor scenario in terrestrial mobile communications.'};
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '设置频段(Frequency band setting)';
            app.acousticNode = uitreenode(app.Node);
            app.acousticNode.Tag = 'Acoustic';
            app.acousticNode.Text = '水声(acoustic)';
            app.ShortWaveNode = uitreenode(app.Node);
            app.ShortWaveNode.Tag = 'ShortWave';
            app.ShortWaveNode.Text = '短波(ShortWave)';
            app.VHFNode = uitreenode(app.Node);
            app.VHFNode.Tag = 'VHF';
            app.VHFNode.Text = '超短波(VHF)';
            app.sub6GHzNode = uitreenode(app.Node);
            app.sub6GHzNode.Tag = 'sub-6 GHz';
            app.sub6GHzNode.Text = '6 GHz 以下 (sub-6 GHz)';
            app.cmWaveNode = uitreenode(app.Node);
            app.cmWaveNode.Tag = 'cmWave';
            app.cmWaveNode.Text = '厘米波 (cmWave)';
            app.mmWaveNode = uitreenode(app.Node);
            app.mmWaveNode.Tag = 'mmWave';
            app.mmWaveNode.Text = '毫米波 (mmWave)';
            app.THzNode = uitreenode(app.Node);
            app.THzNode.Tag = 'THz';
            app.THzNode.Text = '太赫兹 (THz)';
            app.OWNode = uitreenode(app.Node);
            app.OWNode.Text = '光无线(OW)';
            app.IRNode = uitreenode(app.OWNode);
            app.IRNode.Tag = 'IR';
            app.IRNode.Text = '红外 (IR)';
            app.VLNode = uitreenode(app.OWNode);
            app.VLNode.Tag = 'VL';
            app.VLNode.Text = '可见光 (VL)';
            app.UVNode = uitreenode(app.OWNode);
            app.UVNode.Tag = 'UV';
            app.UVNode.Text = '紫外 (UV)';
            app.Tree_2 = uitree(app.Tab);
            app.Tree_2.SelectionChangedFcn = createCallbackFcn(app, @Tree_2SelectionChanged, true);
            app.Tree_2.Tooltip = {''};
            app.Node_2 = uitreenode(app.Tree_2);
            app.Node_2.Text = '设置场景(Scenarios setting)';
            app.SatelliteNode = uitreenode(app.Node_2);
            app.SatelliteNode.Text = '卫星(Satellite)';
            app.SatelliteRuralNode = uitreenode(app.SatelliteNode);
            app.SatelliteRuralNode.Tag = 'Satellite-Rural';
            app.SatelliteRuralNode.Text = 'Satellite-Rural（乡村）';
            app.SatelliteUrbanNode = uitreenode(app.SatelliteNode);
            app.SatelliteUrbanNode.Tag = 'Satellite-Urban';
            app.SatelliteUrbanNode.Text = 'Satellite-Urban（城市）';
            app.SatelliteSuburbanNode = uitreenode(app.SatelliteNode);
            app.SatelliteSuburbanNode.Tag = 'Satellite-Suburban';
            app.SatelliteSuburbanNode.Text = 'Satellite-Suburban（郊区）';
            app.SatelliteDenseUrbanNode = uitreenode(app.SatelliteNode);
            app.SatelliteDenseUrbanNode.Tag = 'Satellite-DenseUrban';
            app.SatelliteDenseUrbanNode.Text = 'Satellite-DenseUrban（密集城市）';
            app.UAVNode = uitreenode(app.Node_2);
            app.UAVNode.Text = '无人机 (UAV)';
            app.UAVUMiNode = uitreenode(app.UAVNode);
            app.UAVUMiNode.Tag = 'UAV-UMi';
            app.UAVUMiNode.Text = 'UAV-UMi(城市微小区)';
            app.UAVUMaNode = uitreenode(app.UAVNode);
            app.UAVUMaNode.Tag = 'UAV-UMa';
            app.UAVUMaNode.Text = 'UAV-UMa(城市宏小区)';
            app.UAVRMaNode = uitreenode(app.UAVNode);
            app.UAVRMaNode.Tag = 'UAV-RMa';
            app.UAVRMaNode.Text = 'UAV-RMa(郊区)';
            app.TerrestrialcommuNode = uitreenode(app.Node_2);
            app.TerrestrialcommuNode.Text = '地面通信 (Terrestrial commu.)';
            app.UMiNode = uitreenode(app.TerrestrialcommuNode);
            app.UMiNode.NodeData = '城市微小区场景';
            app.UMiNode.Tag = 'UMi';
            app.UMiNode.Text = 'UMi(城市微小区)';
            app.UMaNode = uitreenode(app.TerrestrialcommuNode);
            app.UMaNode.Tag = 'UMa';
            app.UMaNode.Text = 'UMa(城市宏小区)';
            app.RMaNode = uitreenode(app.TerrestrialcommuNode);
            app.RMaNode.Tag = 'RMa';
            app.RMaNode.Text = 'RMa(郊区)';
            app.RMiNode = uitreenode(app.TerrestrialcommuNode);
            app.RMiNode.NodeData = '郊区微小区场景';
            app.RMiNode.Tag = 'RMi';
            app.RMiNode.Text = 'RMi(郊区微小区)';
            app.OfficeOutdoorNode = uitreenode(app.TerrestrialcommuNode);
            app.OfficeOutdoorNode.Tag = 'OfficeOutdoor';
            app.OfficeOutdoorNode.Text = 'OfficeOutdoor(办公楼室外)';
            app.InterOfficeNode = uitreenode(app.TerrestrialcommuNode);
            app.InterOfficeNode.Tag = 'InterOffice';
            app.InterOfficeNode.Text = 'InterOffice(办公楼宇间)';
            app.IndoorNode = uitreenode(app.TerrestrialcommuNode);
            app.IndoorNode.Tag = 'Indoor';
            app.IndoorNode.Text = 'Indoor(室内)';
            app.IndoorCorridorNode = uitreenode(app.TerrestrialcommuNode);
            app.IndoorCorridorNode.Tag = 'Indoor-Corridor';
            app.IndoorCorridorNode.Text = 'Indoor-Corridor(室内-走廊)';
            app.ExpresswaymainroadNode = uitreenode(app.TerrestrialcommuNode);
            app.ExpresswaymainroadNode.Tag = 'Expressway-main road';
            app.ExpresswaymainroadNode.Text = 'Expressway-main road (公路-主干道)';
            app.ExpresswaysideroadNode = uitreenode(app.TerrestrialcommuNode);
            app.ExpresswaysideroadNode.Tag = 'Expressway-side road';
            app.ExpresswaysideroadNode.Text = 'Expressway-side road (公路-支路)';
            app.SkyWaveNode = uitreenode(app.TerrestrialcommuNode);
            app.SkyWaveNode.Tag = 'SkyWave';
            app.SkyWaveNode.Text = '天波(SkyWave)';
            app.GroundWaveNode = uitreenode(app.TerrestrialcommuNode);
            app.GroundWaveNode.Tag = 'GroundWave';
            app.GroundWaveNode.Text = '地波(GroundWave)';
            app.MaritimeNode = uitreenode(app.Node_2);
            app.MaritimeNode.Text = '海洋 (Maritime)';
            app.MaritimeShiptoshipNode = uitreenode(app.MaritimeNode);
            app.MaritimeShiptoshipNode.Tag = 'Maritime-Ship to ship';
            app.MaritimeShiptoshipNode.Text = 'Maritime-Ship to ship（船对船）';
            app.MaritimeShiptolandNode = uitreenode(app.MaritimeNode);
            app.MaritimeShiptolandNode.Tag = 'Maritime-Ship to land';
            app.MaritimeShiptolandNode.Text = 'Maritime-Ship to land（船对岸）';
            app.V2VNode = uitreenode(app.Node_2);
            app.V2VNode.Text = '车对车通信 (V2V)';
            app.V2VHighwayNode = uitreenode(app.V2VNode);
            app.V2VHighwayNode.Tag = 'V2V-Highway';
            app.V2VHighwayNode.Text = 'V2V-Highway(高速公路)';
            app.V2VUrbanNode = uitreenode(app.V2VNode);
            app.V2VUrbanNode.Tag = 'V2V-Urban';
            app.V2VUrbanNode.Text = 'V2V-Urban(城市)';
            app.RISNode = uitreenode(app.Node_2);
            app.RISNode.Text = '智能反射面 (RIS)';
            app.RISIndoorNode = uitreenode(app.RISNode);
            app.RISIndoorNode.Tag = 'RIS-Indoor';
            app.RISIndoorNode.Text = 'RIS-Indoor(室内)';
            app.RISUMiNode = uitreenode(app.RISNode);
            app.RISUMiNode.Tag = 'RIS-UMi';
            app.RISUMiNode.Text = 'RIS-UMi(城市微小区)';
            app.RISUMaNode = uitreenode(app.RISNode);
            app.RISUMaNode.Tag = 'RIS-UMa';
            app.RISUMaNode.Text = 'RIS-UMa(城市宏小区)';
            app.RISRMaNode = uitreenode(app.RISNode);
            app.RISRMaNode.Tag = 'RIS-RMa';
            app.RISRMaNode.Text = 'RIS-RMa(郊区)';
            app.IIoTNode_2 = uitreenode(app.Node_2);
            app.IIoTNode_2.Text = '工业物联网 (IIoT)';
            app.IIoT_LoSNode = uitreenode(app.IIoTNode_2);
            app.IIoT_LoSNode.Tag = 'IIoT_LoS';
            app.IIoT_LoSNode.Text = 'IIoT_LoS';
            app.IIoTDH_NLoSNode = uitreenode(app.IIoTNode_2);
            app.IIoTDH_NLoSNode.Tag = 'IIoT-DH_NLoS';
            app.IIoTDH_NLoSNode.Text = 'IIoT-DH_NLoS(障碍物密集_高天线)';
            app.IIoTDL_NLoSNode = uitreenode(app.IIoTNode_2);
            app.IIoTDL_NLoSNode.Tag = 'IIoT-DL_NLoS';
            app.IIoTDL_NLoSNode.Text = 'IIoT-DL_NLoS(障碍物密集_低天线)';
            app.IIoTSH_NLoSNode = uitreenode(app.IIoTNode_2);
            app.IIoTSH_NLoSNode.Tag = 'IIoT-SH_NLoS';
            app.IIoTSH_NLoSNode.Text = 'IIoT-SH_NLoS(障碍物稀少_高天线)';
            app.IIoTSL_NLoSNode = uitreenode(app.IIoTNode_2);
            app.IIoTSL_NLoSNode.Tag = 'IIoT-SL_NLoS';
            app.IIoTSL_NLoSNode.Text = 'IIoT-SL_NLoS(障碍物稀少_低天线)';
            app.ISACNode = uitreenode(app.Node_2);
            app.ISACNode.Text = '通感一体化 (ISAC)';
            app.ISACUMaNode = uitreenode(app.ISACNode);
            app.ISACUMaNode.Tag = 'ISAC-UMa';
            app.ISACUMaNode.Text = 'ISAC-UMa(城市宏小区)';
            app.ISACUMiNode = uitreenode(app.ISACNode);
            app.ISACUMiNode.Tag = 'ISAC-UMi';
            app.ISACUMiNode.Text = 'ISAC-UMi(城市微小区)';
            app.ISACRMaNode = uitreenode(app.ISACNode);
            app.ISACRMaNode.Tag = 'ISAC-RMa';
            app.ISACRMaNode.Text = 'ISAC-RMa(郊区)';
            app.ISACIndoorNode = uitreenode(app.ISACNode);
            app.ISACIndoorNode.Tag = 'ISAC-Indoor';
            app.ISACIndoorNode.Text = 'ISAC-Indoor(室内)';
            app.UHSTNode = uitreenode(app.Node_2);
            app.UHSTNode.Tag = 'UHST';
            app.UHSTNode.Text = '超高铁 (UHST)';
            app.USNode = uitreenode(app.Node_2);
            app.USNode.Text = '地下空间(US)';
            app.UStunnelNode = uitreenode(app.USNode);
            app.UStunnelNode.Tag = 'US-tunnel';
            app.UStunnelNode.Text = 'US-tunnel(地下隧道)';
            app.USgarageNode = uitreenode(app.USNode);
            app.USgarageNode.Tag = 'US-garage';
            app.USgarageNode.Text = 'US-garage(地下停车场)';
            app.UWANode = uitreenode(app.Node_2);
            app.UWANode.Text = '水下通信(UWA)';
            app.UWAShallowWaterNode = uitreenode(app.UWANode);
            app.UWAShallowWaterNode.Tag = 'UWA-ShallowWater';
            app.UWAShallowWaterNode.Text = 'UWA-ShallowWater(浅海域)';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.Tooltip = {'acoustic：200 Hz - 50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03 - 0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'cmWave：6-24 GHz'; 'mmWave：24 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.EditField.Value = '2.6';
            app.panelPos1 = uipanel(app.Tab);
            app.panelPos1.Title = 'Panel2';
            app.ButtonGroup = uibuttongroup(app.panelPos1);
            app.ButtonGroup.Title = '传播条件 (Propagation conditions)';
            app.LoSButton = uiradiobutton(app.ButtonGroup);
            app.LoSButton.Text = 'LoS';
            app.LoSButton.Value = true;
            app.NLoSButton = uiradiobutton(app.ButtonGroup);
            app.NLoSButton.Text = 'NLoS';
            app.LoSO2IButton = uiradiobutton(app.ButtonGroup);
            app.LoSO2IButton.Text = 'LoS-O2I';
            app.NLoSO2IButton = uiradiobutton(app.ButtonGroup);
            app.NLoSO2IButton.Text = 'NLoS-O2I';
            app.NLoSvButton = uiradiobutton(app.ButtonGroup);
            app.NLoSvButton.Text = 'NLoSv';
            app.panelPos2 = uipanel(app.panelPos1);
            app.panelPos2.Title = 'Panel';
            app.EditField_14Label_4 = uilabel(app.panelPos2);
            app.EditField_14Label_4.Text = '簇初始数量（个）';
            app.noCluster_5 = uieditfield(app.panelPos2, 'numeric');
            app.noCluster_5.Limits = [2 50];
            app.noCluster_5.Value = 10;
            app.EditField_15Label_7 = uilabel(app.panelPos2);
            app.EditField_15Label_7.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_5 = uieditfield(app.panelPos2, 'numeric');
            app.noRaysEachCluster_5.Limits = [2 50];
            app.noRaysEachCluster_5.Value = 15;
            app.EditField_14Label_6 = uilabel(app.panelPos2);
            app.EditField_14Label_6.Text = 'Initial number of clusters';
            app.EditField_15Label_9 = uilabel(app.panelPos2);
            app.EditField_15Label_9.Text = 'Number of sub-paths within a cluster';
            app.Image13 = uiimage(app.panelPos2);
            app.Image13.ImageSource = 'result.jpg';
            app.DropDown_7 = uidropdown(app.panelPos1);
            app.DropDown_7.Items = {'single-bounce model', 'multi-bounce model'};
            app.DropDown_7.Enable = 'off';
            app.DropDown_7.Visible = 'off';
            app.DropDown_7.Value = 'single-bounce model';
            app.choose_parameter = uibutton(app.Tab, 'push');
            app.choose_parameter.ButtonPushedFcn = createCallbackFcn(app, @choose_parameterButtonPushed, true);
            app.choose_parameter.Text = {'修改信道模型参数'; 'Modify parameters'};
            app.MHzEditFieldLabel_2 = uilabel(app.Tab);
            app.MHzEditFieldLabel_2.Tooltip = {''};
            app.MHzEditFieldLabel_2.Text = '子载波数量';
            app.Carrierfrequency_Label = uilabel(app.Tab);
            app.Carrierfrequency_Label.Tooltip = {'acoustic：200 Hz - 50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03 - 0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'cmWave：6-24 GHz'; 'mmWave：24 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.Carrierfrequency_Label.Text = 'Carrier frequency';
            app.Bandwidth_Label = uilabel(app.Tab);
            app.Bandwidth_Label.Tooltip = {'acoustic：0.1 kHz-10 kHz'; 'ShortWave：0.003 - 0.004 MHz'; 'VHF：3-30 MHz'; 'sub-6 GHz：30 - 1000 MHz'; 'mmWave：100 - 2000 MHz'; 'THz：500 - 5000 MHz'};
            app.Bandwidth_Label.Text = 'Bandwidth';
            app.Number_of_subcarriers_Label = uilabel(app.Tab);
            app.Number_of_subcarriers_Label.Tooltip = {''};
            app.Number_of_subcarriers_Label.Text = 'Number of subcarriers';
            app.bandwidth = uieditfield(app.Tab, 'text');
            app.bandwidth.Tooltip = {'acoustic：0.1 kHz-10 kHz'; 'ShortWave：0.003 - 0.004 MHz'; 'VHF：3-30 MHz'; 'sub-6 GHz：30 - 1000 MHz'; 'mmWave：100 - 2000 MHz'; 'THz：500 - 5000 MHz'};
            app.bandwidth.Value = '100';
            app.freqSamples = uieditfield(app.Tab, 'text');
            app.freqSamples.Value = '100';
            app.GHzEditFieldLabel = uilabel(app.Tab);
            app.GHzEditFieldLabel.Tooltip = {'acoustic：200 Hz - 50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03 - 0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'cmWave：6-24 GHz'; 'mmWave：24 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.GHzEditFieldLabel.Text = '载波频率（GHz）';
            app.MHzEditFieldLabel = uilabel(app.Tab);
            app.MHzEditFieldLabel.Tooltip = {'acoustic：0.1 kHz-10 kHz'; 'ShortWave：0.003 - 0.004 MHz'; 'VHF：3-30 MHz'; 'sub-6 GHz：30 - 1000 MHz'; 'mmWave：100 - 2000 MHz'; 'THz：500 - 5000 MHz'};
            app.MHzEditFieldLabel.Text = '带宽（MHz）';
            app.AntennasettingsTab = uitab(app.TabGroup);
            app.AntennasettingsTab.Title = ' 天线配置(Antenna settings)';
            app.add_antenna = uibutton(app.AntennasettingsTab, 'push');
            app.add_antenna.ButtonPushedFcn = createCallbackFcn(app, @add_antennaButtonPushed, true);
            app.add_antenna.Text = '添加天线 (Add antenna array)';
            app.delete_antenna = uibutton(app.AntennasettingsTab, 'push');
            app.delete_antenna.ButtonPushedFcn = createCallbackFcn(app, @delete_antennaButtonPushed, true);
            app.delete_antenna.Text = '删除天线 (Delete selected antenna array)';
            app.UITable_antenna = uitable(app.AntennasettingsTab);
            app.UITable_antenna.ColumnName = {'Antenna array id'; 'Name'; 'Number of elements per row'; 'Number of elements per column'; 'Element spacing per row'; 'Element spacing per column'; 'Rotate angle Z'; 'Rotate angle Y'; 'Rotate angle X'; 'Polarization mode'; 'Directory of antenna pattern'; 'Antenna type'};
            app.UITable_antenna.RowName = {};
            app.UITable_antenna.CellSelectionCallback = createCallbackFcn(app, @UITable_antennaCellSelection, true);
            app.edit_antenna = uibutton(app.AntennasettingsTab, 'push');
            app.edit_antenna.ButtonPushedFcn = createCallbackFcn(app, @edit_antennaButtonPushed, true);
            app.edit_antenna.Text = '编辑天线 (Edit selected antenna array)';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '运动轨迹(Track)';
            app.MovedurationsLabel = uilabel(app.Tab_3);
            app.MovedurationsLabel.Text = {'运动时长'; 'Move duration (s)'};
            app.trackLength = uieditfield(app.Tab_3, 'numeric');
            app.trackLength.Limits = [1e-06 1000];
            app.trackLength.Value = 0.01;
            app.SamplingratepersecondHzLabel = uilabel(app.Tab_3);
            app.SamplingratepersecondHzLabel.Text = {'每秒采样频率'; 'Sampling rate per second (Hz)'};
            app.sampleRate = uieditfield(app.Tab_3, 'numeric');
            app.sampleRate.Limits = [1 10000000];
            app.sampleRate.Value = 10000;
            app.ButtonGroup_cluster = uipanel(app.Tab_3);
            app.ButtonGroup_cluster.Title = '簇的运动轨迹 (Movement track of clusters)';
            app.anSpeed = uieditfield(app.ButtonGroup_cluster, 'numeric');
            app.anSpeed.Limits = [0 1000];
            app.msFirstbounceClustersvelocityLabel = uilabel(app.ButtonGroup_cluster);
            app.msFirstbounceClustersvelocityLabel.Text = {'首跳簇：运动速度(m/s)'; 'First-bounce Clusters:velocity'};
            app.znOri = uieditfield(app.ButtonGroup_cluster, 'text');
            app.znOri.Value = '[1,1,0]';
            app.DirectionLabel_4 = uilabel(app.ButtonGroup_cluster);
            app.DirectionLabel_4.Text = {'运动方向'; 'Direction'};
            app.anOri = uieditfield(app.ButtonGroup_cluster, 'text');
            app.anOri.Value = '[1,1,0]';
            app.DirectionLabel_3 = uilabel(app.ButtonGroup_cluster);
            app.DirectionLabel_3.Text = {'运动方向'; 'Direction'};
            app.znSpeed = uieditfield(app.ButtonGroup_cluster, 'numeric');
            app.znSpeed.Limits = [0 1000];
            app.msLastbounceClustersvelocityLabel = uilabel(app.ButtonGroup_cluster);
            app.msLastbounceClustersvelocityLabel.Text = {'末跳簇：运动速度(m/s)'; 'Last-bounce Clusters: velocity'};
            app.switchSampleRate = uiswitch(app.Tab_3, 'slider');
            app.switchSampleRate.Items = {'Close', 'Open'};
            app.switchSampleRate.ValueChangedFcn = createCallbackFcn(app, @switchSampleRateValueChanged, true);
            app.switchSampleRate.Value = 'Close';
            app.Lamp = uilamp(app.Tab_3);
            app.CheckLabel = uilabel(app.Tab_3);
            app.CheckLabel.Text = {'检查是否满足抽样定理：'; 'Check if the sampling theorem is met'};
            app.MovementtrackofTxPanel = uipanel(app.Tab_3);
            app.MovementtrackofTxPanel.Title = '发射端运动轨迹(Movement track of Tx)';
            app.settingButton = uibutton(app.MovementtrackofTxPanel, 'push');
            app.settingButton.ButtonPushedFcn = createCallbackFcn(app, @settingButtonPushed, true);
            app.settingButton.Text = '配置用户数量及其初始位置(setting)';
            app.UITable_tx = uitable(app.MovementtrackofTxPanel);
            app.UITable_tx.ColumnName = {'User id'; 'User name'; 'Initial position'; 'Antenna array id'; 'Track type'; 'Velocity'; 'Acceleration'; 'Motion direction'};
            app.UITable_tx.RowName = {};
            app.UITable_tx.CellSelectionCallback = createCallbackFcn(app, @UITable_txCellSelection, true);
            app.UITable_tx.DisplayDataChangedFcn = createCallbackFcn(app, @UITable_txDisplayDataChanged, true);
            app.DistributionLabel = uilabel(app.MovementtrackofTxPanel);
            app.DistributionLabel.Text = {'分布类型   '; ' (Distribution)'};
            app.DropDown_tx = uidropdown(app.MovementtrackofTxPanel);
            app.DropDown_tx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
            app.DropDown_tx.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_txOpening, true);
            app.DropDown_tx.Value = '点(point)';
            app.MovementtrackofRxPanel = uipanel(app.Tab_3);
            app.MovementtrackofRxPanel.Title = '接收端运动轨迹(Movement track of Rx)';
            app.settingButton_2 = uibutton(app.MovementtrackofRxPanel, 'push');
            app.settingButton_2.ButtonPushedFcn = createCallbackFcn(app, @settingButton_2Pushed2, true);
            app.settingButton_2.Text = '配置用户数量及其初始位置(setting)';
            app.UITable_rx = uitable(app.MovementtrackofRxPanel);
            app.UITable_rx.ColumnName = {'User id'; 'User name'; 'Initial position'; 'Antenna array id'; 'Track type'; 'Velocity'; 'Acceleration'; 'Motion direction'};
            app.UITable_rx.RowName = {};
            app.UITable_rx.CellSelectionCallback = createCallbackFcn(app, @UITable_rxCellSelection, true);
            app.DropDown_9Label_2 = uilabel(app.MovementtrackofRxPanel);
            app.DropDown_9Label_2.Text = {'分布类型   '; ' (Distribution)'};
            app.DropDown_rx = uidropdown(app.MovementtrackofRxPanel);
            app.DropDown_rx.Items = {'点(point)', '直线(straight line)', '圆(circle)', '随机分布(random)'};
            app.DropDown_rx.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_rxOpening, true);
            app.DropDown_rx.Value = '点(point)';
            app.Tx_RxPosVisualize = uibutton(app.Tab_3, 'push');
            app.Tx_RxPosVisualize.ButtonPushedFcn = createCallbackFcn(app, @Tx_RxPosVisualizeButtonPushed, true);
            app.Tx_RxPosVisualize.Text = '收发端轨迹预览 (Preview)';
            app.ButtonGroup_preview = uibuttongroup(app.Tab_3);
            app.RxButton = uiradiobutton(app.ButtonGroup_preview);
            app.RxButton.Text = 'Rx';
            app.TxButton = uiradiobutton(app.ButtonGroup_preview);
            app.TxButton.Text = 'Tx';
            app.BothButton = uiradiobutton(app.ButtonGroup_preview);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '仿真结果(Results)';
            app.settings = uitextarea(app.Tab_4);
            app.Button_Analysis = uibutton(app.Tab_4, 'push');
            app.Button_Analysis.ButtonPushedFcn = createCallbackFcn(app, @Button_AnalysisPushed, true);
            app.Button_Analysis.Text = {'信道统计特性分析'; 'Analyze'};
            app.CIR = uibutton(app.Tab_4, 'push');
            app.CIR.ButtonPushedFcn = createCallbackFcn(app, @CIRButtonPushed, true);
            app.CIR.Text = {'运行'; 'Run'};
            app.statisticalProps = uibutton(app.Tab_4, 'push');
            app.statisticalProps.ButtonPushedFcn = createCallbackFcn(app, @statisticalPropsButtonPushed3, true);
            app.statisticalProps.Text = {'检查和显示所有参数配置'; 'Display all parameters'};
            app.Button_Optimization = uibutton(app.Tab_4, 'push');
            app.Button_Optimization.ButtonPushedFcn = createCallbackFcn(app, @Button_OptimizationPushed, true);
            app.Button_Optimization.Text = {'信道模型参数优化（试用）'; 'Parameter optimization (trial)'};
            app.NumofSimulationsLabel = uilabel(app.Tab_4);
            app.NumofSimulationsLabel.Text = {'运行次数'; 'Num. of Simulations'};
            app.Run_times = uieditfield(app.Tab_4, 'numeric');
            app.Run_times.Limits = [1 10000];
            app.Run_times.Value = 1;
            app.Tree_save = uitree(app.Tab_4, 'checkbox');
            app.AllNode_save = uitreenode(app.Tree_save);
            app.AllNode_save.Text = '全部 (All)';
            app.LargescaleparametersNode = uitreenode(app.AllNode_save);
            app.LargescaleparametersNode.Text = '大尺度参数(Large scale parameters)';
            app.Node_saveTxtrack = uitreenode(app.AllNode_save);
            app.Node_saveTxtrack.Text = '发射端运动轨迹信息(Movement track information of Tx)';
            app.Node_saveRxtrack = uitreenode(app.AllNode_save);
            app.Node_saveRxtrack.Text = '接收端运动轨迹信息(Movement track information of Rx)';
            app.Node_saveCIR = uitreenode(app.AllNode_save);
            app.Node_saveCIR.Text = '小尺度衰落信道冲激响应矩阵(Channel impulse response)';
            app.Node_saveCTF = uitreenode(app.AllNode_save);
            app.Node_saveCTF.Text = '小尺度衰落信道转移矩阵(Channel transition matrix)';
            app.Node_saveDalays = uitreenode(app.AllNode_save);
            app.Node_saveDalays.Text = '子径时延(Sub-path delay)';
            app.Node_saveClusters = uitreenode(app.AllNode_save);
            app.Node_saveClusters.Text = '散射体位置信息(Scatterers’ positions information)';
            app.BeamdomainresultsNode = uitreenode(app.AllNode_save);
            app.BeamdomainresultsNode.Text = '波束域结果(Beam domain results)';
            app.ChoosechannelinfostobesavedLabel = uilabel(app.Tab_4);
            app.ChoosechannelinfostobesavedLabel.Text = {'选择要保存的参数'; 'Choose channel infos. to be saved'};
            app.ChooseachannelmodelLabel = uilabel(app.Tab_4);
            app.ChooseachannelmodelLabel.Text = {'选择使用的信道模型'; 'Choose a channel model'};
            app.Model_Selection = uidropdown(app.Tab_4);
            app.Model_Selection.Items = {'6GPCS', '3GPP TR 38.901', 'IMT2020', '3GPP TR 36.777'};
            app.Model_Selection.DropDownOpeningFcn = createCallbackFcn(app, @Model_SelectionDropDownOpening, true);
            app.Model_Selection.Value = '6GPCS';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '许可协议(Copyright)';
            app.Image8 = uiimage(app.Tab_5);
            app.Image8.ImageSource = 'about.jpg';
            app.Label_about_2 = uilabel(app.Tab_5);
            app.Label_about_2.Text = {'      如果您的工作中使用了本软件或者本软件的修改版本，请使用以下引文:                                                                                                   '; '      [1] C.-X. Wang, Z. Lv, X. Gao, X. You, Y. Hao, and H. Haas, “Pervasive wireless channel modeling theory and applications to 6G GBSMs for all frequency bands and all scenarios,”  IEEE Trans. Veh. Technol., vol. 71, no. 9, pp. 9159-9173, Sept. 2022.'; '      [2] C.-X. Wang, Z. Lv, Y. Chen, and H. Haas, “A complete study of space-time-frequency statistical properties of the 6G pervasive channel model,” IEEE Trans. Commun., vol. 71, no. 12, pp. 7273-7287, Dec. 2023.'; '        此外，如果您能在致谢中对6G普适信道仿真器软件的代码开发和共享工作表示认可，我们将不胜感激，例如：“感谢来自东南大学和紫金山实验室的王承祥教授团队在6G普适信道仿真器方面的支持。”  '; '        联系我们：lvzhen@pmlabs.com.cn, chxwang@seu.edu.cn. '; ''; '     For any researchers using the simulator or its modified versions in your scientific work, please cite the following papers:'; '     [1] C.-X. Wang, Z. Lv, X. Gao, X. You, Y. Hao, and H. Haas, “Pervasive wireless channel modeling theory and applications to 6G GBSMs for all frequency bands and all scenarios,”  IEEE Trans. Veh. Technol., vol. 71, no. 9, pp. 9159-9173, Sept. 2022.'; '     [2] C.-X. Wang, Z. Lv, Y. Chen, and H. Haas, “A complete study of space-time-frequency statistical properties of the 6G pervasive channel model,”   IEEE Trans. Commun., vol. 71, no. 12, pp. 7273-7287, Dec. 2023.                                                                  '; '    '; '     In addition, we sincerely appreciate if you express your recognition of the code development and sharing of the 6G Pervasive Channel Simulator Software in the Acknowledgment section, e.g., “The authors thank Professor C.-X. Wang and his research team from Southeast University and Purple Mountain Laboratories for their 6G Pervasive Channel Simulator.”   '; '     Contact us: lvzhen@pmlabs.com.cn, chxwang@seu.edu.cn. '};
            app.GV30Label = uilabel(app.Tab_5);
            app.GV30Label.Text = '东南大学-紫金山实验室-6G普适信道仿真器 V3.0 ';
            app.Image7 = uiimage(app.Tab_5);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'iconsAndFigures', 'QRcode.jpg');
            app.Label_about_4 = uilabel(app.Tab_5);
            app.Label_about_4.Text = {'欢迎扫描左侧二维码，关注Awitec Group公众号，您将：'; '● 加入【SEU-PML-6GPCS软件试用讨论群】'; '● 与其他用户分享使用经验和技巧；'; '● 提出关于软件功能和性能的问题和建议；'; '● 获取来自软件开发团队的最新消息和更新。'; '                                      '; 'Welcome to scan the QR code on the left and follow the Awitec Group official account. Additionally, you are welcome to join our WeChat group if interested.'};
            app.advice = uibutton(app.Tab_5, 'push');
            app.advice.ButtonPushedFcn = createCallbackFcn(app, @adviceButtonPushed, true);
            app.advice.Text = {'提建议'; 'Suggestions'};
            app.SoutheastUniversityPurpleMountainlaboratoriesLabel = uilabel(app.Tab_5);
            app.SoutheastUniversityPurpleMountainlaboratoriesLabel.Text = 'Southeast University-Purple Mountain Laboratories-6G Pervasive Channel Simulator (SEU-PML-6GPCS), V3.0';
            app.Button_copy = uibutton(app.Tab_5, 'push');
            app.Button_copy.ButtonPushedFcn = createCallbackFcn(app, @Button_copyPushed, true);
            app.Button_copy.Text = '引文复制 (Copy)';
            app.maritimePanel = uipanel(app.UIFigure);
            app.maritimePanel.Title = '海洋通信信道模型参数设置(Parameter settings of maritime channel model )';
            app.SubchannelofseasurfaceevaporationductPanel = uipanel(app.maritimePanel);
            app.SubchannelofseasurfaceevaporationductPanel.Title = 'Sub-channel of sea surface evaporation duct';
            app.noCluster_2 = uieditfield(app.SubchannelofseasurfaceevaporationductPanel, 'numeric');
            app.noCluster_2.Limits = [1 5000];
            app.noCluster_2.RoundFractionalValues = 'on';
            app.noCluster_2.Tooltip = {''};
            app.noCluster_2.Value = 10;
            app.GHzEditFieldLabel_4 = uilabel(app.SubchannelofseasurfaceevaporationductPanel);
            app.GHzEditFieldLabel_4.Text = {'簇初始数量（个）'; 'Initial number of clusters'};
            app.noRaysEachCluster_2 = uieditfield(app.SubchannelofseasurfaceevaporationductPanel, 'numeric');
            app.noRaysEachCluster_2.Limits = [1 5000];
            app.noRaysEachCluster_2.RoundFractionalValues = 'on';
            app.noRaysEachCluster_2.Tooltip = {''};
            app.noRaysEachCluster_2.Value = 5;
            app.GHzEditFieldLabel_5 = uilabel(app.SubchannelofseasurfaceevaporationductPanel);
            app.GHzEditFieldLabel_5.Text = {'簇内子径数量（个）'; 'Number of sub-paths within a cluster'};
            app.SubchannelofseasurfacePanel = uipanel(app.maritimePanel);
            app.SubchannelofseasurfacePanel.Title = 'Sub-channel of sea surface';
            app.noCluster_3 = uieditfield(app.SubchannelofseasurfacePanel, 'numeric');
            app.noCluster_3.Limits = [1 5000];
            app.noCluster_3.RoundFractionalValues = 'on';
            app.noCluster_3.Tooltip = {''};
            app.noCluster_3.Value = 10;
            app.GHzEditFieldLabel_6 = uilabel(app.SubchannelofseasurfacePanel);
            app.GHzEditFieldLabel_6.Text = {'簇初始数量（个）'; 'Initial number of clusters'};
            app.noRaysEachCluster_3 = uieditfield(app.SubchannelofseasurfacePanel, 'numeric');
            app.noRaysEachCluster_3.Limits = [1 5000];
            app.noRaysEachCluster_3.RoundFractionalValues = 'on';
            app.noRaysEachCluster_3.Tooltip = {''};
            app.noRaysEachCluster_3.Value = 5;
            app.GHzEditFieldLabel_7 = uilabel(app.SubchannelofseasurfacePanel);
            app.GHzEditFieldLabel_7.Text = {'簇内子径数量（个）'; 'Number of sub-paths within a cluster'};
            app.SeasurfaceevaporationwaveguideheightmLabel = uilabel(app.maritimePanel);
            app.SeasurfaceevaporationwaveguideheightmLabel.Text = {'海面蒸发波导高度 (米)'; 'Sea surface evaporation duct height, m'};
            app.EditField_7 = uieditfield(app.maritimePanel, 'numeric');
            app.EditField_7.Value = 30;
            app.WindvelocitymsLabel = uilabel(app.maritimePanel);
            app.WindvelocitymsLabel.Text = {'风速 (米/秒)'; 'Wind velocity, m/s'};
            app.wind_speed = uieditfield(app.maritimePanel, 'numeric');
            app.wind_speed.Value = 5;
            app.SubchannelofseasurfaceevaporationwaveguidePanel_label = uilabel(app.maritimePanel);
            app.SubchannelofseasurfaceevaporationwaveguidePanel_label.Text = '海面蒸发波导子信道';
            app.SubchannelofseasurfacePanel_label = uilabel(app.maritimePanel);
            app.SubchannelofseasurfacePanel_label.Text = '海面子信道';
            app.Image11 = uiimage(app.maritimePanel);
            app.Image11.ImageSource = 'Maritime.jpg';
            app.satellitePanel = uipanel(app.UIFigure);
            app.satellitePanel.Title = '卫星信道模型参数设置 (Parameter settings of satellite channel model )';
            app.iotaLabel = uilabel(app.satellitePanel);
            app.iotaLabel.Text = '轨道平面倾角，iota(°)';
            app.CinEditField = uieditfield(app.satellitePanel, 'numeric');
            app.CinEditField.Limits = [0 360];
            app.CinEditField.Tooltip = {'轨道和赤道平面的夹角为轨道平面倾角，取值范围是0-360°'};
            app.CinEditField.Value = 30;
            app.OmegaLabel = uilabel(app.satellitePanel);
            app.OmegaLabel.Text = '升交点赤经，Omega (°)';
            app.DinEditField = uieditfield(app.satellitePanel, 'numeric');
            app.DinEditField.Limits = [0 360];
            app.DinEditField.Tooltip = {'轨道向上穿过赤道平面的交点的经度称为升交点赤经'};
            app.eLabel = uilabel(app.satellitePanel);
            app.eLabel.Text = '椭圆轨道偏心率，e';
            app.BinEditField = uieditfield(app.satellitePanel, 'numeric');
            app.BinEditField.Limits = [0 0.999];
            app.BinEditField.Tooltip = {'0-1之间，设置为0时，椭圆轨道简化为圆形轨道'};
            app.omegaLabel = uilabel(app.satellitePanel);
            app.omegaLabel.Text = '近地点幅角，omega (°)';
            app.EinEditField = uieditfield(app.satellitePanel, 'numeric');
            app.EinEditField.Limits = [0 360];
            app.EinEditField.Tooltip = {'椭圆在轨道平面上的方向由轨道近地点与升交点的夹角近地点幅角确定'};
            app.nuLabel = uilabel(app.satellitePanel);
            app.nuLabel.Text = '真近点角，nu (°)';
            app.FinEditField = uieditfield(app.satellitePanel, 'numeric');
            app.FinEditField.Limits = [0 360];
            app.FinEditField.Tooltip = {'从近地点起沿着轨道运动扫过的角度，确定轨道平面后，通过真近点角可确定卫星在圆轨道中的具体位置'};
            app.FinEditField.Value = 10;
            app.Label_23 = uilabel(app.satellitePanel);
            app.Label_23.Text = '卫星高度（千米）';
            app.satHeight = uieditfield(app.satellitePanel, 'numeric');
            app.satHeight.Limits = [500 36000];
            app.satHeight.Tooltip = {'地球静止卫星高度为35786千米'};
            app.satHeight.Value = 800;
            app.EditField_14Label_3 = uilabel(app.satellitePanel);
            app.EditField_14Label_3.Text = '信道模型中簇初始数量（个）';
            app.noCluster_4 = uieditfield(app.satellitePanel, 'numeric');
            app.noCluster_4.Limits = [1 1000];
            app.noCluster_4.Value = 10;
            app.EditField_15Label_6 = uilabel(app.satellitePanel);
            app.EditField_15Label_6.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_4 = uieditfield(app.satellitePanel, 'numeric');
            app.noRaysEachCluster_4.Limits = [1 5000];
            app.noRaysEachCluster_4.Value = 15;
            app.Label_26 = uilabel(app.satellitePanel);
            app.Label_26.Text = '是否降雨';
            app.DropDown_8 = uidropdown(app.satellitePanel);
            app.DropDown_8.Items = {'No rainfall, rainfall rate is 0 mm/h', 'Scattered light rain, rainfall rate is 0.01- 0.25 mm/h', 'Light rain, rainfall rate is 0.25 - 1.0 mm/h', 'moderate rain, rainfall rate is 1.0- 4.0 mm/H', 'Heavy rain, rainfall rate is  4.0 - 16.0 mm/H', 'Rainstorm, rainfall rate is 16.0- 50.0 mm/H', 'Extraordinary rainstorm, rainfall rate is 50.0 - 100.0 mm/H'};
            app.DropDown_8.Value = 'No rainfall, rainfall rate is 0 mm/h';
            app.mmhEditFieldLabel = uilabel(app.satellitePanel);
            app.mmhEditFieldLabel.Text = '降雨速率(mm/h)';
            app.rainRate = uieditfield(app.satellitePanel, 'numeric');
            app.rainRate.Limits = [0 100];
            app.EditField_14Label_7 = uilabel(app.satellitePanel);
            app.EditField_14Label_7.Text = 'Initial number of clusters';
            app.EditField_14Label_8 = uilabel(app.satellitePanel);
            app.EditField_14Label_8.Text = 'Number of sub-paths in each cluster';
            app.EditField_14Label_9 = uilabel(app.satellitePanel);
            app.EditField_14Label_9.Text = 'Satellite altitude/km';
            app.EditField_14Label_10 = uilabel(app.satellitePanel);
            app.EditField_14Label_10.Text = 'Elliptic orbit eccentricity';
            app.EditField_14Label_11 = uilabel(app.satellitePanel);
            app.EditField_14Label_11.Text = 'Right ascension of the ascending node/°';
            app.EditField_14Label_12 = uilabel(app.satellitePanel);
            app.EditField_14Label_12.Text = 'Inclination/°';
            app.EditField_14Label_13 = uilabel(app.satellitePanel);
            app.EditField_14Label_13.Text = 'Argument of periapsis/°';
            app.EditField_14Label_14 = uilabel(app.satellitePanel);
            app.EditField_14Label_14.Text = 'Trueanomaly/°';
            app.EditField_14Label_15 = uilabel(app.satellitePanel);
            app.EditField_14Label_15.Text = 'Rainfall or not';
            app.EditField_14Label_16 = uilabel(app.satellitePanel);
            app.EditField_14Label_16.Text = 'Rainfall rate（mm/h）';
            app.Image14 = uiimage(app.satellitePanel);
            app.Image14.ImageSource = 'satellite.jpg';
            app.RISPanel = uipanel(app.UIFigure);
            app.RISPanel.Title = '智能反射面 (RIS) 信道模型参数设置 (Parameter settings of RIS channel model )';
            app.Image12 = uiimage(app.RISPanel);
            app.Image12.ImageSource = 'RIS.jpg';
            app.vectorEditField_1 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_2 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_2.Value = -1;
            app.vectorEditField_3 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_3.Value = -0.7071;
            app.vectorEditField_4 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_4.Value = 0.7071;
            app.vectorEditField_5 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_6 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_6.Value = 0.7071;
            app.vectorEditField_7 = uieditfield(app.RISPanel, 'numeric');
            app.vectorEditField_7.Value = 0.7071;
            app.vectorEditField_8 = uieditfield(app.RISPanel, 'numeric');
            app.RISEditFieldLabel = uilabel(app.RISPanel);
            app.RISEditFieldLabel.Text = 'RIS姿态';
            app.vectorEditField_0 = uieditfield(app.RISPanel, 'numeric');
            app.Label_22 = uilabel(app.RISPanel);
            app.Label_22.Text = '数量 (个):  每行';
            app.EditField_RISx = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISx.Limits = [1 100];
            app.EditField_RISx.Value = 4;
            app.Label_17 = uilabel(app.RISPanel);
            app.Label_17.Text = '每列';
            app.EditField_RISy = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISy.Limits = [1 100];
            app.EditField_RISy.Value = 4;
            app.Label_18 = uilabel(app.RISPanel);
            app.Label_18.Text = '高度';
            app.EditField_RISheight = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISheight.Limits = [0 Inf];
            app.EditField_RISheight.Value = 0.234;
            app.Label_19 = uilabel(app.RISPanel);
            app.Label_19.Text = '大小 (波长倍数): 宽度';
            app.EditField_RISwidth = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISwidth.Limits = [0 Inf];
            app.EditField_RISwidth.Value = 0.234;
            app.Label_20 = uilabel(app.RISPanel);
            app.Label_20.Text = '每列';
            app.EditField_RISdelta_y = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISdelta_y.Limits = [0 Inf];
            app.EditField_RISdelta_y.Value = 0.468;
            app.Label_21 = uilabel(app.RISPanel);
            app.Label_21.Text = '间隔 (波长倍数):  每行';
            app.EditField_RISdelta_x = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISdelta_x.Limits = [0 Inf];
            app.EditField_RISdelta_x.Value = 0.468;
            app.EditField_RISposy = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISposz = uieditfield(app.RISPanel, 'numeric');
            app.EditField_RISposz.Value = 1.7;
            app.RISLabel = uilabel(app.RISPanel);
            app.RISLabel.Text = 'RIS位置';
            app.EditField_RISposx = uieditfield(app.RISPanel, 'numeric');
            app.LoSLabel_2 = uilabel(app.RISPanel);
            app.LoSLabel_2.Text = 'LoS';
            app.LoSLabel = uilabel(app.RISPanel);
            app.LoSLabel.Text = 'LoS';
            app.EditField_14Label_17 = uilabel(app.RISPanel);
            app.EditField_14Label_17.Text = 'Number: per row';
            app.EditField_14Label_18 = uilabel(app.RISPanel);
            app.EditField_14Label_18.Text = 'per column';
            app.EditField_14Label_19 = uilabel(app.RISPanel);
            app.EditField_14Label_19.Text = 'Size (wavelength multiple): Width';
            app.EditField_14Label_20 = uilabel(app.RISPanel);
            app.EditField_14Label_20.Text = 'height';
            app.EditField_14Label_21 = uilabel(app.RISPanel);
            app.EditField_14Label_21.Text = 'Interval (wavelength multiple): per row';
            app.EditField_14Label_22 = uilabel(app.RISPanel);
            app.EditField_14Label_22.Text = 'per column';
            app.EditField_14Label_23 = uilabel(app.RISPanel);
            app.EditField_14Label_23.Text = 'RIS position';
            app.EditField_14Label_24 = uilabel(app.RISPanel);
            app.EditField_14Label_24.Text = 'RIS posture';
            app.ButtonGroup_RIS = uibuttongroup(app.RISPanel);
            app.ButtonGroup_RIS.Title = 'BS-UE Propagation conditions';
            app.LoSButton_RIS = uiradiobutton(app.ButtonGroup_RIS);
            app.LoSButton_RIS.Text = 'LoS';
            app.LoSButton_RIS.Value = true;
            app.NLoSButton_RIS = uiradiobutton(app.ButtonGroup_RIS);
            app.NLoSButton_RIS.Text = 'NLoS';
            app.EditField_15Label_11 = uilabel(app.RISPanel);
            app.EditField_15Label_11.Text = 'Number of sub-paths in a cluster';
            app.noRaysEachCluster_RIS1 = uieditfield(app.RISPanel, 'numeric');
            app.noRaysEachCluster_RIS1.Limits = [1 5000];
            app.noRaysEachCluster_RIS1.Value = 5;
            app.BSRISLabel_2 = uilabel(app.RISPanel);
            app.BSRISLabel_2.Text = 'Initial number of  clusters in BS-RIS-UE sub-channel';
            app.noCluster_RIS1 = uieditfield(app.RISPanel, 'numeric');
            app.noCluster_RIS1.Limits = [1 1000];
            app.noCluster_RIS1.Value = 10;
            app.EditField_15Label_12 = uilabel(app.RISPanel);
            app.EditField_15Label_12.Text = 'Number of sub-paths in a cluster';
            app.noRaysEachCluster_RIS3 = uieditfield(app.RISPanel, 'numeric');
            app.noRaysEachCluster_RIS3.Limits = [1 5000];
            app.noRaysEachCluster_RIS3.Value = 5;
            app.BSUELabel_2 = uilabel(app.RISPanel);
            app.BSUELabel_2.Text = 'Initial number of  clusters in BS-UE sub-channel';
            app.noCluster_RIS3 = uieditfield(app.RISPanel, 'numeric');
            app.noCluster_RIS3.Limits = [1 1000];
            app.noCluster_RIS3.Value = 10;
            app.OWCPanel = uipanel(app.UIFigure);
            app.OWCPanel.Title = '光无线通信 (OWC) 信道模型参数设置 (Parameter settings of OWC channel model)';
            app.LEDDropDown_2Label = uilabel(app.OWCPanel);
            app.LEDDropDown_2Label.Text = '可见光频段LED 颜色';
            app.LEDColor = uidropdown(app.OWCPanel);
            app.LEDColor.Items = {'white_low', 'white_high', 'red', 'green', 'blue'};
            app.LEDColor.Value = 'white_low';
            app.LEDDropDown_3Label = uilabel(app.OWCPanel);
            app.LEDDropDown_3Label.Text = 'LED 辐射模式';
            app.LEDType = uidropdown(app.OWCPanel);
            app.LEDType.Items = {'Lambertian', 'LUXEON_LB', 'LUXEON_UB', 'EdiPower', 'XLamp'};
            app.LEDType.Value = 'Lambertian';
            app.AntennaLED = uibuttongroup(app.OWCPanel);
            app.AntennaLED.Title = 'LED array configuration';
            app.LEDLabel = uilabel(app.AntennaLED);
            app.LEDLabel.Text = 'LED 数量（个）:    每行';
            app.noLEDRow = uieditfield(app.AntennaLED, 'numeric');
            app.noLEDRow.Limits = [1 1024];
            app.noLEDRow.Value = 1;
            app.LEDLabel_2 = uilabel(app.AntennaLED);
            app.LEDLabel_2.Text = 'LED 间距  (米)：   每行';
            app.spacingLEDRow = uieditfield(app.AntennaLED, 'numeric');
            app.spacingLEDRow.Limits = [0.01 1000];
            app.spacingLEDRow.Value = 1;
            app.Label_27 = uilabel(app.AntennaLED);
            app.Label_27.Text = '水平角（弧度）:    每行';
            app.azimuthAngleLEDRow = uieditfield(app.AntennaLED, 'numeric');
            app.azimuthAngleLEDRow.Limits = [-180 180];
            app.azimuthAngleLEDRow.Value = 30;
            app.Label_28 = uilabel(app.AntennaLED);
            app.Label_28.Text = '俯仰角（弧度）:    每行';
            app.elevationAngleLEDRow = uieditfield(app.AntennaLED, 'numeric');
            app.elevationAngleLEDRow.Limits = [-180 180];
            app.elevationAngleLEDRow.Value = 45;
            app.Label_29 = uilabel(app.AntennaLED);
            app.Label_29.Text = '每列';
            app.noLEDColumn = uieditfield(app.AntennaLED, 'numeric');
            app.noLEDColumn.Limits = [1 1024];
            app.noLEDColumn.Value = 1;
            app.EditField_11Label_2 = uilabel(app.AntennaLED);
            app.EditField_11Label_2.Text = '每列';
            app.spacingLEDColumn = uieditfield(app.AntennaLED, 'numeric');
            app.spacingLEDColumn.Limits = [0.01 10];
            app.spacingLEDColumn.Value = 1;
            app.EditField_12Label_2 = uilabel(app.AntennaLED);
            app.EditField_12Label_2.Text = '每列';
            app.azimuthAngleLEDColumn = uieditfield(app.AntennaLED, 'numeric');
            app.azimuthAngleLEDColumn.Limits = [-180 180];
            app.EditField_13Label_2 = uilabel(app.AntennaLED);
            app.EditField_13Label_2.Text = '每列';
            app.elevationAngleLEDColumn = uieditfield(app.AntennaLED, 'numeric');
            app.elevationAngleLEDColumn.Limits = [-180 180];
            app.percolumnLabel_9 = uilabel(app.AntennaLED);
            app.percolumnLabel_9.Text = 'per column';
            app.percolumnLabel_10 = uilabel(app.AntennaLED);
            app.percolumnLabel_10.Text = 'per column';
            app.percolumnLabel_11 = uilabel(app.AntennaLED);
            app.percolumnLabel_11.Text = 'per column';
            app.percolumnLabel_8 = uilabel(app.AntennaLED);
            app.percolumnLabel_8.Text = 'per column';
            app.EditField_14Label_25 = uilabel(app.AntennaLED);
            app.EditField_14Label_25.Text = 'Number of LEDs：per row';
            app.EditField_14Label_26 = uilabel(app.AntennaLED);
            app.EditField_14Label_26.Text = 'Spacing of LEDs：per row';
            app.EditField_14Label_27 = uilabel(app.AntennaLED);
            app.EditField_14Label_27.Text = 'Horizontal angle：per row';
            app.EditField_14Label_28 = uilabel(app.AntennaLED);
            app.EditField_14Label_28.Text = 'Pitch angle：per row';
            app.AntennaPD = uibuttongroup(app.OWCPanel);
            app.AntennaPD.Title = 'Photodiode (PD) configuration';
            app.azimuthPD = uilabel(app.AntennaPD);
            app.azimuthPD.Text = '水平角（°）:            ';
            app.azimuthAnglePD = uieditfield(app.AntennaPD, 'numeric');
            app.azimuthAnglePD.Limits = [-180 180];
            app.azimuthAnglePD.Value = -180;
            app.elevationPD = uilabel(app.AntennaPD);
            app.elevationPD.Text = '俯仰角（°）:            ';
            app.elevationAnglePD = uieditfield(app.AntennaPD, 'numeric');
            app.elevationAnglePD.Limits = [-180 180];
            app.elevationAnglePD.Value = -40;
            app.PD_azi = uilabel(app.AntennaPD);
            app.PD_azi.Text = '水平角旋转速度:    ';
            app.PD_omegavR_A = uieditfield(app.AntennaPD, 'numeric');
            app.PD_omegavR_A.Limits = [0 20];
            app.elevationPD_2 = uilabel(app.AntennaPD);
            app.elevationPD_2.Text = '俯仰角旋转速度:       ';
            app.PD_omegavR_E = uieditfield(app.AntennaPD, 'numeric');
            app.PD_omegavR_E.Limits = [0 20];
            app.EditField_14Label_29 = uilabel(app.AntennaPD);
            app.EditField_14Label_29.Text = 'Elevation angle';
            app.EditField_14Label_30 = uilabel(app.AntennaPD);
            app.EditField_14Label_30.Text = 'Azimuth angular rotation speed';
            app.EditField_14Label_31 = uilabel(app.AntennaPD);
            app.EditField_14Label_31.Text = 'Azimuth angle';
            app.EditField_14Label_32 = uilabel(app.AntennaPD);
            app.EditField_14Label_32.Text = 'Elevation  angular rotation speed';
            app.EditField_14Label_5 = uilabel(app.OWCPanel);
            app.EditField_14Label_5.Text = '簇初始数量（个）';
            app.noCluster_6 = uieditfield(app.OWCPanel, 'numeric');
            app.noCluster_6.Limits = [1 1000];
            app.noCluster_6.Value = 10;
            app.EditField_15Label_8 = uilabel(app.OWCPanel);
            app.EditField_15Label_8.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_6 = uieditfield(app.OWCPanel, 'numeric');
            app.noRaysEachCluster_6.Limits = [1 5000];
            app.noRaysEachCluster_6.Value = 15;
            app.EditField_14Label_33 = uilabel(app.OWCPanel);
            app.EditField_14Label_33.Text = '光无线频段--LED阵列配置 ';
            app.EditField_14Label_34 = uilabel(app.OWCPanel);
            app.EditField_14Label_34.Text = '光无线频段--光电二极管(PD)配置';
            app.EditField_14Label_36 = uilabel(app.OWCPanel);
            app.EditField_14Label_36.Text = 'Number of sub-paths  within the cluster';
            app.EditField_14Label_37 = uilabel(app.OWCPanel);
            app.EditField_14Label_37.Text = 'Visible band LED colors';
            app.EditField_14Label_38 = uilabel(app.OWCPanel);
            app.EditField_14Label_38.Text = 'LED radiation pattern';
            app.EditField_14Label_35 = uilabel(app.OWCPanel);
            app.EditField_14Label_35.Text = 'Initial number of clusters';
            app.Image9 = uiimage(app.OWCPanel);
            app.Image9.ImageSource = 'owc.jpg';
            app.Panel = uipanel(app.UIFigure);
            app.DaysremainingLabel = uilabel(app.Panel);
            app.DaysremainingLabel.Text = 'Days remaining';
            app.timechecking = uitextarea(app.Panel);
            app.timechecking.Editable = 'off';
            app.skywavePanel = uipanel(app.UIFigure);
            app.skywavePanel.Title = '短波信道模型参数设置 (Parameter settings of shortwave channel model )';
            app.eLabel_2 = uilabel(app.skywavePanel);
            app.eLabel_2.Text = '一年中的第几天';
            app.Day = uieditfield(app.skywavePanel, 'numeric');
            app.Day.Limits = [1 366];
            app.Day.Tooltip = {''};
            app.Day.Value = 1;
            app.omegaLabel_2 = uilabel(app.skywavePanel);
            app.omegaLabel_2.Text = '纬度';
            app.Latitude = uieditfield(app.skywavePanel, 'numeric');
            app.Latitude.Limits = [0 360];
            app.Latitude.Tooltip = {''};
            app.Latitude.Value = 30;
            app.Label_30 = uilabel(app.skywavePanel);
            app.Label_30.Text = 'E电离层高度（千米）';
            app.satHeight_2 = uieditfield(app.skywavePanel, 'numeric');
            app.satHeight_2.Editable = 'off';
            app.satHeight_2.Tooltip = {''};
            app.satHeight_2.Value = 110;
            app.EditField_15Label_10 = uilabel(app.skywavePanel);
            app.EditField_15Label_10.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_7 = uieditfield(app.skywavePanel, 'numeric');
            app.noRaysEachCluster_7.Limits = [1 5000];
            app.noRaysEachCluster_7.Value = 15;
            app.EditField_14Label_39 = uilabel(app.skywavePanel);
            app.EditField_14Label_39.Text = 'Number of sub-paths in each cluster';
            app.EditField_14Label_40 = uilabel(app.skywavePanel);
            app.EditField_14Label_40.Text = 'ionosphereE altitude/km';
            app.EditField_14Label_41 = uilabel(app.skywavePanel);
            app.EditField_14Label_41.Text = 'Day of the year';
            app.EditField_14Label_42 = uilabel(app.skywavePanel);
            app.EditField_14Label_42.Text = 'Latitude';
            app.Image14_2 = uiimage(app.skywavePanel);
            app.Image14_2.ImageSource = 'ShortWave.png';
            app.Label_31 = uilabel(app.skywavePanel);
            app.Label_31.Text = 'F1电离层高度（千米）';
            app.satHeight_3 = uieditfield(app.skywavePanel, 'numeric');
            app.satHeight_3.Editable = 'off';
            app.satHeight_3.Tooltip = {''};
            app.satHeight_3.Value = 200;
            app.EditField_14Label_43 = uilabel(app.skywavePanel);
            app.EditField_14Label_43.Text = 'ionosphereF1 altitude/km';
            app.Label_32 = uilabel(app.skywavePanel);
            app.Label_32.Text = 'F2电离层高度（千米）';
            app.satHeight_4 = uieditfield(app.skywavePanel, 'numeric');
            app.satHeight_4.Editable = 'off';
            app.satHeight_4.Tooltip = {''};
            app.satHeight_4.Value = 300;
            app.EditField_14Label_44 = uilabel(app.skywavePanel);
            app.EditField_14Label_44.Text = 'ionosphereF2 altitude/km';
            app.EditField_14Label_45 = uilabel(app.skywavePanel);
            app.EditField_14Label_45.Text = 'Hour';
            app.Hour = uieditfield(app.skywavePanel, 'numeric');
            app.Hour.Limits = [0 24];
            app.Hour.Tooltip = {''};
            app.Hour.Value = 12;
            app.omegaLabel_3 = uilabel(app.skywavePanel);
            app.omegaLabel_3.Text = '时间';
            app.omegaLabel_4 = uilabel(app.skywavePanel);
            app.omegaLabel_4.Text = '太阳黑子数';
            app.R12 = uieditfield(app.skywavePanel, 'numeric');
            app.R12.Limits = [0 360];
            app.R12.Tooltip = {''};
            app.R12.Value = 120;
            app.EditField_14Label_46 = uilabel(app.skywavePanel);
            app.EditField_14Label_46.Text = 'sunspot number';
            app.eLabel_3 = uilabel(app.skywavePanel);
            app.eLabel_3.Text = '磁感应强度(法)';
            app.Bx = uieditfield(app.skywavePanel, 'numeric');
            app.Bx.Limits = [0 0.999];
            app.Bx.Tooltip = {''};
            app.Bx.Value = 1e-07;
            app.EditField_14Label_47 = uilabel(app.skywavePanel);
            app.EditField_14Label_47.Text = 'Magnetic induction(F)';
            app.uwaPanel = uipanel(app.UIFigure);
            app.uwaPanel.Title = '水下水声信道模型参数设置(Parameter settings of uwa channel model )';
            app.EditField_14Label_48 = uilabel(app.uwaPanel);
            app.EditField_14Label_48.Text = '信道模型中簇初始数量（个）';
            app.noCluster_7 = uieditfield(app.uwaPanel, 'numeric');
            app.noCluster_7.Limits = [1 1000];
            app.noCluster_7.Value = 10;
            app.EditField_15Label_13 = uilabel(app.uwaPanel);
            app.EditField_15Label_13.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_8 = uieditfield(app.uwaPanel, 'numeric');
            app.noRaysEachCluster_8.Limits = [1 5000];
            app.noRaysEachCluster_8.Value = 15;
            app.EditField_14Label_49 = uilabel(app.uwaPanel);
            app.EditField_14Label_49.Text = 'Initial number of clusters';
            app.EditField_14Label_50 = uilabel(app.uwaPanel);
            app.EditField_14Label_50.Text = 'Number of sub-paths in each cluster';
            app.EditField_15Label_14 = uilabel(app.uwaPanel);
            app.EditField_15Label_14.Text = '风速（米/秒）';
            app.wind_speed_uwa = uieditfield(app.uwaPanel, 'numeric');
            app.wind_speed_uwa.Limits = [0 30];
            app.wind_speed_uwa.Value = 5;
            app.EditField_14Label_51 = uilabel(app.uwaPanel);
            app.EditField_14Label_51.Text = 'Speed of wind, m/s';
            app.EditField_15Label_15 = uilabel(app.uwaPanel);
            app.EditField_15Label_15.Text = '水深度 (米)';
            app.height_water_uwa = uieditfield(app.uwaPanel, 'numeric');
            app.height_water_uwa.Limits = [0 100];
            app.height_water_uwa.ValueChangedFcn = createCallbackFcn(app, @height_water_uwaValueChanged, true);
            app.height_water_uwa.Value = 100;
            app.EditField_14Label_52 = uilabel(app.uwaPanel);
            app.EditField_14Label_52.Text = 'Depth of water, m';
            app.EditField_15Label_16 = uilabel(app.uwaPanel);
            app.EditField_15Label_16.Text = '发射端深度 (米)';
            app.txDepth = uieditfield(app.uwaPanel, 'numeric');
            app.txDepth.Limits = [1 100];
            app.txDepth.ValueChangedFcn = createCallbackFcn(app, @txDepthValueChanged, true);
            app.txDepth.Value = 50;
            app.EditField_14Label_53 = uilabel(app.uwaPanel);
            app.EditField_14Label_53.Text = 'Depth of Transmitter, m';
            app.EditField_15Label_17 = uilabel(app.uwaPanel);
            app.EditField_15Label_17.Text = '接收端深度 (米)';
            app.rxDepth = uieditfield(app.uwaPanel, 'numeric');
            app.rxDepth.Limits = [1 100];
            app.rxDepth.ValueChangedFcn = createCallbackFcn(app, @rxDepthValueChanged, true);
            app.rxDepth.Value = 50;
            app.EditField_14Label_54 = uilabel(app.uwaPanel);
            app.EditField_14Label_54.Text = 'Depth of Receiver, m';
            app.EditField_15Label_18 = uilabel(app.uwaPanel);
            app.EditField_15Label_18.Text = '声速（米/秒）';
            app.acousticSpeed = uieditfield(app.uwaPanel, 'numeric');
            app.acousticSpeed.Limits = [1400 1700];
            app.acousticSpeed.Value = 1500;
            app.EditField_14Label_55 = uilabel(app.uwaPanel);
            app.EditField_14Label_55.Text = 'Speed of acoustic wave, m/s';
            app.EditField_15Label_19 = uilabel(app.uwaPanel);
            app.EditField_15Label_19.Text = '发射接收端水平距离 (米)';
            app.Distance_tx_rx = uieditfield(app.uwaPanel, 'numeric');
            app.Distance_tx_rx.Limits = [100 20000];
            app.Distance_tx_rx.Value = 2000;
            app.EditField_14Label_56 = uilabel(app.uwaPanel);
            app.EditField_14Label_56.Text = 'Distance between Tx and Rx, m';
            app.Image15 = uiimage(app.uwaPanel);
            app.Image15.ImageSource = 'uwa.jpg';
            app.ISACPanel = uipanel(app.UIFigure);
            app.ISACPanel.Title = 'ISAC信道模型参数设置(Parameter settings of ISAC channel model )';
            app.ButtonGroup_2 = uibuttongroup(app.ISACPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '传播条件 (Propagation conditions)';
            app.LoSLoSButton = uiradiobutton(app.ButtonGroup_2);
            app.LoSLoSButton.Text = 'LoS-LoS';
            app.NLoSLoSButton = uiradiobutton(app.ButtonGroup_2);
            app.NLoSLoSButton.Enable = 'off';
            app.NLoSLoSButton.Text = 'NLoS-LoS';
            app.NLoSNLoSButton = uiradiobutton(app.ButtonGroup_2);
            app.NLoSNLoSButton.Text = 'NLoS-NLoS';
            app.NLoSNLoSButton.Value = true;
            app.LoSNLoSButton = uiradiobutton(app.ButtonGroup_2);
            app.LoSNLoSButton.Enable = 'off';
            app.LoSNLoSButton.Text = 'LoS-NLoS';
            app.SensingmodelDropDownLabel = uilabel(app.ISACPanel);
            app.SensingmodelDropDownLabel.Text = {'感知模式'; 'Sensing model'};
            app.SensingmodelDropDown = uidropdown(app.ISACPanel);
            app.SensingmodelDropDown.Items = {'单站感知', '双站感知'};
            app.SensingmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @SensingmodelDropDownValueChanged, true);
            app.SensingmodelDropDown.Value = '双站感知';
            app.EditField_14Label_57 = uilabel(app.ISACPanel);
            app.EditField_14Label_57.Text = '簇初始数量（个）';
            app.noCluster_8 = uieditfield(app.ISACPanel, 'numeric');
            app.noCluster_8.Limits = [2 50];
            app.noCluster_8.Value = 10;
            app.EditField_15Label_20 = uilabel(app.ISACPanel);
            app.EditField_15Label_20.Text = '簇内子径数量（个）';
            app.noRaysEachCluster_9 = uieditfield(app.ISACPanel, 'numeric');
            app.noRaysEachCluster_9.Limits = [2 50];
            app.noRaysEachCluster_9.Value = 15;
            app.EditField_14Label_58 = uilabel(app.ISACPanel);
            app.EditField_14Label_58.Text = 'Initial number of clusters';
            app.EditField_15Label_21 = uilabel(app.ISACPanel);
            app.EditField_15Label_21.Text = 'Number of sub-paths within a cluster';
            app.EditField_15Label_22 = uilabel(app.ISACPanel);
            app.EditField_15Label_22.Text = '共有簇数量（个）';
            app.noRaysEachCluster_10 = uieditfield(app.ISACPanel, 'numeric');
            app.noRaysEachCluster_10.Limits = [2 50];
            app.noRaysEachCluster_10.Editable = 'off';
            app.noRaysEachCluster_10.Value = 5;
            app.EditField_15Label_23 = uilabel(app.ISACPanel);
            app.EditField_15Label_23.Text = 'Number of shared-clusters';
            app.TargettypeDropDownLabel = uilabel(app.ISACPanel);
            app.TargettypeDropDownLabel.Text = {'目标类型'; 'Target type'};
            app.TargettypeDropDown = uidropdown(app.ISACPanel);
            app.TargettypeDropDown.Items = {'汽车', '无人机', '人体', 'AGV'};
            app.TargettypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TargettypeDropDownValueChanged, true);
            app.TargettypeDropDown.Value = '汽车';
            app.ButtonGroup_txTrack = uipanel(app.ISACPanel);
            app.ButtonGroup_txTrack.Title = '感知目标运动轨迹 (Movement track of the Target)';
            app.txTrackType = uidropdown(app.ButtonGroup_txTrack);
            app.txTrackType.Items = {'static', 'random_circle', 'random', 'linear', 'circle'};
            app.txTrackType.ValueChangedFcn = createCallbackFcn(app, @txTrackTypeValueChanged2, true);
            app.txTrackType.Value = 'static';
            app.Label_33 = uilabel(app.ButtonGroup_txTrack);
            app.Label_33.Text = {'轨迹类型'; 'Track type'};
            app.txSpeed = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txSpeed.Limits = [0 100000];
            app.txSpeed.ValueChangedFcn = createCallbackFcn(app, @txSpeedValueChanged, true);
            app.msVelocitymsLabel = uilabel(app.ButtonGroup_txTrack);
            app.msVelocitymsLabel.Text = {'运动速度(m/s)'; 'Velocity(m/s)'};
            app.txAcceleration = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txAcceleration.Limits = [0 1000];
            app.ms2Accelerationms2Label = uilabel(app.ButtonGroup_txTrack);
            app.ms2Accelerationms2Label.Interpreter = 'latex';
            app.ms2Accelerationms2Label.Text = {'运动加速度(m/s$^2$)'; 'Acceleration(m/s$^2$)'};
            app.txTrackOri = uieditfield(app.ButtonGroup_txTrack, 'text');
            app.txTrackOri.Tooltip = {''};
            app.txTrackOri.Value = '[1,1,0]';
            app.DirectionLabel_5 = uilabel(app.ButtonGroup_txTrack);
            app.DirectionLabel_5.Text = {'运动方向'; 'Direction'};
            app.mInitialpositionLabel = uilabel(app.ButtonGroup_txTrack);
            app.mInitialpositionLabel.Text = {'初始位置(m)'; 'Initial position'};
            app.txInitialPos = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos.ValueChangedFcn = createCallbackFcn(app, @txInitialPosValueChanged, true);
            app.txInitialPos.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.txInitialPos_2 = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos_2.ValueChangedFcn = createCallbackFcn(app, @txInitialPos_2ValueChanged, true);
            app.txInitialPos_2.Value = 10;
            app.txInitialPos_3 = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos_3.ValueChangedFcn = createCallbackFcn(app, @txInitialPos_3ValueChanged, true);
            app.Image16 = uiimage(app.ISACPanel);
            app.Image16.ImageSource = fullfile(pathToMLAPP, 'iconsAndFigures', 'ISAC4.png');
            app.ButtonGroup_3 = uibuttongroup(app.ISACPanel);
            app.ButtonGroup_3.Title = 'RCS Setting';
            app.GHzEditFieldLabel_8 = uilabel(app.ButtonGroup_3);
            app.GHzEditFieldLabel_8.Tooltip = {'acoustic：200 Hz-50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03-0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'mmWave：6 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.GHzEditFieldLabel_8.Text = 'Mean';
            app.EditField_59 = uieditfield(app.ButtonGroup_3, 'text');
            app.EditField_59.Tooltip = {'acoustic：200 Hz-50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03-0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'mmWave：6 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.EditField_59.Value = '2.6';
            app.bandwidth_2 = uieditfield(app.ButtonGroup_3, 'text');
            app.bandwidth_2.Tooltip = {'''acoustic：0.1 kHz-10 kHz'''; '''ShortWave：0.003 - 0.004 MHz'''; '''VHF：3-30 MHz'''; '''sub-6 GHz：30 - 1000 MHz'''; '''mmWave：100 - 2000 MHz'''; '''THz：500 - 5000 MHz'''};
            app.bandwidth_2.Value = '100';
            app.GHzEditFieldLabel_9 = uilabel(app.ButtonGroup_3);
            app.GHzEditFieldLabel_9.Tooltip = {'acoustic：200 Hz-50 kHz'; 'ShortWave：0.0015 - 0.03 GHz'; 'VHF：0.03-0.45 GHz'; 'Sub-6 GHz：0.45 - 6 GHz'; 'mmWave：6 - 300 GHz'; 'THz：300 - 10000 GHz'; 'IR：10000 - 395000 GHz'; 'VL：395000 - 750000 GHz'; 'UV：750000 - 30000000 GHz'};
            app.GHzEditFieldLabel_9.Text = 'Variance';
            app.EditField_14Label_59 = uilabel(app.ISACPanel);
            app.EditField_14Label_59.Text = '目标数量';
            app.noCluster_9 = uieditfield(app.ISACPanel, 'numeric');
            app.noCluster_9.Limits = [1 50];
            app.noCluster_9.Editable = 'off';
            app.noCluster_9.Value = 1;
            app.EditField_14Label_60 = uilabel(app.ISACPanel);
            app.EditField_14Label_60.Text = 'Target number';
            app.EditField_14Label_61 = uilabel(app.ISACPanel);
            app.EditField_14Label_61.Text = '目标散射点数量';
            app.noCluster_10 = uieditfield(app.ISACPanel, 'numeric');
            app.noCluster_10.Limits = [2 50];
            app.noCluster_10.Editable = 'off';
            app.noCluster_10.Value = 10;
            app.EditField_14Label_62 = uilabel(app.ISACPanel);
            app.EditField_14Label_62.Text = 'Target scattering points number';
            app.TxtrackMenu = uicontextmenu(app.UIFigure);
            app.ConfiguremodifyantennaarrayidMenu = uimenu(app.TxtrackMenu);
            app.ConfiguremodifyantennaarrayidMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguremodifyantennaarrayidMenuSelected, true);
            app.ConfiguremodifyantennaarrayidMenu.Text = '为所选用户配置/修改天线阵列(Configure/modify antenna array id)';
            app.ConfiguremodifymotiontrajectoryMenu_2 = uimenu(app.TxtrackMenu);
            app.ConfiguremodifymotiontrajectoryMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ConfiguremodifymotiontrajectoryMenu_2Selected, true);
            app.ConfiguremodifymotiontrajectoryMenu_2.Text = '为所选用户配置/修改运动轨迹(Configure/modify motion trajectory)';
            app.DeleteMenu = uimenu(app.TxtrackMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = '删除(Delete)';
            % Assign app.TxtrackMenu
            app.UITable_tx.ContextMenu = app.TxtrackMenu;
            app.RxtrackMenu = uicontextmenu(app.UIFigure);
            app.ConfiguremodifyantennaarrayidMenu_2 = uimenu(app.RxtrackMenu);
            app.ConfiguremodifyantennaarrayidMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ConfiguremodifyantennaarrayidMenu_2Selected, true);
            app.ConfiguremodifyantennaarrayidMenu_2.Text = '为所选用户配置/修改天线阵列(Configure/modify antenna array id)';
            app.ConfiguremodifymotiontrajectoryMenu = uimenu(app.RxtrackMenu);
            app.ConfiguremodifymotiontrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguremodifymotiontrajectoryMenuSelected, true);
            app.ConfiguremodifymotiontrajectoryMenu.Text = '为所选用户配置/修改运动轨迹(Configure/modify motion trajectory)';
            app.DeleteMenu_2 = uimenu(app.RxtrackMenu);
            app.DeleteMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenu_2Selected, true);
            app.DeleteMenu_2.Text = '删除(Delete)';
            % Assign app.RxtrackMenu
            app.UITable_rx.ContextMenu = app.RxtrackMenu;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditselectedantennaarrayMenu = uimenu(app.ContextMenu);
            app.EditselectedantennaarrayMenu.MenuSelectedFcn = createCallbackFcn(app, @EditselectedantennaarrayMenuSelected, true);
            app.EditselectedantennaarrayMenu.Text = '编辑天线 (Edit selected antenna array)';
            app.DeleteselectedantennaarrayMenu = uimenu(app.ContextMenu);
            app.DeleteselectedantennaarrayMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteselectedantennaarrayMenuSelected, true);
            app.DeleteselectedantennaarrayMenu.Text = '删除天线 (Delete selected antenna array)';
            % Assign app.ContextMenu
            app.UITable_antenna.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_6GPCM_GBSM

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end