% description: 如果您使用本软件，可引用以下论文:
% For any researchers using the simulator or its modified versions in your scientific work, please cite the following papers:
% [1] C.-X. Wang, Z. Lv, X. Gao, X. You, Y. Hao, and H. Haas, “Pervasive wireless channel modeling theory and applications to 6G GBSMs for all frequency bands and all scenarios,” IEEE Trans. Veh.
% Technol., vol. 71, no. 9, pp. 9159-9173, Sept. 2022.
% [2] C.-X. Wang, Z. Lv, Y. Chen, and H. Haas, “A complete study of space-time-frequency statistical properties of the 6G pervasive channel model,” IEEE Trans. Commun., vol. 71, no. 12, pp. 7273-7287,
% Dec. 2023.
classdef start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GSEUPML6GPCSUIFigure  matlab.ui.Figure
        LoginPanel            matlab.ui.container.Panel
        Button_6              matlab.ui.control.Button
        LoginButton           matlab.ui.control.Button
        PasswordField_2       matlab.ui.control.EditField
        PasswordLabel         matlab.ui.control.Label
        UserNameField_2       matlab.ui.control.EditField
        UsenameLabel_2        matlab.ui.control.Label
        RegisterPanel         matlab.ui.container.Panel
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        email                 matlab.ui.control.EditField
        UsenameLabel_5        matlab.ui.control.Label
        editFieldOther        matlab.ui.control.EditField
        JobtitleDropDown      matlab.ui.control.DropDown
        Label                 matlab.ui.control.Label
        Affiliation           matlab.ui.control.EditField
        UsenameLabel_4        matlab.ui.control.Label
        NameField             matlab.ui.control.EditField
        UsenameLabel_3        matlab.ui.control.Label
        Button_5              matlab.ui.control.Button
        RegisterButton        matlab.ui.control.Button
        PasswordField         matlab.ui.control.EditField
        PasswordLabel_2       matlab.ui.control.Label
        UserNameField         matlab.ui.control.EditField
        UsenameLabel          matlab.ui.control.Label
        RegisterButton_2      matlab.ui.control.Button
        LoginButton_2         matlab.ui.control.Button
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        selectedTitle = '教授/研究员(Professor)'; % Description
    end
    methods (Access = private)       
        function Showpanel(app)
            app.RegisterPanel.Position = [243,144,385,416];
            % 显示面板
            app.RegisterPanel.Visible = 'on';
        end
        
        function Hidepanel(app)
             app.RegisterPanel.Visible = 'off'; % 隐藏面板
        end

        function  startupFcn(app)
            app.UIFigure.Name = '东南大学-紫金山实验室-6G普适信道仿真器(SEU-PML-6GPCS) v3.0';

            % 初始化代码
            app.PasswordField.MaskCharacter = '*'; % 设置密码字段的掩码字符
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonStartPushed(app, event)
            delete(app.GSEUPML6GPCSUIFigure);
            main_6GPCM_GBSM;
        end

        % Button pushed function: RegisterButton_2
        function RegisterButton_2Pushed(app, event)
            try
                Showpanel(app);
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                return;
            end
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            try
                userName = app.UserNameField.Value; %
                password = app.PasswordField.Value; %
                peoplename=app.NameField.Value;
                affiliation=app.Affiliation.Value;
                jobtitle=app.selectedTitle;
                useremail=app.email.Value;
                % 读取mac码
                [~,macInfo] = dos('getmac');
                pattern = '\w\w-\w\w-\w\w-\w\w-\w\w-\w\w';
                macCode = regexp(macInfo,pattern,'match');
                macCode = cell2mat(macCode);
                if tools.isValidEmail(useremail)
                    % 如果电子邮件有效，可以在这里执行更多操作             
                
                    if isempty(userName) || isempty(password)|| isempty(peoplename)|| isempty(affiliation)|| isempty(jobtitle)|| isempty(useremail)
                        uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
                        return;
                    elseif isempty(regexp([userName, userName], '^[a-zA-Z0-9]*$', 'once'))
                        % 输入不是纯数字和字母
                        app.PasswordField_2.Value = ''; % 清空输入或设置为上一个有效值
                        uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', '输入错误');
                        return;
                    end
    
                    % 创建进度对话框
                    dlg = uiprogressdlg(app.GSEUPML6GPCSUIFigure,'Title', 'Please wait a moment',...
                        'Message','Connectioning database...','Indeterminate','on','Cancelable', 'off');
    
                    % 连接数据库
                    conn = databaseConnection();
    
                    if ~isempty(conn.Message)
                        close(dlg); % 关闭进度对话框
                        uialert(app.GSEUPML6GPCSUIFigure, ['Connection error: ' conn.Message], 'Error');
                        return;
                    end
    
                    % 加密密码
                    encryptedPassword = tools.encryptPassword(password);
                    
                    %加密信息
                    

                    [encryptedpeoplename] = tools.encryptData(peoplename);
                    [encryptedaffiliation] = tools.encryptData(affiliation);
                    [encryptedjobtitle] = tools.encryptData(jobtitle);
                    [encrypteduseremail] = tools.encryptData(useremail);

    
                    % 检查用户名是否存在
                    % 更新进度对话框
    
                    dlg.Message = 'Registering ...';
                    checkUserQuery = sprintf('SELECT user_name FROM user WHERE user_name = "%s"', userName);
                    curs = exec(conn, checkUserQuery);
                    curs = fetch(curs);
                    if ~isempty(curs.Message)
                        close(dlg); % 关闭进度对话框
                        uialert(app.GSEUPML6GPCSUIFigure, ['Query error: ' curs.Message], 'Error');
                        return;
                    end
    
                    % 如果curs.Data是'No Data'，则表示未找到用户
                    % ... [用户名检查代码] ...
    
                    dlg.Message = 'Registering...';
                    if isequal(curs.Data, {'No Data'})
                        % insertUserQuery = sprintf('INSERT INTO user (user_name, password, initial_time,name,affiliation,job_title,user_email) VALUES ("%s", "%s", NOW(),"%s", "%s","%s", "%s")', userName, encryptedPassword,peoplename,affiliation,jobtitle,useremail);
                        insertUserQuery = sprintf('INSERT INTO user (user_name, password, initial_time,name,affiliation,job_title,user_email,mac_code,version) VALUES ("%s", "%s", NOW(),"%s", "%s","%s", "%s", "%s","v3")', userName, encryptedPassword,encryptedpeoplename,encryptedaffiliation,encryptedjobtitle,encrypteduseremail,macCode);
                        curs = exec(conn, insertUserQuery);
                        if ~isempty(curs.Message)
                            close(dlg); % 关闭进度对话框
                            uialert(app.GSEUPML6GPCSUIFigure, ['Insert error: ' curs.Message], 'Error');
                            return;
                        end
                        disp('Insert success');
    
                        % 关闭进度对话框
                        close(dlg);
                        % 提示用户注册成功
                        uiwait(msgbox('Registered successfully！', 'Success'));
                        Hidepanel(app);
                    else
                        % 用户名已存在
                        uialert(app.GSEUPML6GPCSUIFigure, 'The username already exists, please reset', 'Error');
                    end
    
                    % 关闭数据库连接
                    close(conn);
                else
                    % 如果电子邮件无效，显示一个错误信息或执行其他操作
                    uialert(app.GSEUPML6GPCSUIFigure, 'Invalid email format', 'Error');
                end
            catch exception
                message = regexprep(exception.message, '://.*', '');
                errordlg(message, 'error: 400',"modal");
                if isopen(conn)
                    close(conn);
                end
                return;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            Hidepanel(app);
        end

        % Button pushed function: LoginButton_2
        function LoginButton_2Pushed(app, event)
            try
                % 设置面板的新位置
                app.LoginPanel.Position = [240,178,343,267];
                % 显示面板
                app.LoginPanel.Visible = 'on';
            catch exception
                message = regexprep(exception.message, '://.*', '');
                errordlg(message, 'error: 400',"modal");
                return;
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             app.LoginPanel.Visible = 'off'; % 隐藏面板
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            try
                userName = app.UserNameField_2.Value; % 用户名字段
                password = app.PasswordField_2.Value; % 密码字段
                % 读取mac码
                [~,macInfo] = dos('getmac');
                pattern = '\w\w-\w\w-\w\w-\w\w-\w\w-\w\w\s*\\Device\\Tcpip';
                macInfo = regexp(macInfo,pattern,'match');
                pattern = '\w\w-\w\w-\w\w-\w\w-\w\w-\w\w';
                macCode = regexp(macInfo,pattern,'match');
                
                if isempty(macCode)
                    uialert(app.GSEUPML6GPCSUIFigure, ['Get MAC code error: ', macInfo], 'Error');
                    return;
                end

                macCode = macCode{1};

                if isempty(userName) || isempty(password)
                    uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
                    return;
                elseif isempty(regexp([userName, userName], '^[a-zA-Z0-9]*$', 'once'))
                    % 输入不是纯数字和字母
                    app.PasswordField_2.Value = ''; % 清空输入或设置为上一个有效值
                    uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', '输入错误');
                    return;
                end

                % 创建进度对话框
                dlg = uiprogressdlg(app.GSEUPML6GPCSUIFigure,'Title','Please wait a moment',...
                    'Message','Connectioning database...','Indeterminate','on','Cancelable', 'off');

                % 连接数据库
                conn = databaseConnection();
                if ~isempty(conn.Message)
                    close(dlg);
                    uialert(app.GSEUPML6GPCSUIFigure, ['Connection failed: ' conn.Message], 'Error');
                    return;
                end
                dlg.Message = 'Verifying username and password ...';

                % 对用户输入的密码进行哈希加密
                encryptedPassword = tools.encryptPassword(password);

                % 验证用户名和密码
                loginQuery = sprintf('SELECT user_id, mac_code FROM user WHERE user_name = "%s" AND password = "%s"', userName, encryptedPassword);
                curs = exec(conn, loginQuery);
                curs = fetch(curs);
%                 disp(curs.Data); % 查看返回的数据
                if ~isempty(curs.Message)
                    close(dlg);
                    uialert(app.GSEUPML6GPCSUIFigure, ['Query error: ' curs.Message], 'Error');
                    return;
                end

                if isequal(curs.Data, {'No Data'})
                    % 没有数据返回，登录失败
                    close(dlg);
                    uialert(app.GSEUPML6GPCSUIFigure, 'Incorrect username or password！', 'Error');
                else
                    macCodeList = curs.Data{2};
                    if contains(macCodeList,macCode)
                        dlg.Message = 'Logining ...';
                        % 数据返回，登录成功
                        userId = curs.Data{1};
                        userId = num2str(userId);
                        % 记录登录时间
                        logLoginQuery = sprintf('INSERT INTO login (user_id, log_time, log_version) VALUES ("%s", NOW(), "v3")', userId);
                        exec(conn, logLoginQuery);
    
                        % 提示用户登录成功
                        uiwait(msgbox('Login successfully！', 'Success'));
                        pause(2);
                        delete(app.GSEUPML6GPCSUIFigure);
    
                        mainApp = main_6GPCM_GBSM;
                        if isvalid(mainApp)
                            % 设置 initialTime
                            mainApp.setuserid(userId);
                        end
                        % 关闭数据库连接
                        close(conn);
                    else
                        close(dlg);
                        uialert(app.GSEUPML6GPCSUIFigure, 'This account has been bound to a device, please re-register the account!', 'Error');
                    end                 
                end
            catch exception
                errordlg(exception.message, 'error: 400',"modal");
                if isopen(conn)
                    close(conn);
                end
                return;
            end
        end

        % Value changed function: UserNameField
        function UserNameFieldValueChanged(app, event)
            newValue = app.UserNameField.Value;
            if isempty(newValue)
                uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
            elseif isempty(regexp(newValue, '^[a-zA-Z0-9]*$', 'once'))
                % 输入不是纯数字和字母
                app.UserNameField.Value = ''; % 清空输入或设置为上一个有效值
                uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', 'error');
            end
        end

        % Value changed function: PasswordField_2
        function PasswordField_2ValueChanged(app, event)
            newValue = app.PasswordField_2.Value;
            if isempty(newValue)
                uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
            elseif isempty(regexp(newValue, '^[a-zA-Z0-9]*$', 'once'))
                % 输入不是纯数字和字母
                app.PasswordField_2.Value = ''; % 清空输入或设置为上一个有效值
                uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', '输入错误');
            end
        end

        % Value changed function: PasswordField
        function PasswordFieldValueChanged(app, event)
            newValue = app.PasswordField.Value;
            if isempty(newValue)
                uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
            elseif isempty(regexp(newValue, '^[a-zA-Z0-9]*$', 'once'))
                % 输入不是纯数字和字母
                app.PasswordField.Value = ''; % 清空输入或设置为上一个有效值
                uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', 'error');
            end
            
        end

        % Value changed function: UserNameField_2
        function UserNameField_2ValueChanged(app, event)
            newValue = app.UserNameField_2.Value;
            if isempty(newValue)
                uialert(app.GSEUPML6GPCSUIFigure, 'can not be empty', 'error');
            elseif isempty(regexp(newValue, '^[a-zA-Z0-9]*$', 'once'))
                % 输入不是纯数字和字母
                app.UserNameField_2.Value = ''; % 清空输入或设置为上一个有效值
                uialert(app.GSEUPML6GPCSUIFigure, 'only numbers and letters are suppoted！', 'error');
            end
        end

        % Value changed function: JobtitleDropDown
        function JobtitleDropDownValueChanged(app, event)
            app.selectedTitle = app.JobtitleDropDown.Value;

            % 检查是否选择了“其他”
            if strcmp(app.selectedTitle, '其他(Other)')
                app.editFieldOther.Visible = 'on'; % 显示输入框
                app.selectedTitle= app.editFieldOther.Value;
            else
                app.editFieldOther.Visible = 'off'; % 隐藏输入框
            end
            
        end

        % Callback function
        function JobtitleDropDownClicked(app, event)
            app.selectedTitle = app.JobtitleDropDown.Value;

            % 检查是否选择了“其他”
            if strcmp(app.selectedTitle, '其他(Other)')
                app.editFieldOther.Visible = 'on'; % 显示输入框
                app.selectedTitle= app.editFieldOther.Value;
            else
                app.editFieldOther.Visible = 'off'; % 隐藏输入框
            end
            
        end

        % Drop down opening function: JobtitleDropDown
        function JobtitleDropDownOpening(app, event)
            
        end

        % Value changed function: editFieldOther
        function editFieldOtherValueChanged(app, event)
            app.selectedTitle = app.editFieldOther.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GSEUPML6GPCSUIFigure = uifigure('Visible', 'off');
            app.GSEUPML6GPCSUIFigure.Name = '东南大学-紫金山实验室-6G普适信道仿真器(SEU-PML-6GPCS)';
            app.GSEUPML6GPCSUIFigure.Icon = 'icon.png';
            app.Image = uiimage(app.GSEUPML6GPCSUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconsAndFigures', 'start.jpg');
            app.LoginButton_2 = uibutton(app.GSEUPML6GPCSUIFigure, 'push');
            app.LoginButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_2Pushed, true);
            app.LoginButton_2.Text = '登录 Login';
            app.RegisterButton_2 = uibutton(app.GSEUPML6GPCSUIFigure, 'push');
            app.RegisterButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegisterButton_2Pushed, true);
            app.RegisterButton_2.Text = '注册 Register';
            app.RegisterPanel = uipanel(app.GSEUPML6GPCSUIFigure);
            app.RegisterPanel.Title = '注册(Register)';
            app.UsenameLabel = uilabel(app.RegisterPanel);
            app.UsenameLabel.Text = '用户名(Usename)';
            app.UserNameField = uieditfield(app.RegisterPanel, 'text');
            app.UserNameField.ValueChangedFcn = createCallbackFcn(app, @UserNameFieldValueChanged, true);
            app.PasswordLabel_2 = uilabel(app.RegisterPanel);
            app.PasswordLabel_2.Text = '密码(Password)';
            app.PasswordField = uieditfield(app.RegisterPanel, 'text');
            app.PasswordField.ValueChangedFcn = createCallbackFcn(app, @PasswordFieldValueChanged, true);
            app.RegisterButton = uibutton(app.RegisterPanel, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = '注册 Register';
            app.Button_5 = uibutton(app.RegisterPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '×';
            app.UsenameLabel_3 = uilabel(app.RegisterPanel);
            app.UsenameLabel_3.Text = '姓名(Name)';
            app.NameField = uieditfield(app.RegisterPanel, 'text');
            app.UsenameLabel_4 = uilabel(app.RegisterPanel);
            app.UsenameLabel_4.Text = '单位(Affiliation)';
            app.Affiliation = uieditfield(app.RegisterPanel, 'text');
            app.Label = uilabel(app.RegisterPanel);
            app.Label.Text = '职称(Job title)';
            app.JobtitleDropDown = uidropdown(app.RegisterPanel);
            app.JobtitleDropDown.Items = {'教授/研究员(Professor)', '副教授/副研究员(Associate professor)', '助理教授/助理研究员(Assistant professor)', '工程师(Engineer)', '学生(Student)', '其他(Other)'};
            app.JobtitleDropDown.DropDownOpeningFcn = createCallbackFcn(app, @JobtitleDropDownOpening, true);
            app.JobtitleDropDown.ValueChangedFcn = createCallbackFcn(app, @JobtitleDropDownValueChanged, true);
            app.JobtitleDropDown.Value = '教授/研究员(Professor)';
            app.editFieldOther = uieditfield(app.RegisterPanel, 'text');
            app.editFieldOther.ValueChangedFcn = createCallbackFcn(app, @editFieldOtherValueChanged, true);
            app.editFieldOther.Visible = 'off';
            app.UsenameLabel_5 = uilabel(app.RegisterPanel);
            app.UsenameLabel_5.Text = '邮箱(E-mail)';
            app.email = uieditfield(app.RegisterPanel, 'text');
            app.Label_2 = uilabel(app.RegisterPanel);
            app.Label_2.Text = {'1. 请妥善管理用户名和密码，系统无法获取您设置的密码内容'; '2. 一个帐户只能绑定一个设备'};
            app.Label_3 = uilabel(app.RegisterPanel);
            app.Label_3.Text = {'1. Please manage your username and password properly as the '; 'software is unable to retrieve the password you have set.'; '2.One account can only be bound to one device.'};
            app.LoginPanel = uipanel(app.GSEUPML6GPCSUIFigure);
            app.LoginPanel.Title = '登录(Login)';
            app.UsenameLabel_2 = uilabel(app.LoginPanel);
            app.UsenameLabel_2.Text = '用户名(Usename)';
            app.UserNameField_2 = uieditfield(app.LoginPanel, 'text');
            app.UserNameField_2.ValueChangedFcn = createCallbackFcn(app, @UserNameField_2ValueChanged, true);
            app.PasswordLabel = uilabel(app.LoginPanel);
            app.PasswordLabel.Text = '密码(Password)';
            app.PasswordField_2 = uieditfield(app.LoginPanel, 'text');
            app.PasswordField_2.ValueChangedFcn = createCallbackFcn(app, @PasswordField_2ValueChanged, true);
            app.LoginButton = uibutton(app.LoginPanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = '登录 Login';
            app.Button_6 = uibutton(app.LoginPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '×';
            % Show the figure after all components are created
            app.GSEUPML6GPCSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GSEUPML6GPCSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GSEUPML6GPCSUIFigure)
        end
    end
end