classdef track_Rx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        sampleRate               matlab.ui.control.NumericEditField
        trackLength              matlab.ui.control.NumericEditField
        txInitialPos_3           matlab.ui.control.NumericEditField
        txInitialPos_2           matlab.ui.control.NumericEditField
        txInitialPos_1           matlab.ui.control.NumericEditField
        ButtonGroup_rxTrack      matlab.ui.container.Panel
        drawback                 matlab.ui.control.Button
        NameofRxEditField        matlab.ui.control.EditField
        EditField_2Label_2       matlab.ui.control.Label
        RxPosVisualize           matlab.ui.control.Button
        rxInitialPos_1           matlab.ui.control.NumericEditField
        mInitialpositionLabel    matlab.ui.control.Label
        rxInitialPos_3           matlab.ui.control.NumericEditField
        rxInitialPos_2           matlab.ui.control.NumericEditField
        rxTrackOri               matlab.ui.control.EditField
        DirectionLabel           matlab.ui.control.Label
        rxAcceleration           matlab.ui.control.NumericEditField
        ms2Accelerationms2Label  matlab.ui.control.Label
        rxSpeed                  matlab.ui.control.NumericEditField
        msVelocityLabel          matlab.ui.control.Label
        rxTrackType              matlab.ui.control.DropDown
        TracktypeLabel           matlab.ui.control.Label
        windSpeed_note           matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingapp2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, number, name, azimuth, elevation, identity)
            app.callingapp2 = mainapp;
            currentRowCount = size(app.callingapp2.UITable_rx.Data, 1);
            tag_scen = app.callingapp2.Tree_2.SelectedNodes.Tag;
            tools.setAppScenarios(app.callingapp2)
            
            app.callingapp2.sps.setScenario(strcat(app.callingapp2.scenarios));
            if contains(tag_scen, app.callingapp2.sps.scenario_UHST)
                app.rxTrackType.Items = {'linear'};
                app.rxInitialPos_1.Limits = [-Inf,0];
                app.rxInitialPos_1.Value = -1000;
                app.rxInitialPos_2.Value = 0;
                app.rxInitialPos_2.Editable = 'off';
                scen_para = get_scen_para_scenario(app.callingapp2, app.callingapp2.scenarios);
                app.rxInitialPos_3.Limits = [0, scen_para.R];
                app.rxTrackOri.Value = '[1,0,0]';
                app.rxTrackOri.Editable = 'off';
                app.rxSpeed.Editable = 'on';
                app.rxAcceleration.Editable = 'on';
                app.rxSpeed.Value = 100;
                app.rxAcceleration.Value = 1;
            end

            if contains(tag_scen, app.callingapp2.sps.scenario_UWA)
                app.rxInitialPos_1.Value = app.callingapp2.Distance_tx_rx.Value;
                app.rxInitialPos_2.Value = 0;
                app.rxInitialPos_3.Value = app.callingapp2.rxDepth.Value;
            end
            
            % 新行的行号，用于txNumberStr和NameofTxEditField的默认值
            newRowNumber = currentRowCount + 1;
            nameOfRx = ['point_', num2str(newRowNumber)];
            app.NameofRxEditField.Value = nameOfRx;
        end

        % Button pushed function: drawback
        function drawbackButtonPushed(app, event)
            [~,initialPosRx] = tools.get_initialPos_trans(app);
            % 确保所有元素都是字符串
            % 计算当前表格的行数
            currentRowCount = size(app.callingapp2.UITable_rx.Data, 1);

            % 生成新行的行号，作为第一个值
            rxNumberStr = num2str(currentRowCount + 1);
            initialPosRxStr = tools.convertToString(initialPosRx);
            initialPosRxStr = ['[',initialPosRxStr,']'];
            nameOfRx = tools.convertToString(app.NameofRxEditField.Value);
            trackTypeStr = tools.convertToString(app.rxTrackType.Value);
            rxSpeedStr = tools.convertToString(app.rxSpeed.Value);
            rxAccelerationStr = tools.convertToString(app.rxAcceleration.Value);
            rxTrackOriStr = tools.convertToString(app.rxTrackOri.Value);

            % 创建新行数据
            team = {rxNumberStr, nameOfRx, initialPosRxStr, '', trackTypeStr, rxSpeedStr, rxAccelerationStr, rxTrackOriStr};
            teamTable = cell2table(team);  
            newColumnNames = teamTable.Properties.VariableNames;  
  
            % 更新现有表格的列名以匹配 teamTable 的列名  
            app.callingapp2.UITable_rx.Data.Properties.VariableNames = newColumnNames; 
            app.callingapp2.UITable_rx.Data = [app.callingapp2.UITable_rx.Data; teamTable];

            app.callingapp2.settingButton_2.Enable = 'on';
            delete(app);
        end

        % Button pushed function: RxPosVisualize
        function RxPosVisualizeButtonPushed(app, event)
            [~, initialPosRx] = tools.get_initialPos_trans(app);

            trackOriRx = mf.str2positions(app.rxTrackOri.Value);
            rx_track = track(app.rxTrackType.Value, app.callingapp2.trackLength.Value, app.rxSpeed.Value, app.rxAcceleration.Value, initialPosRx, trackOriRx, app.callingapp2.sampleRate.Value);

            figure();
            scatter3(rx_track.positions(:,1,:), rx_track.positions(:,2,:), rx_track.positions(:,3,:), 'MarkerEdgeColor','r', 'MarkerFaceColor','r');
            title('\fontsize{16}The motion trajectory diagram of the Rx(m)');
            xlabel('x-axis/(m)')
            ylabel('y-axis/(m)')
            zlabel('z-axis/(m)')
        end

        % Value changed function: rxTrackType
        function rxTrackTypeValueChanged(app, event)
            if contains(app.rxTrackType.Value, 'static', 'IgnoreCase',true)
                app.rxSpeed.Value = 0;
                app.rxAcceleration.Value = 0;
                app.rxSpeed.Editable = 'off';
                app.rxAcceleration.Editable = 'off';
            else
                app.rxSpeed.Value = 5;
                app.rxAcceleration.Value = 1;
                app.rxSpeed.Editable = 'on';
                app.rxAcceleration.Editable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp2.settingButton_2.Enable='on';
            delete(app);
            
        end

        % Drop down opening function: rxTrackType
        function rxTrackTypeDropDownOpening(app, event)
            tools.check_freqAndScensChosen(app.callingapp2);
            tag_scen = app.callingapp2.Tree_2.SelectedNodes.Tag;
            %tag_freq = app.callingapp1.Tree.SelectedNodes.Tag;  
            if contains(tag_scen, app.callingapp2.sps.scenario_MARITIME_ship2ship)
                app.rxTrackType.Items = {'shipStatic', 'shipLinear'};
            elseif contains(tag_scen, app.callingapp2.sps.scenario_MARITIME_ship2land)
                app.rxTrackType.Items = {'static', 'random_circle', 'random', 'linear', 'circle'};
            elseif contains(tag_scen, app.callingapp2.sps.scenario_UHST)
                app.rxTrackType.Items = {'linear'};
            elseif contains(tag_scen, app.callingapp2.sps.scenario_UWA)
                app.rxTrackType.Items = {'static','linear','shipStatic', 'shipLinear'};
            else
                app.rxTrackType.Items = {'static', 'random_circle', 'random', 'linear', 'circle'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup_rxTrack = uipanel(app.UIFigure);
            app.ButtonGroup_rxTrack.Title = '接收端运动轨迹 (Movement track of the Rx)';
            app.windSpeed_note = uilabel(app.ButtonGroup_rxTrack);
            app.windSpeed_note.Text = {'When the track type is satellite, the initial position of the receiver is [longitude,latitude, '; 'altitude].The altitude can be ignored compared with the satellite altitude when modeling.'};
            app.TracktypeLabel = uilabel(app.ButtonGroup_rxTrack);
            app.TracktypeLabel.Text = {'轨迹类型'; 'Track type'};
            app.rxTrackType = uidropdown(app.ButtonGroup_rxTrack);
            app.rxTrackType.Items = {'static', 'linear', 'circle', 'random', 'random_circle'};
            app.rxTrackType.DropDownOpeningFcn = createCallbackFcn(app, @rxTrackTypeDropDownOpening, true);
            app.rxTrackType.ValueChangedFcn = createCallbackFcn(app, @rxTrackTypeValueChanged, true);
            app.rxTrackType.Value = 'static';
            app.msVelocityLabel = uilabel(app.ButtonGroup_rxTrack);
            app.msVelocityLabel.Text = {'运动速度(m/s)'; 'Velocity(m/s)'};
            app.rxSpeed = uieditfield(app.ButtonGroup_rxTrack, 'numeric');
            app.rxSpeed.Limits = [0 1000];
            app.ms2Accelerationms2Label = uilabel(app.ButtonGroup_rxTrack);
            app.ms2Accelerationms2Label.Interpreter = 'latex';
            app.ms2Accelerationms2Label.Text = {'运动加速度(m/s$^2$)'; 'Acceleration(m/s$^2$)'};
            app.rxAcceleration = uieditfield(app.ButtonGroup_rxTrack, 'numeric');
            app.rxAcceleration.Limits = [0 1000];
            app.DirectionLabel = uilabel(app.ButtonGroup_rxTrack);
            app.DirectionLabel.Text = {'运动方向'; 'Direction'};
            app.rxTrackOri = uieditfield(app.ButtonGroup_rxTrack, 'text');
            app.rxTrackOri.Value = '[1,1,0]';
            app.rxInitialPos_2 = uieditfield(app.ButtonGroup_rxTrack, 'numeric');
            app.rxInitialPos_2.Value = 10;
            app.rxInitialPos_3 = uieditfield(app.ButtonGroup_rxTrack, 'numeric');
            app.mInitialpositionLabel = uilabel(app.ButtonGroup_rxTrack);
            app.mInitialpositionLabel.Text = {'初始位置(m)'; 'Initial position'};
            app.rxInitialPos_1 = uieditfield(app.ButtonGroup_rxTrack, 'numeric');
            app.rxInitialPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.rxInitialPos_1.Value = 20;
            app.RxPosVisualize = uibutton(app.ButtonGroup_rxTrack, 'push');
            app.RxPosVisualize.ButtonPushedFcn = createCallbackFcn(app, @RxPosVisualizeButtonPushed, true);
            app.RxPosVisualize.Text = '预览 (Preview)';
            app.EditField_2Label_2 = uilabel(app.ButtonGroup_rxTrack);
            app.EditField_2Label_2.Text = {'接收端名称'; 'Name of Rx'};
            app.NameofRxEditField = uieditfield(app.ButtonGroup_rxTrack, 'text');
            app.drawback = uibutton(app.ButtonGroup_rxTrack, 'push');
            app.drawback.ButtonPushedFcn = createCallbackFcn(app, @drawbackButtonPushed, true);
            app.drawback.Text = '保存并关闭（Save and Close）';
            app.txInitialPos_1 = uieditfield(app.UIFigure, 'numeric');
            app.txInitialPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.txInitialPos_2 = uieditfield(app.UIFigure, 'numeric');
            app.txInitialPos_2.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.txInitialPos_3 = uieditfield(app.UIFigure, 'numeric');
            app.txInitialPos_3.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.trackLength = uieditfield(app.UIFigure, 'numeric');
            app.trackLength.Limits = [1e-06 1000];
            app.trackLength.Value = 0.01;
            app.sampleRate = uieditfield(app.UIFigure, 'numeric');
            app.sampleRate.Limits = [1 1000000];
            app.sampleRate.Value = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Rx(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end