classdef track_Rx_straight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        straightlinesettingPanel      matlab.ui.container.Panel
        saveclose                     matlab.ui.control.Button
        previewtx                     matlab.ui.control.Button
        mInitialpositionsettingLabel  matlab.ui.control.Label
        mInitialpositionLabel_2       matlab.ui.control.Label
        rxInitialPos_4                matlab.ui.control.NumericEditField
        rxInitialPos_6                matlab.ui.control.NumericEditField
        rxInitialPos_5                matlab.ui.control.NumericEditField
        rxInitialPos_1                matlab.ui.control.NumericEditField
        mInitialpositionLabel         matlab.ui.control.Label
        rxInitialPos_3                matlab.ui.control.NumericEditField
        rxInitialPos_2                matlab.ui.control.NumericEditField
        Rxnum                         matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;  % Description
        rx_positions;
    end
    
    methods (Access = private)
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
             app.CallingApp = mainApp;
             rxUser_num= size(app.CallingApp.UITable_rx.Data,1);
             if app.CallingApp.user_number-rxUser_num==2
                 app.Rxnum.Value = 2;
                 app.Rxnum.Editable = "off";
             else
                 app.Rxnum.Limits = [2 app.CallingApp.user_number-rxUser_num];
             end
        end

        % Button pushed function: previewtx
        function previewtxButtonPushed(app, event)
          
            N = app.Rxnum.Value; % 获取发送端数量

            % 获取起点和终点坐标
            start_point = [app.rxInitialPos_1.Value, app.rxInitialPos_2.Value, app.rxInitialPos_3.Value];
            end_point = [app.rxInitialPos_4.Value, app.rxInitialPos_5.Value, app.rxInitialPos_6.Value];

            % 计算均匀分布的发送端坐标
            app.rx_positions = zeros(N, 3);
            for i = 1:N
                app.rx_positions(i, :) = start_point + (i-1) / (N-1) * (end_point - start_point);
            end

            % 绘制发送端位置
            plot3(app.UIAxes, app.rx_positions(:,1), app.rx_positions(:,2), app.rx_positions(:,3), 'o');
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            title(app.UIAxes, 'Uniform Linear Distribution of Transmitters');

        end

        % Button pushed function: saveclose
        function savecloseButtonPushed(app, event)
            N = app.Rxnum.Value; % 获取发送端数量

            % 获取起点和终点坐标
            start_point = [app.rxInitialPos_1.Value, app.rxInitialPos_2.Value, app.rxInitialPos_3.Value];
            end_point = [app.rxInitialPos_4.Value, app.rxInitialPos_5.Value, app.rxInitialPos_6.Value];

            % 计算均匀分布的发送端坐标
            app.rx_positions = zeros(N, 3);
            for i = 1:N
                app.rx_positions(i, :) = start_point + (i-1) / (N-1) * (end_point - start_point);
            end

            % 初始化新数据
%             totalRows = size(app.CallingApp.UITable_tx.Data, 1) + N;
            nums = arrayfun(@num2str, (1:N) + size(app.CallingApp.UITable_rx.Data, 1), 'UniformOutput', false);
            names = arrayfun(@(i) ['straight_' num2str(i)], 1:N, 'UniformOutput', false);
            coords = arrayfun(@(i) mat2str(app.rx_positions(i, :)), 1:N, 'UniformOutput', false);
            emptyCols = repmat({''}, N, 1);

            % 创建新行的数据
            team = [nums' names' coords' emptyCols emptyCols emptyCols emptyCols emptyCols];
            teamTable = cell2table(team);  
            newColumnNames = teamTable.Properties.VariableNames;  
  
            % 更新现有表格的列名以匹配 teamTable 的列名  
            app.CallingApp.UITable_rx.Data.Properties.VariableNames = newColumnNames;  
            app.CallingApp.UITable_rx.Data = [app.CallingApp.UITable_rx.Data; teamTable];
            app.CallingApp.settingButton_2.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.settingButton_2.Enable = 'on';
            delete(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest2(app, event)
            app.CallingApp.settingButton_2.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest2, true);
            app.straightlinesettingPanel = uipanel(app.UIFigure);
            app.straightlinesettingPanel.Title = '接收端直线设置(straight line setting)';
            app.UIAxes = uiaxes(app.straightlinesettingPanel);
            title(app.UIAxes, '初始位置预览(Preview Initial Position)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_3 = uilabel(app.straightlinesettingPanel);
            app.Label_3.Text = {'接收端的用户数量 :  '; 'Number of  Rx users :  '};
            app.Rxnum = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.Rxnum.Limits = [1 10];
            app.Rxnum.RoundFractionalValues = 'on';
            app.Rxnum.Value = 2;
            app.rxInitialPos_2 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_3 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_3.Value = 20;
            app.mInitialpositionLabel = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionLabel.Text = {'起点'; 'The Starting'};
            app.rxInitialPos_1 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.rxInitialPos_5 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_5.Value = 10;
            app.rxInitialPos_6 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_6.Value = 20;
            app.rxInitialPos_4 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.rxInitialPos_4.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.mInitialpositionLabel_2 = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionLabel_2.Text = {'终点'; 'The Ending'};
            app.mInitialpositionsettingLabel = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionsettingLabel.Text = {'初始位置设置(m)'; 'Initial position setting'};
            app.previewtx = uibutton(app.straightlinesettingPanel, 'push');
            app.previewtx.ButtonPushedFcn = createCallbackFcn(app, @previewtxButtonPushed, true);
            app.previewtx.Text = '预览初始位置(Preview)';
            app.saveclose = uibutton(app.straightlinesettingPanel, 'push');
            app.saveclose.ButtonPushedFcn = createCallbackFcn(app, @savecloseButtonPushed, true);
            app.saveclose.Text = '保存并关闭(Save and Close)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Rx_straight(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end