classdef track_Tx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        sampleRate               matlab.ui.control.NumericEditField
        trackLength              matlab.ui.control.NumericEditField
        rxInitialPos_1           matlab.ui.control.NumericEditField
        rxInitialPos_2           matlab.ui.control.NumericEditField
        rxInitialPos_3           matlab.ui.control.NumericEditField
        ButtonGroup_txTrack      matlab.ui.container.Panel
        drawback                 matlab.ui.control.Button
        NameofTxEditField        matlab.ui.control.EditField
        Label_3                  matlab.ui.control.Label
        TxPosVisualize           matlab.ui.control.Button
        txInitialPos_3           matlab.ui.control.NumericEditField
        txInitialPos_2           matlab.ui.control.NumericEditField
        txInitialPos_1           matlab.ui.control.NumericEditField
        mInitialpositionLabel    matlab.ui.control.Label
        DirectionLabel           matlab.ui.control.Label
        txTrackOri               matlab.ui.control.EditField
        ms2Accelerationms2Label  matlab.ui.control.Label
        txAcceleration           matlab.ui.control.NumericEditField
        msVelocitymsLabel        matlab.ui.control.Label
        txSpeed                  matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        txTrackType              matlab.ui.control.DropDown
        windSpeed_note           matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingapp1
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, number, name, direction, antennanumber, type, speed, acceleration)
            app.callingapp1=mainapp;
            currentRowCount = size(app.callingapp1.UITable_tx.Data, 1);
            tag_scen = app.callingapp1.Tree_2.SelectedNodes.Tag;
            tools.setAppScenarios(app.callingapp1)

            app.callingapp1.sps.setScenario(strcat(app.callingapp1.scenarios));
            if contains(tag_scen, app.callingapp1.sps.scenario_UHST)
                app.txInitialPos_1.Editable = 'off';
                app.txInitialPos_2.Editable = 'off';
                scen_para = get_scen_para_scenario(app.callingapp1, app.callingapp1.scenarios);
                app.txInitialPos_3.Value = scen_para.R;
                app.txInitialPos_3.Editable = 'off';
            end

            if contains(tag_scen, app.callingapp1.sps.scenario_UWA)
                app.txInitialPos_1.Value = 0;
                app.txInitialPos_2.Value = 0;
                app.txInitialPos_3.Value = app.callingapp1.txDepth.Value;
            end

            % 新行的行号，用于txNumberStr和NameofTxEditField的默认值
            newRowNumber = currentRowCount + 1;
            nameOfTx = ['point_', num2str(newRowNumber)];
            app.NameofTxEditField.Value = nameOfTx;
        end

        % Button pushed function: TxPosVisualize
        function TxPosVisualizeButtonPushed(app, event)
            [initialPosTx, ~] = tools.get_initialPos_trans(app);

            trackOriTx = mf.str2positions(app.txTrackOri.Value);
            move_time = app.callingapp1.trackLength.Value;
            samp_rate = app.callingapp1.sampleRate.Value;

            if strfind(app.txTrackType.Value, 'satellite')
                tx_track = tools.get_track_satellite(app, move_time, samp_rate, initialPosTx);
                app.h_satellite.visualize_earth(tx_track.time_scale');

                %% 轨迹图示
                figure();
                scatter3(0,0,0);
                hold on;
                scatter3(tx_track.positions(:,1,:), tx_track.positions(:,2,:), tx_track.positions(:,3,:));
                xlabel('x-axis/(m)')
                ylabel('y-axis/(m)')
                zlabel('z-axis/(m)')
                title('\fontsize{16}The trajectory of the satellite relative to the Tx(m)');
                return;
            else
                tx_track = track(app.txTrackType.Value, move_time, app.txSpeed.Value, app.txAcceleration.Value, initialPosTx, trackOriTx, samp_rate);
                figure();
                scatter3(tx_track.positions(:,1,:), tx_track.positions(:,2,:), tx_track.positions(:,3,:));
                title('\fontsize{16}The motion trajectory diagram of the Tx(m)');
                xlabel('x-axis/(m)')
                ylabel('y-axis/(m)')
                zlabel('z-axis/(m)')
            end
        end

        % Value changed function: txTrackType
        function txTrackTypeValueChanged(app, event)
             if contains(app.txTrackType.Value, 'Static', 'IgnoreCase',true)
                 app.txSpeed.Value = 0;
                 app.txAcceleration.Value = 0;
                 app.txSpeed.Editable = 'off';
                 app.txAcceleration.Editable = 'off';
             else
                 app.txSpeed.Value = 5;
                 app.txAcceleration.Value = 1;
                 app.txSpeed.Editable = 'on';
                 app.txAcceleration.Editable = 'on';            
             end
        end

        % Button pushed function: drawback
        function drawbackButtonPushed(app, event)
            [initialPosTx, ~] = tools.get_initialPos_trans(app);
            % 确保所有元素都是字符串
            % 计算当前表格的行数
            currentRowCount = size(app.callingapp1.UITable_tx.Data, 1);

            % 生成新行的行号，作为第一个值
            txNumberStr = num2str(currentRowCount + 1);
            initialPosTxStr = tools.convertToString(initialPosTx);
            % 240202 sgxy加
            initialPosTxStr = ['[',initialPosTxStr,']'];
            nameOfTx = tools.convertToString(app.NameofTxEditField.Value);
            trackTypeStr = tools.convertToString(app.txTrackType.Value);
            txSpeedStr = tools.convertToString(app.txSpeed.Value);
            txAccelerationStr = tools.convertToString(app.txAcceleration.Value);
            txTrackOriStr = tools.convertToString(app.txTrackOri.Value);

            % 创建新行数据
            team= {txNumberStr, nameOfTx, initialPosTxStr, '', trackTypeStr, txSpeedStr, txAccelerationStr, txTrackOriStr};
            teamTable = cell2table(team);  
            newColumnNames = teamTable.Properties.VariableNames;  
            % 更新现有表格的列名以匹配 teamTable 的列名  
            app.callingapp1.UITable_tx.Data.Properties.VariableNames = newColumnNames;             
            app.callingapp1.UITable_tx.Data = [app.callingapp1.UITable_tx.Data; teamTable];

            app.callingapp1.settingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp1.settingButton.Enable='on';
            delete(app)
            
        end

        % Callback function
        function antennanumberValueChanged(app, event)
%             value=app.antennanumber;
%             app.antennanumber.Value=value;
            
        end

        % Drop down opening function: txTrackType
        function txTrackTypeDropDownOpening(app, event)
            tools.check_freqAndScensChosen(app.callingapp1);
            tag_scen = app.callingapp1.Tree_2.SelectedNodes.Tag;
            %tag_freq = app.callingapp1.Tree.SelectedNodes.Tag;
            if contains(tag_scen, app.callingapp1.sps.scenario_SATELLITE)
                app.txTrackType.Items = {'satellite'};
            elseif contains(tag_scen, app.callingapp1.sps.scenario_MARITIME)
                app.txTrackType.Items = {'shipStatic', 'shipLinear'};
            elseif contains(tag_scen, app.callingapp1.sps.scenario_UHST)
                app.txTrackType.Items = {'static'};
            elseif contains(tag_scen, app.callingapp1.sps.scenario_UWA)
                app.txTrackType.Items = {'static','linear','shipStatic', 'shipLinear'};
            else
                app.txTrackType.Items = {'static', 'random_circle', 'random', 'linear', 'circle'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup_txTrack = uipanel(app.UIFigure);
            app.ButtonGroup_txTrack.Title = '发送端运动轨迹 (Movement track of the Tx)';
            app.windSpeed_note = uilabel(app.ButtonGroup_txTrack);
            app.windSpeed_note.Text = {'When the track type is satellite, the satellite position is calculated based on the satellite'; ' altitude and the receiver position. Please set the initial position of the receiver first.'};
            app.txTrackType = uidropdown(app.ButtonGroup_txTrack);
            app.txTrackType.Items = {'static', 'random_circle', 'random', 'linear', 'circle', 'shipStatic', 'shipLinear', 'satellite'};
            app.txTrackType.DropDownOpeningFcn = createCallbackFcn(app, @txTrackTypeDropDownOpening, true);
            app.txTrackType.ValueChangedFcn = createCallbackFcn(app, @txTrackTypeValueChanged, true);
            app.txTrackType.Value = 'static';
            app.Label = uilabel(app.ButtonGroup_txTrack);
            app.Label.Text = {'轨迹类型'; 'Track type'};
            app.txSpeed = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txSpeed.Limits = [0 100000];
            app.txSpeed.Editable = 'off';
            app.msVelocitymsLabel = uilabel(app.ButtonGroup_txTrack);
            app.msVelocitymsLabel.Text = {'运动速度(m/s)'; 'Velocity(m/s)'};
            app.txAcceleration = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txAcceleration.Limits = [0 1000];
            app.txAcceleration.Editable = 'off';
            app.ms2Accelerationms2Label = uilabel(app.ButtonGroup_txTrack);
            app.ms2Accelerationms2Label.Interpreter = 'latex';
            app.ms2Accelerationms2Label.Text = {'运动加速度(m/s$^2$)'; 'Acceleration(m/s$^2$)'};
            app.txTrackOri = uieditfield(app.ButtonGroup_txTrack, 'text');
            app.txTrackOri.Tooltip = {'卫星的运动轨迹根据卫星高度和接收端经纬度确定，不设置运动方向'};
            app.txTrackOri.Value = '[1,1,0]';
            app.DirectionLabel = uilabel(app.ButtonGroup_txTrack);
            app.DirectionLabel.Text = {'运动方向'; 'Direction'};
            app.mInitialpositionLabel = uilabel(app.ButtonGroup_txTrack);
            app.mInitialpositionLabel.Text = {'初始位置(m)'; 'Initial position'};
            app.txInitialPos_1 = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.txInitialPos_2 = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos_3 = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txInitialPos_3.Value = 20;
            app.TxPosVisualize = uibutton(app.ButtonGroup_txTrack, 'push');
            app.TxPosVisualize.ButtonPushedFcn = createCallbackFcn(app, @TxPosVisualizeButtonPushed, true);
            app.TxPosVisualize.Text = '预览 (Preview)';
            app.Label_3 = uilabel(app.ButtonGroup_txTrack);
            app.Label_3.Text = {'发送端名称'; 'Name of Tx'};
            app.NameofTxEditField = uieditfield(app.ButtonGroup_txTrack, 'text');
            app.drawback = uibutton(app.ButtonGroup_txTrack, 'push');
            app.drawback.ButtonPushedFcn = createCallbackFcn(app, @drawbackButtonPushed, true);
            app.drawback.Text = '保存并关闭（Save and Close）';
            app.rxInitialPos_3 = uieditfield(app.UIFigure, 'numeric');
            app.rxInitialPos_2 = uieditfield(app.UIFigure, 'numeric');
            app.rxInitialPos_1 = uieditfield(app.UIFigure, 'numeric');
            app.trackLength = uieditfield(app.UIFigure, 'numeric');
            app.trackLength.Limits = [1e-06 1000];
            app.trackLength.Value = 0.01;
            app.sampleRate = uieditfield(app.UIFigure, 'numeric');
            app.sampleRate.Limits = [1 1000000];
            app.sampleRate.Value = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Tx(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end