classdef track_Tx_change < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ButtonGroup_txTrack  matlab.ui.container.Panel
        Text_Display         matlab.ui.control.TextArea
        drawback             matlab.ui.control.Button
        DirectionLabel       matlab.ui.control.Label
        txTrackOri           matlab.ui.control.EditField
        ms2Label             matlab.ui.control.Label
        txAcceleration       matlab.ui.control.NumericEditField
        msVelocityLabel      matlab.ui.control.Label
        txSpeed              matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        txTrackType          matlab.ui.control.DropDown
        windSpeed_note       matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp;
        sps = simulation_parameters; % 仿真参数控制类
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp=mainApp;
             % 获取选中的所有行索引
            selectedRows = unique(app.CallingApp.selection_tx(:,1)); % 仅行索引，去除重复

            % 获取选中行的编号列数据
            selectedIds = app.CallingApp.UITable_tx.Data{selectedRows, 1}; %

            % 将表格列数据转换为字符串
            selectedIdsStr = strjoin(cellstr(selectedIds), ', ');
            message = ['Select/modify tracks of the ' selectedIdsStr ' users'];

            % 显示消息在文本组件中
            app.Text_Display.Value = message;

        end

        % Callback function
        function TxPosVisualizeButtonPushed(app, event)
             [initialPosTx, ~] = tools.get_initialPos_trans(app);

            trackOriTx = mf.str2positions(app.txTrackOri.Value);
            move_time = app.trackLength.Value;
            samp_rate = app.sampleRate.Value;

            if strfind(app.txTrackType.Value, 'satellite')
                tx_track = tools.get_track_satellite(app, move_time, samp_rate, initialPosTx);
                app.h_satellite.visualize_earth(tx_track.time_scale');

                %% 轨迹图示
                figure();
                scatter3(0,0,0);
                hold on;
                scatter3(tx_track.positions(:,1,:), tx_track.positions(:,2,:), tx_track.positions(:,3,:));
                xlabel('x轴（单位为米）')
                ylabel('y轴（单位为米）')
                zlabel('z轴（单位为米）')
                title('\fontsize{16}卫星相对于接收端的运动轨迹（单位是米）');
                return;
            else
                tx_track = track(app.txTrackType.Value, move_time, app.txSpeed.Value, app.txAcceleration.Value, initialPosTx, trackOriTx, samp_rate);
                figure();
                scatter3(tx_track.positions(:,1,:), tx_track.positions(:,2,:), tx_track.positions(:,3,:));
                title('\fontsize{16}发送端运动轨迹示意图（单位为米）');
                xlabel('x轴（单位为米）')
                ylabel('y轴（单位为米）')
                zlabel('z轴（单位为米）')
            end
        end

        % Value changed function: txTrackType
        function txTrackTypeValueChanged(app, event)
             if contains(app.txTrackType.Value, 'Static', 'IgnoreCase',true)
                app.txSpeed.Value = 0;
                app.txAcceleration.Value = 0;
                app.txSpeed.Editable = 'off';
                app.txAcceleration.Editable = 'off';
            else
                app.txSpeed.Value = 5;
                app.txAcceleration.Value = 1;
                app.txSpeed.Editable = 'on';
                app.txAcceleration.Editable = 'on';
            end
        end

        % Button pushed function: drawback
        function drawbackButtonPushed(app, event)
            % 获取选中的所有行索引
            selectedRows = unique(app.CallingApp.selection_tx(:,1)); % 仅行索引，去除重复
            % 获取当前表格数据
            tableData = app.CallingApp.UITable_tx.Data;
            % 更新选中行的第5, 6, 7, 8列
            for i = 1:length(selectedRows)
                rowIndex = selectedRows(i);
                if rowIndex <= size(tableData, 1)
                    tableData(rowIndex, 5) = {num2str(app.txTrackType.Value)};
                    tableData(rowIndex, 6) = {num2str(app.txSpeed.Value)};
                    tableData(rowIndex, 7) = {num2str(app.txAcceleration.Value)};
                    tableData(rowIndex, 8) = {num2str(app.txTrackOri.Value)};
                end
            end
            % 将更新后的数据重新设置到表格中
            app.CallingApp.UITable_tx.Data = tableData;
            app.CallingApp.settingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.settingButton.Enable = 'on';
            delete(app)
        end

        % Drop down opening function: txTrackType
        function txTrackTypeDropDownOpening(app, event)
            tools.setAppScenarios(app.CallingApp)
            scenario = app.CallingApp.scenarios;
            if strfind(scenario, app.sps.scenario_SATELLITE)
                app.txTrackType.Items = {'satellite'};
            elseif strfind(scenario, app.sps.scenario_MARITIME)
                app.txTrackType.Items = {'shipStatic','shipLinear'};
            elseif contains(scenario, app.sps.scenario_UHST)
                app.txTrackType.Items = {'static'};
            elseif contains(scenario, app.sps.scenario_UWA)
                app.txTrackType.Items = {'static','linear','shipStatic', 'shipLinear'};
            else
                app.txTrackType.Items = {'static','random','random_circle','linear','circle'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup_txTrack = uipanel(app.UIFigure);
            app.ButtonGroup_txTrack.Title = '发射端运动轨迹 (Movement track of the Tx)';
            app.windSpeed_note = uilabel(app.ButtonGroup_txTrack);
            app.windSpeed_note.Text = {'When the track type is satellite, the satellite position is calculated based on the satellite'; ' altitude and the receiver position. Please set the initial position of the receiver first.'};
            app.txTrackType = uidropdown(app.ButtonGroup_txTrack);
            app.txTrackType.Items = {'static', 'random', 'random_circle', 'linear', 'circle', 'shipStatic', 'shipLinear', 'satellite'};
            app.txTrackType.DropDownOpeningFcn = createCallbackFcn(app, @txTrackTypeDropDownOpening, true);
            app.txTrackType.ValueChangedFcn = createCallbackFcn(app, @txTrackTypeValueChanged, true);
            app.txTrackType.Value = 'static';
            app.Label = uilabel(app.ButtonGroup_txTrack);
            app.Label.Text = {'轨迹类型'; 'Track type'};
            app.txSpeed = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txSpeed.Limits = [0 100000];
            app.txSpeed.Editable = 'off';
            app.msVelocityLabel = uilabel(app.ButtonGroup_txTrack);
            app.msVelocityLabel.Text = {'运动速度(m/s)'; 'Velocity'};
            app.txAcceleration = uieditfield(app.ButtonGroup_txTrack, 'numeric');
            app.txAcceleration.Limits = [0 1000];
            app.txAcceleration.Editable = 'off';
            app.ms2Label = uilabel(app.ButtonGroup_txTrack);
            app.ms2Label.Interpreter = 'latex';
            app.ms2Label.Text = {'运动加速度(m/s$^2$)'; 'Acceleration'};
            app.txTrackOri = uieditfield(app.ButtonGroup_txTrack, 'text');
            app.txTrackOri.Tooltip = {'卫星的运动轨迹根据卫星高度和接收端经纬度确定，不设置运动方向'};
            app.txTrackOri.Value = '[1,1,0]';
            app.DirectionLabel = uilabel(app.ButtonGroup_txTrack);
            app.DirectionLabel.Text = {'运动方向'; 'Direction'};
            app.drawback = uibutton(app.ButtonGroup_txTrack, 'push');
            app.drawback.ButtonPushedFcn = createCallbackFcn(app, @drawbackButtonPushed, true);
            app.drawback.Text = '保存并关闭（Save and Close）';
            app.Text_Display = uitextarea(app.ButtonGroup_txTrack);
            app.Text_Display.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Tx_change(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end