classdef track_Tx_circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CircleinitialpositionsettingPanel  matlab.ui.container.Panel
        saveclose                     matlab.ui.control.Button
        mInitialpositionLabel_3       matlab.ui.control.Label
        antennaHeight                 matlab.ui.control.NumericEditField
        previewtx                     matlab.ui.control.Button
        mInitialpositionsettingLabel  matlab.ui.control.Label
        mInitialpositionLabel_2       matlab.ui.control.Label
        circleRadius                  matlab.ui.control.NumericEditField
        circlecenterPos_1             matlab.ui.control.NumericEditField
        mInitialpositionLabel         matlab.ui.control.Label
        circlecenterPos_3             matlab.ui.control.NumericEditField
        circlecenterPos_2             matlab.ui.control.NumericEditField
        Txnum                         matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;
        tx_positions;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
          app.CallingApp = mainApp;
          txUser_num= size(app.CallingApp.UITable_tx.Data,1);
          if app.CallingApp.user_number-txUser_num==2
              app.Txnum.Value = 2;
              app.Txnum.Editable = "off";
          else
              app.Txnum.Limits = [2 app.CallingApp.user_number-txUser_num];
          end
%             N = app.Txnum.Value+1; % 获取发送端数量
% 
%             % 获取起点和终点坐标
%             circle_center = [app.circlecenterPos_1.Value, app.circlecenterPos_2.Value, app.circlecenterPos_3.Value];
%             circle_radius = app.circleRadius.Value;
%             theta = linspace(0, 2*pi, N);
%             % 计算坐标值
%             app.tx_positions(:,1) = circle_center(1) + circle_radius * cos(theta);
%             app.tx_positions(:,2) = circle_center(2) + circle_radius * sin(theta);
%             app.tx_positions(:,3) = circle_center(3) * ones(1, N);  % 如果在三维空间中，z坐标保持不变
        end

        % Button pushed function: previewtx
        function previewtxButtonPushed(app, event)
          
            N = app.Txnum.Value; % 获取发送端数量
            app.tx_positions = zeros(N,3);%初始化为N行3列的零矩阵
            
            % 获取起点和终点坐标
            circle_center = [app.circlecenterPos_1.Value, app.circlecenterPos_2.Value, app.circlecenterPos_3.Value];
            circle_radius = app.circleRadius.Value;
            theta = linspace(0, 2*pi, N+1); % 生成N+1个点
            theta(end) = []; % 去掉最后一个点，以避免重合
            % 计算坐标值
            app.tx_positions(:,1) = circle_center(1) + circle_radius * cos(theta);
            app.tx_positions(:,2) = circle_center(2) + circle_radius * sin(theta);
            app.tx_positions(:,3) = circle_center(3) * ones(1, N);  % 如果在三维空间中，z坐标保持不变


            % 绘制发送端位置
            plot3(app.UIAxes, app.tx_positions(:,1), app.tx_positions(:,2), app.tx_positions(:,3), 'o');
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            title(app.UIAxes, 'Uniform Circle Distribution of Transmitters');

        end

        % Button pushed function: saveclose
        function savecloseButtonPushed(app, event)
             N = app.Txnum.Value; % 获取发送端数量
              app.tx_positions = zeros(N,3);%初始化为N行3列的零矩阵

            % 获取起点和终点坐标
            circle_center = [app.circlecenterPos_1.Value, app.circlecenterPos_2.Value, app.circlecenterPos_3.Value];
            circle_radius = app.circleRadius.Value;
            theta = linspace(0, 2*pi, N+1); % 生成N+1个点
            theta(end) = []; % 去掉最后一个点，以避免重合
            % 计算坐标值
            app.tx_positions(:,1) = circle_center(1) + circle_radius * cos(theta);
            app.tx_positions(:,2) = circle_center(2) + circle_radius * sin(theta);
            app.tx_positions(:,3) = circle_center(3) * ones(1, N);  % 如果在三维空间中，z坐标保持不变

            % 初始化新数据
%             totalRows = size(app.CallingApp.UITable_tx.Data, 1) + N;
            nums = arrayfun(@num2str, (1:N) + size(app.CallingApp.UITable_tx.Data, 1), 'UniformOutput', false);
            names = arrayfun(@(i) ['circle_' num2str(i)], 1:N, 'UniformOutput', false);
            coords = arrayfun(@(i) mat2str(app.tx_positions(i, :)), 1:N, 'UniformOutput', false);
            emptyCols = repmat({''}, N, 1);

            % 创建新行的数据
            team = [nums' names' coords' emptyCols emptyCols emptyCols emptyCols emptyCols];
            teamTable = cell2table(team);  
            newColumnNames = teamTable.Properties.VariableNames;  
            % 更新现有表格的列名以匹配 teamTable 的列名  
            app.CallingApp.UITable_tx.Data.Properties.VariableNames = newColumnNames;  
            app.CallingApp.UITable_tx.Data = [app.CallingApp.UITable_tx.Data; teamTable];
            
            app.CallingApp.settingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest2(app, event)
            app.CallingApp.settingButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest2, true);
            app.CircleinitialpositionsettingPanel = uipanel(app.UIFigure);
            app.CircleinitialpositionsettingPanel.Title = '圆形初始位置设置(Circle initial position setting)';
            app.UIAxes = uiaxes(app.CircleinitialpositionsettingPanel);
            title(app.UIAxes, '初始位置预览(Preview Initial Position)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_3 = uilabel(app.CircleinitialpositionsettingPanel);
            app.Label_3.Text = {'发送端的用户数量 :  '; 'Number of  Tx users :  '};
            app.Txnum = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.Txnum.Limits = [1 10];
            app.Txnum.RoundFractionalValues = 'on';
            app.Txnum.Value = 6;
            app.circlecenterPos_2 = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.circlecenterPos_3 = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.circlecenterPos_3.Value = 20;
            app.mInitialpositionLabel = uilabel(app.CircleinitialpositionsettingPanel);
            app.mInitialpositionLabel.Text = {'圆心'; '(Center of circle)'};
            app.circlecenterPos_1 = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.circlecenterPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.circleRadius = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.circleRadius.Limits = [0 250];
            app.circleRadius.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.circleRadius.Value = 5;
            app.mInitialpositionLabel_2 = uilabel(app.CircleinitialpositionsettingPanel);
            app.mInitialpositionLabel_2.Text = {'半径'; '(Radius)'};
            app.mInitialpositionsettingLabel = uilabel(app.CircleinitialpositionsettingPanel);
            app.mInitialpositionsettingLabel.Text = {'初始位置设置(m)'; 'Initial position setting'};
            app.previewtx = uibutton(app.CircleinitialpositionsettingPanel, 'push');
            app.previewtx.ButtonPushedFcn = createCallbackFcn(app, @previewtxButtonPushed, true);
            app.previewtx.Text = '预览初始位置(Preview)';
            app.antennaHeight = uieditfield(app.CircleinitialpositionsettingPanel, 'numeric');
            app.antennaHeight.Limits = [0 250];
            app.antennaHeight.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.mInitialpositionLabel_3 = uilabel(app.CircleinitialpositionsettingPanel);
            app.mInitialpositionLabel_3.Text = {'天线高度'; '(Antenna height)'};
            app.saveclose = uibutton(app.CircleinitialpositionsettingPanel, 'push');
            app.saveclose.ButtonPushedFcn = createCallbackFcn(app, @savecloseButtonPushed, true);
            app.saveclose.Text = '保存并关闭(Save and Close)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Tx_circle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end