classdef track_Tx_straight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        straightlinesettingPanel      matlab.ui.container.Panel
        saveclose                     matlab.ui.control.Button
        previewtx                     matlab.ui.control.Button
        mInitialpositionsettingLabel  matlab.ui.control.Label
        mInitialpositionLabel_2       matlab.ui.control.Label
        txInitialPos_4                matlab.ui.control.NumericEditField
        txInitialPos_6                matlab.ui.control.NumericEditField
        txInitialPos_5                matlab.ui.control.NumericEditField
        txInitialPos_1                matlab.ui.control.NumericEditField
        mInitialpositionLabel         matlab.ui.control.Label
        txInitialPos_3                matlab.ui.control.NumericEditField
        txInitialPos_2                matlab.ui.control.NumericEditField
        Txnum                         matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;  % Description
        tx_positions;
    end
    
    methods (Access = private)
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
             app.CallingApp = mainApp;
             txUser_num= size(app.CallingApp.UITable_tx.Data,1);
             if app.CallingApp.user_number-txUser_num==2
                 app.Txnum.Value = 2;
                 app.Txnum.Editable = "off";
             else
                 app.Txnum.Limits = [2 app.CallingApp.user_number-txUser_num];
             end
%             N = app.Txnum.Value; % 获取发送端数量
% 
%             % 获取起点和终点坐标
%             start_point = [app.txInitialPos_1.Value, app.txInitialPos_2.Value, app.txInitialPos_3.Value];
%             end_point = [app.txInitialPos_4.Value, app.txInitialPos_5.Value, app.txInitialPos_6.Value];
% 
%             % 计算均匀分布的发送端坐标
%             app.tx_positions = zeros(N, 3);
%             for i = 1:N
%                 app.tx_positions(i, :) = start_point + (i-1) / (N-1) * (end_point - start_point);
%             end

        end

        % Button pushed function: previewtx
        function previewtxButtonPushed(app, event)
          
            N = app.Txnum.Value; % 获取发送端数量

            % 获取起点和终点坐标
            start_point = [app.txInitialPos_1.Value, app.txInitialPos_2.Value, app.txInitialPos_3.Value];
            end_point = [app.txInitialPos_4.Value, app.txInitialPos_5.Value, app.txInitialPos_6.Value];

            % 计算均匀分布的发送端坐标
            app.tx_positions = zeros(N, 3);
            for i = 1:N
                app.tx_positions(i, :) = start_point + (i-1) / (N-1) * (end_point - start_point);
            end
            % 绘制发送端位置
            plot3(app.UIAxes, app.tx_positions(:,1), app.tx_positions(:,2), app.tx_positions(:,3), 'o');
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            title(app.UIAxes, 'Uniform Linear Distribution of Transmitters');

        end

        % Button pushed function: saveclose
        function savecloseButtonPushed(app, event)
            N = app.Txnum.Value; % 获取发送端数量

            % 获取起点和终点坐标
            start_point = [app.txInitialPos_1.Value, app.txInitialPos_2.Value, app.txInitialPos_3.Value];
            end_point = [app.txInitialPos_4.Value, app.txInitialPos_5.Value, app.txInitialPos_6.Value];

            % 计算均匀分布的发送端坐标
            app.tx_positions = zeros(N, 3);
            for i = 1:N
                app.tx_positions(i, :) = start_point + (i-1) / (N-1) * (end_point - start_point);
            end
            N = size(app.tx_positions, 1); % 获取点的数量

            % 初始化新数据
%             totalRows = size(app.CallingApp.UITable_tx.Data, 1) + N;
            nums = arrayfun(@num2str, (1:N) + size(app.CallingApp.UITable_tx.Data, 1), 'UniformOutput', false);
            names = arrayfun(@(i) ['straight_' num2str(i)], 1:N, 'UniformOutput', false);
            coords = arrayfun(@(i) mat2str(app.tx_positions(i, :)), 1:N, 'UniformOutput', false);
            emptyCols = repmat({''}, N, 1);

            % 创建新行的数据
            team = [nums' names' coords' emptyCols emptyCols emptyCols emptyCols emptyCols];
            teamTable = cell2table(team);  
            newColumnNames = teamTable.Properties.VariableNames;  
            % 更新现有表格的列名以匹配 teamTable 的列名  
            app.CallingApp.UITable_tx.Data.Properties.VariableNames = newColumnNames;  
            app.CallingApp.UITable_tx.Data = [app.CallingApp.UITable_tx.Data; teamTable];
            
            app.CallingApp.settingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.settingButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.straightlinesettingPanel = uipanel(app.UIFigure);
            app.straightlinesettingPanel.Title = '发送端直线设置(straight line setting)';
            app.UIAxes = uiaxes(app.straightlinesettingPanel);
            title(app.UIAxes, '初始位置预览(Preview Initial Position)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_3 = uilabel(app.straightlinesettingPanel);
            app.Label_3.Text = {'发送端的用户数量 :  '; 'Number of  Tx users :  '};
            app.Txnum = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.Txnum.Limits = [1 10];
            app.Txnum.RoundFractionalValues = 'on';
            app.Txnum.Value = 3;
            app.txInitialPos_2 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_3 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_3.Value = 20;
            app.mInitialpositionLabel = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionLabel.Text = {'起点'; 'The Starting'};
            app.txInitialPos_1 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_1.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.txInitialPos_5 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_5.Value = 10;
            app.txInitialPos_6 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_6.Value = 20;
            app.txInitialPos_4 = uieditfield(app.straightlinesettingPanel, 'numeric');
            app.txInitialPos_4.Tooltip = {'3个输入框分别为：x轴，y轴，z轴坐标'};
            app.mInitialpositionLabel_2 = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionLabel_2.Text = {'终点'; 'The Ending'};
            app.mInitialpositionsettingLabel = uilabel(app.straightlinesettingPanel);
            app.mInitialpositionsettingLabel.Text = {'初始位置设置(m)'; 'Initial position setting'};
            app.previewtx = uibutton(app.straightlinesettingPanel, 'push');
            app.previewtx.ButtonPushedFcn = createCallbackFcn(app, @previewtxButtonPushed, true);
            app.previewtx.Text = '预览初始位置(Preview)';
            app.saveclose = uibutton(app.straightlinesettingPanel, 'push');
            app.saveclose.ButtonPushedFcn = createCallbackFcn(app, @savecloseButtonPushed, true);
            app.saveclose.Text = '保存并关闭(Save and Close)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_Tx_straight(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end